classdef InterfazPrueba < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        InformacionSesion               matlab.ui.container.Panel
        IntroduzcalosDatosdeIniciodelaSesinLabel  matlab.ui.control.Label
        IndicarelBrazoSeleccionadoLabel  matlab.ui.control.Label
        SeleccionarlaPosicindelPacienteLabel  matlab.ui.control.Label
        SeleccionarelTipodeMovimientoaRealizarLabel  matlab.ui.control.Label
        Switch                          matlab.ui.control.Switch
        Switch_2                        matlab.ui.control.Switch
        DropDown                        matlab.ui.control.DropDown
        ConectarelRobotylosSensoresLabel  matlab.ui.control.Label
        IIWACheckBox                    matlab.ui.control.CheckBox
        SensorFuerzaTensinCheckBox      matlab.ui.control.CheckBox
        SensoresDelsysCheckBox          matlab.ui.control.CheckBox
        ColocarlosSensoresButton        matlab.ui.control.Button
        VolveralMenButton               matlab.ui.control.Button
        ContinuarButton                 matlab.ui.control.Button
        MenuPrueba                      matlab.ui.container.Panel
        ResumendelosDatosInicialesLabel  matlab.ui.control.Label
        IIWAConectadoLabel              matlab.ui.control.Label
        SensorFuerzaTensinConectadoLabel  matlab.ui.control.Label
        SensoresDelsysConectadoLabel    matlab.ui.control.Label
        BrazoSeleccionadoDerechoLabel   matlab.ui.control.Label
        PosicindelPacientePlanoXZLabel  matlab.ui.control.Label
        PosicinSensor1BicepsBrachii000Label  matlab.ui.control.Label
        PosicinSensor2TricepsLateralHead000Label  matlab.ui.control.Label
        PosicinSensor3BrachioRadialis000Label  matlab.ui.control.Label
        PosicinSensor4FlexorCarpiRadialis000Label  matlab.ui.control.Label
        TipodeMovimientoSeleccionadoFlexinExtensinCodoLabel  matlab.ui.control.Label
        CapturarNuevaTrayectoriaButton  matlab.ui.control.Button
        RepetirTrayectoriaCapturadaButton  matlab.ui.control.Button
        CambiarDatosInicialesButton     matlab.ui.control.Button
        VolveralMenButton_4             matlab.ui.control.Button
        Capturar1                       matlab.ui.container.Panel
        CapturarNuevaTrayectoriaLabel   matlab.ui.control.Label
        MoverRobotalaPosicinInicialdeReferenciaLabel  matlab.ui.control.Label
        BrazoIzquierdoButton            matlab.ui.control.Button
        BrazoDerechoButton              matlab.ui.control.Button
        ConfigurarAjustesdeTrayectoriaLabel  matlab.ui.control.Label
        ParmetrodeSmoothingLabel        matlab.ui.control.Label
        TrayectoriaGrabadaEditFieldLabel  matlab.ui.control.Label
        TrayectoriaGrabadaEditField     matlab.ui.control.NumericEditField
        TrayectoriaAjustadaEditFieldLabel  matlab.ui.control.Label
        TrayectoriaAjustadaEditField    matlab.ui.control.NumericEditField
        TrayectoriaEjecutadaEditFieldLabel  matlab.ui.control.Label
        TrayectoriaEjecutadaEditField   matlab.ui.control.NumericEditField
        SeleccionarGrficasaMostrarTraselAjusteLabel  matlab.ui.control.Label
        PosicinCartesianaAjusteCircularCheckBox  matlab.ui.control.CheckBox
        PosicinCartesianaCheckBox       matlab.ui.control.CheckBox
        VelocidadCartesianaCheckBox     matlab.ui.control.CheckBox
        ErrorPosicinCartesianaCheckBox  matlab.ui.control.CheckBox
        ErrorVelocidadCartesianaCheckBox  matlab.ui.control.CheckBox
        PosicinCartesiana3DCheckBox     matlab.ui.control.CheckBox
        ContinuarButton_3               matlab.ui.control.Button
        Capturar2                       matlab.ui.container.Panel
        CapturarNuevaTrayectoriaLabel_2  matlab.ui.control.Label
        PersonalizarPosicinInicialLabel  matlab.ui.control.Label
        BloquearRobotButton             matlab.ui.control.Button
        LiberarEjeXButton               matlab.ui.control.Button
        LiberarEjeYButton               matlab.ui.control.Button
        LiberarEjeZButton               matlab.ui.control.Button
        GrabarlaTrayectoriaLabel        matlab.ui.control.Label
        BloquearRobotButton_2           matlab.ui.control.Button
        IniciarCapturaButton            matlab.ui.control.Button
        FinalizarCapturaButton          matlab.ui.control.Button
        GeneraryGuardarTrayectoriaLabel  matlab.ui.control.Label
        SeleccionarTrayectoriaDropDownLabel  matlab.ui.control.Label
        SeleccionarTrayectoriaDropDown  matlab.ui.control.DropDown
        GuardarTrayectoriaButton        matlab.ui.control.Button
        RepetirCapturaButton            matlab.ui.control.Button
        Repetir                         matlab.ui.container.Panel
        RepetirTrayectoriaLabel         matlab.ui.control.Label
        SeleccionartrayectoriaarepetirLabel  matlab.ui.control.Label
        TrayectoriaDropDownLabel        matlab.ui.control.Label
        TrayectoriaDropDown             matlab.ui.control.DropDown
        ModificarvelocidadLabel         matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        CalibrarsensorFuerzaTensinparaestatrayectoriaLabel  matlab.ui.control.Label
        MandaralaPosicinInicialButton   matlab.ui.control.Button
        ComprobarRelajacinMuscularButton  matlab.ui.control.Button
        EjecutarTrayectoriaenVacoButton  matlab.ui.control.Button
        EjecutarTrayectoriaLabel        matlab.ui.control.Label
        MandaralaPosicinInicialButton_2  matlab.ui.control.Button
        ComprobarRelajacinMuscularButton_2  matlab.ui.control.Button
        EjecutarTrayectoriaButton       matlab.ui.control.Button
        Spinner                         matlab.ui.control.Spinner
        VerResultadosButton             matlab.ui.control.Button
        VolveralMenButton_2             matlab.ui.control.Button
        Resultados                      matlab.ui.container.Panel
        TrajectoryLabel                 matlab.ui.control.Label
        JointLabel                      matlab.ui.control.Label
        qButton                         matlab.ui.control.Button
        dqButton                        matlab.ui.control.Button
        EqButton                        matlab.ui.control.Button
        EdqButton                       matlab.ui.control.Button
        CartesianLabel                  matlab.ui.control.Label
        xButton                         matlab.ui.control.Button
        ExButton                        matlab.ui.control.Button
        EdxButton                       matlab.ui.control.Button
        ThreeDButton                    matlab.ui.control.Button
        dxButton                        matlab.ui.control.Button
        ForceSensorLabel                matlab.ui.control.Label
        FTButton                        matlab.ui.control.Button
        DelsysSensorsLabel              matlab.ui.control.Label
        EMGButton                       matlab.ui.control.Button
        AccelerationsButton             matlab.ui.control.Button
        AngularVelocityButton           matlab.ui.control.Button
        ddqButton                       matlab.ui.control.Button
        EddqButton                      matlab.ui.control.Button
        EpqButton                       matlab.ui.control.Button
        EpdqButton                      matlab.ui.control.Button
        EpddqButton                     matlab.ui.control.Button
        EpxButton                       matlab.ui.control.Button
        EpdxButton                      matlab.ui.control.Button
        JointPrecisionLabel             matlab.ui.control.Label
        JointAccuracyLabel              matlab.ui.control.Label
        CartesianPrecisionLabel         matlab.ui.control.Label
        CartesianAccuracyLabel          matlab.ui.control.Label
        ResultadosLabel                 matlab.ui.control.Label
        VolveralMenButton_3             matlab.ui.control.Button
        SeleccionelarepeticinamostrarSpinnerLabel  matlab.ui.control.Label
        SeleccionelarepeticinamostrarSpinner  matlab.ui.control.Spinner
        Sensores                        matlab.ui.container.Panel
        IndicarlaPosicindelosSensoresenmmLabel  matlab.ui.control.Label
        Sensor1BicepsBrachiiLabel       matlab.ui.control.Label
        Sensor2TricepsLateralHeadLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
        Image4                          matlab.ui.control.Image
        Sensor3BrachioRadialisLabel     matlab.ui.control.Label
        Sensor4FlexorCarpiRadialisLabel  matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        EditField2                      matlab.ui.control.EditField
        EditField3                      matlab.ui.control.EditField
        EditField4                      matlab.ui.control.EditField
        ContinuarButton_2               matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ColocarlosSensoresButton
        function ColocarlosSensoresButtonPushed(app, event)
            app.InformacionSesion.Visible='off';
            app.Sensores.Visible='on';
        end

        % Button pushed function: ContinuarButton
        function ContinuarButtonPushed(app, event)
            app.InformacionSesion.Visible='off';
            app.MenuPrueba.Visible='on';
        end

        % Button pushed function: CapturarNuevaTrayectoriaButton
        function CapturarNuevaTrayectoriaButtonPushed(app, event)
            app.MenuPrueba.Visible='off';
            app.Capturar1.Visible='on';
        end

        % Button pushed function: RepetirTrayectoriaCapturadaButton
        function RepetirTrayectoriaCapturadaButtonPushed(app, event)
            app.MenuPrueba.Visible='off';
            app.Repetir.Visible='on';
        end

        % Button pushed function: CambiarDatosInicialesButton
        function CambiarDatosInicialesButtonPushed(app, event)
            app.MenuPrueba.Visible='off';
            app.InformacionSesion.Visible='on';
        end

        % Button pushed function: ContinuarButton_3
        function ContinuarButton_3Pushed(app, event)
            app.Capturar1.Visible='off';
            app.Capturar2.Visible='on';
        end

        % Button pushed function: GuardarTrayectoriaButton
        function GuardarTrayectoriaButtonPushed(app, event)
            app.Capturar2.Visible='off';
            app.MenuPrueba.Visible='on';
        end

        % Button pushed function: VerResultadosButton
        function VerResultadosButtonPushed(app, event)
            app.Repetir.Visible='off';
            app.Resultados.Visible='on';
        end

        % Button pushed function: VolveralMenButton_2
        function VolveralMenButton_2Pushed(app, event)
            app.Repetir.Visible='off';
            app.MenuPrueba.Visible='on';
        end

        % Button pushed function: VolveralMenButton_3
        function VolveralMenButton_3Pushed(app, event)
            app.Resultados.Visible='off';
            app.MenuPrueba.Visible='on';
        end

        % Button pushed function: ContinuarButton_2
        function ContinuarButton_2Pushed(app, event)
            app.Sensores.Visible='off';
            app.InformacionSesion.Visible='on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.InformacionSesion = uipanel(app.UIFigure);
            app.IntroduzcalosDatosdeIniciodelaSesinLabel = uilabel(app.InformacionSesion);
            app.IntroduzcalosDatosdeIniciodelaSesinLabel.Text = 'Introduzca los Datos de Inicio de la Sesión';
            app.IndicarelBrazoSeleccionadoLabel = uilabel(app.InformacionSesion);
            app.IndicarelBrazoSeleccionadoLabel.Text = 'Indicar el Brazo Seleccionado';
            app.SeleccionarlaPosicindelPacienteLabel = uilabel(app.InformacionSesion);
            app.SeleccionarlaPosicindelPacienteLabel.Text = 'Seleccionar la Posición del Paciente';
            app.SeleccionarelTipodeMovimientoaRealizarLabel = uilabel(app.InformacionSesion);
            app.SeleccionarelTipodeMovimientoaRealizarLabel.Text = 'Seleccionar el Tipo de Movimiento a Realizar';
            app.Switch = uiswitch(app.InformacionSesion, 'slider');
            app.Switch.Items = {'Brazo Izquierdo', 'Brazo Derecho'};
            app.Switch.Value = 'Brazo Izquierdo';
            app.Switch_2 = uiswitch(app.InformacionSesion, 'slider');
            app.Switch_2.Items = {'Plano YZ', 'Plano XZ'};
            app.Switch_2.Value = 'Plano YZ';
            app.DropDown = uidropdown(app.InformacionSesion);
            app.ConectarelRobotylosSensoresLabel = uilabel(app.InformacionSesion);
            app.ConectarelRobotylosSensoresLabel.Text = 'Conectar el Robot y los Sensores';
            app.IIWACheckBox = uicheckbox(app.InformacionSesion);
            app.IIWACheckBox.Text = 'IIWA';
            app.SensorFuerzaTensinCheckBox = uicheckbox(app.InformacionSesion);
            app.SensorFuerzaTensinCheckBox.Text = 'Sensor Fuerza-Tensión';
            app.SensoresDelsysCheckBox = uicheckbox(app.InformacionSesion);
            app.SensoresDelsysCheckBox.Text = 'Sensores Delsys';
            app.ColocarlosSensoresButton = uibutton(app.InformacionSesion, 'push');
            app.ColocarlosSensoresButton.ButtonPushedFcn = createCallbackFcn(app, @ColocarlosSensoresButtonPushed, true);
            app.ColocarlosSensoresButton.Text = 'Colocar los Sensores';
            app.VolveralMenButton = uibutton(app.InformacionSesion, 'push');
            app.VolveralMenButton.Text = 'Volver al Menú';
            app.ContinuarButton = uibutton(app.InformacionSesion, 'push');
            app.ContinuarButton.ButtonPushedFcn = createCallbackFcn(app, @ContinuarButtonPushed, true);
            app.ContinuarButton.Text = 'Continuar';
            app.MenuPrueba = uipanel(app.UIFigure);
            app.MenuPrueba.Visible = 'off';
            app.ResumendelosDatosInicialesLabel = uilabel(app.MenuPrueba);
            app.ResumendelosDatosInicialesLabel.Text = 'Resumen de los Datos Iniciales';
            app.IIWAConectadoLabel = uilabel(app.MenuPrueba);
            app.IIWAConectadoLabel.Text = 'IIWA: Conectado';
            app.SensorFuerzaTensinConectadoLabel = uilabel(app.MenuPrueba);
            app.SensorFuerzaTensinConectadoLabel.Text = 'Sensor Fuerza-Tensión: Conectado';
            app.SensoresDelsysConectadoLabel = uilabel(app.MenuPrueba);
            app.SensoresDelsysConectadoLabel.Text = 'Sensores Delsys: Conectado';
            app.BrazoSeleccionadoDerechoLabel = uilabel(app.MenuPrueba);
            app.BrazoSeleccionadoDerechoLabel.Text = 'Brazo Seleccionado: Derecho';
            app.PosicindelPacientePlanoXZLabel = uilabel(app.MenuPrueba);
            app.PosicindelPacientePlanoXZLabel.Text = 'Posición del Paciente: Plano XZ';
            app.PosicinSensor1BicepsBrachii000Label = uilabel(app.MenuPrueba);
            app.PosicinSensor1BicepsBrachii000Label.Text = 'Posición Sensor 1 (Biceps Brachii) : (0,0,0)';
            app.PosicinSensor2TricepsLateralHead000Label = uilabel(app.MenuPrueba);
            app.PosicinSensor2TricepsLateralHead000Label.Text = 'Posición Sensor 2 (Triceps Lateral Head): (0,0,0)';
            app.PosicinSensor3BrachioRadialis000Label = uilabel(app.MenuPrueba);
            app.PosicinSensor3BrachioRadialis000Label.Text = 'Posición Sensor 3 (Brachio Radialis): (0,0,0)';
            app.PosicinSensor4FlexorCarpiRadialis000Label = uilabel(app.MenuPrueba);
            app.PosicinSensor4FlexorCarpiRadialis000Label.Text = 'Posición Sensor 4 (Flexor Carpi Radialis): (0,0,0)';
            app.TipodeMovimientoSeleccionadoFlexinExtensinCodoLabel = uilabel(app.MenuPrueba);
            app.TipodeMovimientoSeleccionadoFlexinExtensinCodoLabel.Text = 'Tipo de Movimiento Seleccionado: Flexión-Extensión Codo';
            app.CapturarNuevaTrayectoriaButton = uibutton(app.MenuPrueba, 'push');
            app.CapturarNuevaTrayectoriaButton.ButtonPushedFcn = createCallbackFcn(app, @CapturarNuevaTrayectoriaButtonPushed, true);
            app.CapturarNuevaTrayectoriaButton.Text = 'Capturar Nueva Trayectoria';
            app.RepetirTrayectoriaCapturadaButton = uibutton(app.MenuPrueba, 'push');
            app.RepetirTrayectoriaCapturadaButton.ButtonPushedFcn = createCallbackFcn(app, @RepetirTrayectoriaCapturadaButtonPushed, true);
            app.RepetirTrayectoriaCapturadaButton.Text = 'Repetir Trayectoria Capturada';
            app.CambiarDatosInicialesButton = uibutton(app.MenuPrueba, 'push');
            app.CambiarDatosInicialesButton.ButtonPushedFcn = createCallbackFcn(app, @CambiarDatosInicialesButtonPushed, true);
            app.CambiarDatosInicialesButton.Text = 'Cambiar Datos Iniciales';
            app.VolveralMenButton_4 = uibutton(app.MenuPrueba, 'push');
            app.VolveralMenButton_4.Text = 'Volver al Menú';
            app.Capturar1 = uipanel(app.UIFigure);
            app.Capturar1.Visible = 'off';
            app.CapturarNuevaTrayectoriaLabel = uilabel(app.Capturar1);
            app.CapturarNuevaTrayectoriaLabel.Text = 'Capturar Nueva Trayectoria';
            app.MoverRobotalaPosicinInicialdeReferenciaLabel = uilabel(app.Capturar1);
            app.MoverRobotalaPosicinInicialdeReferenciaLabel.Text = '1.Mover Robot a la Posición Inicial de Referencia';
            app.BrazoIzquierdoButton = uibutton(app.Capturar1, 'push');
            app.BrazoIzquierdoButton.Text = 'Brazo Izquierdo';
            app.BrazoDerechoButton = uibutton(app.Capturar1, 'push');
            app.BrazoDerechoButton.Text = 'Brazo Derecho';
            app.ConfigurarAjustesdeTrayectoriaLabel = uilabel(app.Capturar1);
            app.ConfigurarAjustesdeTrayectoriaLabel.Text = '2.Configurar Ajustes de Trayectoria';
            app.ParmetrodeSmoothingLabel = uilabel(app.Capturar1);
            app.ParmetrodeSmoothingLabel.Text = 'Parámetro de Smoothing';
            app.TrayectoriaGrabadaEditFieldLabel = uilabel(app.Capturar1);
            app.TrayectoriaGrabadaEditFieldLabel.Text = 'Trayectoria Grabada';
            app.TrayectoriaGrabadaEditField = uieditfield(app.Capturar1, 'numeric');
            app.TrayectoriaAjustadaEditFieldLabel = uilabel(app.Capturar1);
            app.TrayectoriaAjustadaEditFieldLabel.Text = 'Trayectoria Ajustada';
            app.TrayectoriaAjustadaEditField = uieditfield(app.Capturar1, 'numeric');
            app.TrayectoriaEjecutadaEditFieldLabel = uilabel(app.Capturar1);
            app.TrayectoriaEjecutadaEditFieldLabel.Text = 'Trayectoria Ejecutada';
            app.TrayectoriaEjecutadaEditField = uieditfield(app.Capturar1, 'numeric');
            app.SeleccionarGrficasaMostrarTraselAjusteLabel = uilabel(app.Capturar1);
            app.SeleccionarGrficasaMostrarTraselAjusteLabel.Text = 'Seleccionar Gráficas a Mostrar Tras el Ajuste';
            app.PosicinCartesianaAjusteCircularCheckBox = uicheckbox(app.Capturar1);
            app.PosicinCartesianaAjusteCircularCheckBox.Text = 'Posición Cartesiana + Ajuste Circular';
            app.PosicinCartesianaCheckBox = uicheckbox(app.Capturar1);
            app.PosicinCartesianaCheckBox.Text = 'Posición Cartesiana';
            app.VelocidadCartesianaCheckBox = uicheckbox(app.Capturar1);
            app.VelocidadCartesianaCheckBox.Text = 'Velocidad Cartesiana';
            app.ErrorPosicinCartesianaCheckBox = uicheckbox(app.Capturar1);
            app.ErrorPosicinCartesianaCheckBox.Text = 'Error Posición Cartesiana';
            app.ErrorVelocidadCartesianaCheckBox = uicheckbox(app.Capturar1);
            app.ErrorVelocidadCartesianaCheckBox.Text = 'Error Velocidad Cartesiana';
            app.PosicinCartesiana3DCheckBox = uicheckbox(app.Capturar1);
            app.PosicinCartesiana3DCheckBox.Text = 'Posición Cartesiana 3D';
            app.ContinuarButton_3 = uibutton(app.Capturar1, 'push');
            app.ContinuarButton_3.ButtonPushedFcn = createCallbackFcn(app, @ContinuarButton_3Pushed, true);
            app.ContinuarButton_3.Text = 'Continuar';
            app.Capturar2 = uipanel(app.UIFigure);
            app.Capturar2.Visible = 'off';
            app.CapturarNuevaTrayectoriaLabel_2 = uilabel(app.Capturar2);
            app.CapturarNuevaTrayectoriaLabel_2.Text = 'Capturar Nueva Trayectoria';
            app.PersonalizarPosicinInicialLabel = uilabel(app.Capturar2);
            app.PersonalizarPosicinInicialLabel.Text = '3.Personalizar Posición Inicial';
            app.BloquearRobotButton = uibutton(app.Capturar2, 'push');
            app.BloquearRobotButton.Text = 'Bloquear Robot';
            app.LiberarEjeXButton = uibutton(app.Capturar2, 'push');
            app.LiberarEjeXButton.Text = 'Liberar Eje X';
            app.LiberarEjeYButton = uibutton(app.Capturar2, 'push');
            app.LiberarEjeYButton.Text = 'Liberar Eje Y';
            app.LiberarEjeZButton = uibutton(app.Capturar2, 'push');
            app.LiberarEjeZButton.Text = 'Liberar Eje Z';
            app.GrabarlaTrayectoriaLabel = uilabel(app.Capturar2);
            app.GrabarlaTrayectoriaLabel.Text = '4.Grabar la Trayectoria';
            app.BloquearRobotButton_2 = uibutton(app.Capturar2, 'push');
            app.BloquearRobotButton_2.Text = 'Bloquear Robot';
            app.IniciarCapturaButton = uibutton(app.Capturar2, 'push');
            app.IniciarCapturaButton.Text = 'Iniciar Captura';
            app.FinalizarCapturaButton = uibutton(app.Capturar2, 'push');
            app.FinalizarCapturaButton.Text = 'Finalizar Captura';
            app.GeneraryGuardarTrayectoriaLabel = uilabel(app.Capturar2);
            app.GeneraryGuardarTrayectoriaLabel.Text = '5.Generar y Guardar Trayectoria';
            app.SeleccionarTrayectoriaDropDownLabel = uilabel(app.Capturar2);
            app.SeleccionarTrayectoriaDropDownLabel.Text = 'Seleccionar Trayectoria';
            app.SeleccionarTrayectoriaDropDown = uidropdown(app.Capturar2);
            app.GuardarTrayectoriaButton = uibutton(app.Capturar2, 'push');
            app.GuardarTrayectoriaButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarTrayectoriaButtonPushed, true);
            app.GuardarTrayectoriaButton.Text = 'Guardar Trayectoria';
            app.RepetirCapturaButton = uibutton(app.Capturar2, 'push');
            app.RepetirCapturaButton.Text = 'Repetir Captura';
            app.Repetir = uipanel(app.UIFigure);
            app.Repetir.Visible = 'off';
            app.RepetirTrayectoriaLabel = uilabel(app.Repetir);
            app.RepetirTrayectoriaLabel.Text = 'Repetir Trayectoria';
            app.SeleccionartrayectoriaarepetirLabel = uilabel(app.Repetir);
            app.SeleccionartrayectoriaarepetirLabel.Text = '1.Seleccionar trayectoria a repetir';
            app.TrayectoriaDropDownLabel = uilabel(app.Repetir);
            app.TrayectoriaDropDownLabel.Text = 'Trayectoria';
            app.TrayectoriaDropDown = uidropdown(app.Repetir);
            app.ModificarvelocidadLabel = uilabel(app.Repetir);
            app.ModificarvelocidadLabel.Text = '2.Modificar velocidad';
            app.Slider = uislider(app.Repetir);
            app.CalibrarsensorFuerzaTensinparaestatrayectoriaLabel = uilabel(app.Repetir);
            app.CalibrarsensorFuerzaTensinparaestatrayectoriaLabel.Text = '3.Calibrar sensor Fuerza-Tensión para esta trayectoria';
            app.MandaralaPosicinInicialButton = uibutton(app.Repetir, 'push');
            app.MandaralaPosicinInicialButton.Text = 'Mandar a la Posición Inicial';
            app.ComprobarRelajacinMuscularButton = uibutton(app.Repetir, 'push');
            app.ComprobarRelajacinMuscularButton.Text = 'Comprobar Relajación Muscular';
            app.EjecutarTrayectoriaenVacoButton = uibutton(app.Repetir, 'push');
            app.EjecutarTrayectoriaenVacoButton.Text = 'Ejecutar Trayectoria en Vacío';
            app.EjecutarTrayectoriaLabel = uilabel(app.Repetir);
            app.EjecutarTrayectoriaLabel.Text = '4.Ejecutar Trayectoria';
            app.MandaralaPosicinInicialButton_2 = uibutton(app.Repetir, 'push');
            app.MandaralaPosicinInicialButton_2.Text = 'Mandar a la Posición Inicial';
            app.ComprobarRelajacinMuscularButton_2 = uibutton(app.Repetir, 'push');
            app.ComprobarRelajacinMuscularButton_2.Text = 'Comprobar Relajación Muscular';
            app.EjecutarTrayectoriaButton = uibutton(app.Repetir, 'push');
            app.EjecutarTrayectoriaButton.Text = 'Ejecutar Trayectoria';
            app.Spinner = uispinner(app.Repetir);
            app.Spinner.Limits = [1 Inf];
            app.Spinner.Value = 1;
            app.VerResultadosButton = uibutton(app.Repetir, 'push');
            app.VerResultadosButton.ButtonPushedFcn = createCallbackFcn(app, @VerResultadosButtonPushed, true);
            app.VerResultadosButton.Text = 'Ver Resultados';
            app.VolveralMenButton_2 = uibutton(app.Repetir, 'push');
            app.VolveralMenButton_2.ButtonPushedFcn = createCallbackFcn(app, @VolveralMenButton_2Pushed, true);
            app.VolveralMenButton_2.Text = 'Volver al Menú';
            app.Resultados = uipanel(app.UIFigure);
            app.Resultados.Visible = 'off';
            app.TrajectoryLabel = uilabel(app.Resultados);
            app.TrajectoryLabel.Text = 'Trayectoria';
            app.JointLabel = uilabel(app.Resultados);
            app.JointLabel.Text = 'Articular';
            app.qButton = uibutton(app.Resultados, 'push');
            app.qButton.Text = 'q';
            app.dqButton = uibutton(app.Resultados, 'push');
            app.dqButton.Text = 'dq';
            app.EqButton = uibutton(app.Resultados, 'push');
            app.EqButton.Text = 'E(q)';
            app.EdqButton = uibutton(app.Resultados, 'push');
            app.EdqButton.Text = 'E(dq)';
            app.CartesianLabel = uilabel(app.Resultados);
            app.CartesianLabel.Text = 'Cartesiana';
            app.xButton = uibutton(app.Resultados, 'push');
            app.xButton.Text = 'x';
            app.ExButton = uibutton(app.Resultados, 'push');
            app.ExButton.Text = 'E(x)';
            app.EdxButton = uibutton(app.Resultados, 'push');
            app.EdxButton.Text = 'E(dx)';
            app.ThreeDButton = uibutton(app.Resultados, 'push');
            app.ThreeDButton.Text = '3D';
            app.dxButton = uibutton(app.Resultados, 'push');
            app.dxButton.Text = 'dx';
            app.ForceSensorLabel = uilabel(app.Resultados);
            app.ForceSensorLabel.Text = 'Sensor Fuerza Par';
            app.FTButton = uibutton(app.Resultados, 'push');
            app.FTButton.Text = 'FT';
            app.DelsysSensorsLabel = uilabel(app.Resultados);
            app.DelsysSensorsLabel.Text = 'Sensores Delsys';
            app.EMGButton = uibutton(app.Resultados, 'push');
            app.EMGButton.Text = 'EMG';
            app.AccelerationsButton = uibutton(app.Resultados, 'push');
            app.AccelerationsButton.Text = 'Aceleraciones';
            app.AngularVelocityButton = uibutton(app.Resultados, 'push');
            app.AngularVelocityButton.Text = 'Velocidad Angular';
            app.ddqButton = uibutton(app.Resultados, 'push');
            app.ddqButton.Text = 'ddq';
            app.EddqButton = uibutton(app.Resultados, 'push');
            app.EddqButton.Text = 'E(ddq)';
            app.EpqButton = uibutton(app.Resultados, 'push');
            app.EpqButton.Text = 'Ep(q)';
            app.EpdqButton = uibutton(app.Resultados, 'push');
            app.EpdqButton.Text = 'Ep(dq)';
            app.EpddqButton = uibutton(app.Resultados, 'push');
            app.EpddqButton.Text = 'Ep(ddq)';
            app.EpxButton = uibutton(app.Resultados, 'push');
            app.EpxButton.Text = 'Ep(x)';
            app.EpdxButton = uibutton(app.Resultados, 'push');
            app.EpdxButton.Text = 'Ep(dx)';
            app.JointPrecisionLabel = uilabel(app.Resultados);
            app.JointPrecisionLabel.Text = 'Precisión:';
            app.JointAccuracyLabel = uilabel(app.Resultados);
            app.JointAccuracyLabel.Text = 'Exactitud:';
            app.CartesianPrecisionLabel = uilabel(app.Resultados);
            app.CartesianPrecisionLabel.Text = 'Precisión:';
            app.CartesianAccuracyLabel = uilabel(app.Resultados);
            app.CartesianAccuracyLabel.Text = 'Exactitud:';
            app.ResultadosLabel = uilabel(app.Resultados);
            app.ResultadosLabel.Text = 'Resultados';
            app.VolveralMenButton_3 = uibutton(app.Resultados, 'push');
            app.VolveralMenButton_3.ButtonPushedFcn = createCallbackFcn(app, @VolveralMenButton_3Pushed, true);
            app.VolveralMenButton_3.Text = 'Volver al Menú';
            app.SeleccionelarepeticinamostrarSpinnerLabel = uilabel(app.Resultados);
            app.SeleccionelarepeticinamostrarSpinnerLabel.Text = 'Seleccione la repetición a mostrar';
            app.SeleccionelarepeticinamostrarSpinner = uispinner(app.Resultados);
            app.SeleccionelarepeticinamostrarSpinner.Limits = [1 Inf];
            app.SeleccionelarepeticinamostrarSpinner.Value = 1;
            app.Sensores = uipanel(app.UIFigure);
            app.Sensores.Visible = 'off';
            app.IndicarlaPosicindelosSensoresenmmLabel = uilabel(app.Sensores);
            app.IndicarlaPosicindelosSensoresenmmLabel.Text = 'Indicar la Posición de los Sensores en mm';
            app.Sensor1BicepsBrachiiLabel = uilabel(app.Sensores);
            app.Sensor1BicepsBrachiiLabel.Text = 'Sensor 1: Biceps Brachii';
            app.Sensor2TricepsLateralHeadLabel = uilabel(app.Sensores);
            app.Sensor2TricepsLateralHeadLabel.Text = 'Sensor 2: Triceps Lateral Head';
            app.Image = uiimage(app.Sensores);
            app.Image.ImageSource = 'ElectrodePositioning1.png';
            app.Image2 = uiimage(app.Sensores);
            app.Image2.ImageSource = 'ElectrodePositioning2.png';
            app.Image3 = uiimage(app.Sensores);
            app.Image3.ImageSource = 'ElectrodePositioning3.png';
            app.Image4 = uiimage(app.Sensores);
            app.Image4.ImageSource = 'ElectrodePositioning4.png';
            app.Sensor3BrachioRadialisLabel = uilabel(app.Sensores);
            app.Sensor3BrachioRadialisLabel.Text = 'Sensor 3: Brachio Radialis';
            app.Sensor4FlexorCarpiRadialisLabel = uilabel(app.Sensores);
            app.Sensor4FlexorCarpiRadialisLabel.Text = 'Sensor 4: Flexor Carpi Radialis';
            app.EditField = uieditfield(app.Sensores, 'text');
            app.EditField.Value = '[0,0,0]';
            app.EditField2 = uieditfield(app.Sensores, 'text');
            app.EditField2.Value = '[0,0,0]';
            app.EditField3 = uieditfield(app.Sensores, 'text');
            app.EditField3.Value = '[0,0,0]';
            app.EditField4 = uieditfield(app.Sensores, 'text');
            app.EditField4.Value = '[0,0,0]';
            app.ContinuarButton_2 = uibutton(app.Sensores, 'push');
            app.ContinuarButton_2.ButtonPushedFcn = createCallbackFcn(app, @ContinuarButton_2Pushed, true);
            app.ContinuarButton_2.Text = 'Continuar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InterfazPrueba

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end