classdef RoboespasDefinitivo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StartPanel                      matlab.ui.container.Panel
        NewPatientButton                matlab.ui.control.Button
        LoadPatientButton               matlab.ui.control.Button
        RoboespasStartImage             matlab.ui.control.Image
        Uc3mStartImage                  matlab.ui.control.Image
        DPILabel                        matlab.ui.control.Label
        TitleLabel                      matlab.ui.control.Label
        RoboespasLabel                  matlab.ui.control.Label
        PatientDataPanel                matlab.ui.container.Panel
        PatientDataLabel                matlab.ui.control.Label
        LeftArmLabel                    matlab.ui.control.Label
        RightArmLabel                   matlab.ui.control.Label
        SavePatientButton               matlab.ui.control.Button
        IDEditFieldLabel                matlab.ui.control.Label
        IDEditField                     matlab.ui.control.NumericEditField
        HeightEditFieldLabel            matlab.ui.control.Label
        HeightEditField                 matlab.ui.control.NumericEditField
        WeightEditFieldLabel            matlab.ui.control.Label
        WeightEditField                 matlab.ui.control.NumericEditField
        AgeEditFieldLabel               matlab.ui.control.Label
        AgeEditField                    matlab.ui.control.NumericEditField
        LeftWristElbowEditFieldLabel    matlab.ui.control.Label
        LeftWristElbowEditField         matlab.ui.control.NumericEditField
        LeftElbowShoulderEditFieldLabel  matlab.ui.control.Label
        LeftElbowShoulderEditField      matlab.ui.control.NumericEditField
        LeftShoulderBreastboneEditFieldLabel  matlab.ui.control.Label
        LeftShoulderBreastboneEditField  matlab.ui.control.NumericEditField
        LeftWristHandleEditFieldLabel   matlab.ui.control.Label
        LeftWristHandleEditField        matlab.ui.control.NumericEditField
        RightWristHandleEditFieldLabel  matlab.ui.control.Label
        RightWristHandleEditField       matlab.ui.control.NumericEditField
        RightWristElbowEditFieldLabel   matlab.ui.control.Label
        RightWristElbowEditField        matlab.ui.control.NumericEditField
        RightElbowShoulderEditFieldLabel  matlab.ui.control.Label
        RightElbowShoulderEditField     matlab.ui.control.NumericEditField
        RightShoulderBreastboneEditFieldLabel  matlab.ui.control.Label
        RightShoulderBreastboneEditField  matlab.ui.control.NumericEditField
        GenderDropDownLabel             matlab.ui.control.Label
        GenderDropDown                  matlab.ui.control.DropDown
        NameEditFieldLabel              matlab.ui.control.Label
        NameEditField                   matlab.ui.control.EditField
        BackToStartPatientDataButton    matlab.ui.control.Button
        SurnamesEditFieldLabel          matlab.ui.control.Label
        SurnamesEditField               matlab.ui.control.EditField
        SpasticityLevelEditFieldLabel   matlab.ui.control.Label
        SpasticityLevelEditField        matlab.ui.control.NumericEditField
        BackToMenuPatientDataButton     matlab.ui.control.Button
        LoadPatientPanel                matlab.ui.container.Panel
        LoadPatientTable                matlab.ui.control.Table
        BackToStartLoadPatientButton    matlab.ui.control.Button
        ChoosePatientButton             matlab.ui.control.Button
        PatientMenuPanel                matlab.ui.container.Panel
        EditPatientButton               matlab.ui.control.Button
        StartTrialButton                matlab.ui.control.Button
        ShowMedicalHistoryButton        matlab.ui.control.Button
        PatientMenuEditFieldLabel       matlab.ui.control.Label
        PatientMenuEditField            matlab.ui.control.NumericEditField
        BackToStartPatientMenuButton    matlab.ui.control.Button
        RoboespasMenuImage              matlab.ui.control.Image
        Uc3mMenuImage                   matlab.ui.control.Image
        MedicalHistoryPanel             matlab.ui.container.Panel
        MedicalHistoryTree              matlab.ui.container.Tree
        PersonalDataPanel               matlab.ui.container.Panel
        LeftArmLabel2                   matlab.ui.control.Label
        RightArmLabel2                  matlab.ui.control.Label
        IDEditFieldLabel2               matlab.ui.control.Label
        IDEditField2                    matlab.ui.control.NumericEditField
        SpasticityLevelEditFieldLabel2  matlab.ui.control.Label
        SpasticityLevelEditField2       matlab.ui.control.NumericEditField
        HeightEditFieldLabel2           matlab.ui.control.Label
        HeightEditField2                matlab.ui.control.NumericEditField
        WeightEditFieldLabel2           matlab.ui.control.Label
        WeightEditField2                matlab.ui.control.NumericEditField
        AgeEditFieldLabel2              matlab.ui.control.Label
        AgeEditField2                   matlab.ui.control.NumericEditField
        LeftWristElbowEditFieldLabel2   matlab.ui.control.Label
        LeftWristElbowEditField2        matlab.ui.control.NumericEditField
        LeftElbowShoulderEditFieldLabel2  matlab.ui.control.Label
        LeftElbowShoulderEditField2     matlab.ui.control.NumericEditField
        LeftShoulderBreastboneEditFieldLabel2  matlab.ui.control.Label
        LeftShoulderBreastboneEditField2  matlab.ui.control.NumericEditField
        LeftWristHandleEditFieldLabel2  matlab.ui.control.Label
        LeftWristHandleEditField2       matlab.ui.control.NumericEditField
        RightWristHandleEditFieldLabel2  matlab.ui.control.Label
        RightWristHandleEditField2      matlab.ui.control.NumericEditField
        RightWristElbowEditFieldLabel2  matlab.ui.control.Label
        RightWristElbowEditField2       matlab.ui.control.NumericEditField
        RightElbowShoulderEditFieldLabel2  matlab.ui.control.Label
        RightElbowShoulderEditField2    matlab.ui.control.NumericEditField
        RightShoulderBreastboneEditFieldLabel2  matlab.ui.control.Label
        RightShoulderBreastboneEditField2  matlab.ui.control.NumericEditField
        NameEditFieldLabel2             matlab.ui.control.Label
        NameEditField2                  matlab.ui.control.EditField
        SurnamesEditFieldLabel2         matlab.ui.control.Label
        SurnamesEditField2              matlab.ui.control.EditField
        GenderEditFieldLabel2           matlab.ui.control.Label
        GenderEditField2                matlab.ui.control.EditField
        BackToMenuMedicalHistoryButton  matlab.ui.control.Button
        ResultsButtonsPanel             matlab.ui.container.Panel
        TrajectoryLabel                 matlab.ui.control.Label
        JointLabel                      matlab.ui.control.Label
        qButton                         matlab.ui.control.Button
        dqButton                        matlab.ui.control.Button
        EqButton                        matlab.ui.control.Button
        EdqButton                       matlab.ui.control.Button
        CartesianLabel                  matlab.ui.control.Label
        xButton                         matlab.ui.control.Button
        ExButton                        matlab.ui.control.Button
        EdxButton                       matlab.ui.control.Button
        ThreeDButton                    matlab.ui.control.Button
        dxButton                        matlab.ui.control.Button
        ForceSensorLabel                matlab.ui.control.Label
        FTButton                        matlab.ui.control.Button
        DelsysSensorsLabel              matlab.ui.control.Label
        EMGButton                       matlab.ui.control.Button
        AccelerationsButton             matlab.ui.control.Button
        AngularVelocityButton           matlab.ui.control.Button
        ddqButton                       matlab.ui.control.Button
        EddqButton                      matlab.ui.control.Button
        EpqButton                       matlab.ui.control.Button
        EpdqButton                      matlab.ui.control.Button
        EpddqButton                     matlab.ui.control.Button
        EpxButton                       matlab.ui.control.Button
        EpdxButton                      matlab.ui.control.Button
        JointPrecisionLabel             matlab.ui.control.Label
        JointAccuracyLabel              matlab.ui.control.Label
        CartesianPrecisionLabel         matlab.ui.control.Label
        CartesianAccuracyLabel          matlab.ui.control.Label
        TrialDataPanel                  matlab.ui.container.Panel
        TrialDataPatientEditField       matlab.ui.control.EditField
        TrialDataChoosedArmEditField    matlab.ui.control.EditField
        TrialDataTrajectoryDateCapturedEditField  matlab.ui.control.EditField
        TrialDataTrajectoryDateExecutedEditField  matlab.ui.control.EditField
        TrialDataChoosedWorkingPlaneEditField  matlab.ui.control.EditField
        TrialDataLabel                  matlab.ui.control.Label
        PlotsPanel                      matlab.ui.container.Panel
        CloseImage                      matlab.ui.control.Image
        SessionInfoPanel                matlab.ui.container.Panel
        SessionInfoLabel                matlab.ui.control.Label
        ChooseArmLabel                  matlab.ui.control.Label
        ChoosePlaneLabel                matlab.ui.control.Label
        ChooseMovementLabel             matlab.ui.control.Label
        ArmSwitch                       matlab.ui.control.Switch
        PlaneSwitch                     matlab.ui.control.Switch
        MovementDropDown                matlab.ui.control.DropDown
        ConnectSensorsLabel             matlab.ui.control.Label
        IIWACheckBox                    matlab.ui.control.CheckBox
        FTSensorCheckBox                matlab.ui.control.CheckBox
        DelsysSensorsCheckBox           matlab.ui.control.CheckBox
        GoToSensorsPanelButton          matlab.ui.control.Button
        BackToMenuSessionInfoButton     matlab.ui.control.Button
        GoToTrialMenuButton             matlab.ui.control.Button
        SensorsPanel                    matlab.ui.container.Panel
        SensorsLabel                    matlab.ui.control.Label
        Sensor1Label                    matlab.ui.control.Label
        Sensor2Label                    matlab.ui.control.Label
        Sensor1Image                    matlab.ui.control.Image
        Sensor2Image                    matlab.ui.control.Image
        Sensor3Image                    matlab.ui.control.Image
        Sensor4Image                    matlab.ui.control.Image
        Sensor3Label                    matlab.ui.control.Label
        Sensor4Label                    matlab.ui.control.Label
        Sensor1PosEditField             matlab.ui.control.EditField
        Sensor2PosEditField             matlab.ui.control.EditField
        Sensor3PosEditField             matlab.ui.control.EditField
        Sensor4PosEditField             matlab.ui.control.EditField
        BackToSessionInfoSensorsButton  matlab.ui.control.Button
        TrialMenuPanel                  matlab.ui.container.Panel
        SummaryPanel                    matlab.ui.container.Panel
        SummaryLabel                    matlab.ui.control.Label
        IIWASummaryLabel                matlab.ui.control.Label
        FTSensorSummaryLabel            matlab.ui.control.Label
        DelsysSensorsSummaryLabel       matlab.ui.control.Label
        ArmSummaryLabel                 matlab.ui.control.Label
        PlaneSummaryLabel               matlab.ui.control.Label
        Sensor1SummaryLabel             matlab.ui.control.Label
        Sensor2SummaryLabel             matlab.ui.control.Label
        Sensor3SummaryLabel             matlab.ui.control.Label
        Sensor4SummaryLabel             matlab.ui.control.Label
        MovementSummaryLabel            matlab.ui.control.Label
        GoToCaptureButton               matlab.ui.control.Button
        GoToRepeatButton                matlab.ui.control.Button
        BackToSessionInfoTrialMenuButton  matlab.ui.control.Button
        BackToMenuTrialMenuButton       matlab.ui.control.Button
        CaptureTrajectory1Panel         matlab.ui.container.Panel
        Capture1Label                   matlab.ui.control.Label
        MoveReferencePosLabel           matlab.ui.control.Label
        LeftReferencePosButton          matlab.ui.control.Button
        RightReferencePosButton         matlab.ui.control.Button
        ConfigureTrajectorySettingsLabel  matlab.ui.control.Label
        SmoothingLabel                  matlab.ui.control.Label
        ChoosePlotsLabel                matlab.ui.control.Label
        PlotAdjustmentCheckBox          matlab.ui.control.CheckBox
        PlotCartesianPosCheckBox        matlab.ui.control.CheckBox
        PlotCartesianVelCheckBox        matlab.ui.control.CheckBox
        PlotErrorCartesianPosCheckBox   matlab.ui.control.CheckBox
        PlotErrorCartesianVelCheckBox   matlab.ui.control.CheckBox
        Plot3DCartesianPosCheckBox      matlab.ui.control.CheckBox
        GoToCapture2Button              matlab.ui.control.Button
        InputSmoothingEditFieldLabel    matlab.ui.control.Label
        InputSmoothingEditField         matlab.ui.control.NumericEditField
        CommandedSmoothingEditFieldLabel  matlab.ui.control.Label
        CommandedSmoothingEditField     matlab.ui.control.NumericEditField
        OutputSmoothingEditFieldLabel   matlab.ui.control.Label
        OutputSmoothingEditField        matlab.ui.control.NumericEditField
        CaptureTrajectory2Panel         matlab.ui.container.Panel
        Capture2Label                   matlab.ui.control.Label
        InitialPositionLabel            matlab.ui.control.Label
        FreeBlockRobotButton            matlab.ui.control.Button
        FreeXButton                     matlab.ui.control.Button
        FreeYButton                     matlab.ui.control.Button
        FreeZButton                     matlab.ui.control.Button
        CaptureTrajectoryLabel          matlab.ui.control.Label
        StartCaptureButton              matlab.ui.control.Button
        EndCaptureButton                matlab.ui.control.Button
        GenerateSaveTrajectoryLabel     matlab.ui.control.Label
        SaveTrajectoryButton            matlab.ui.control.Button
        RepeatCaptureButton             matlab.ui.control.Button
        InputDropDownLabel              matlab.ui.control.Label
        InputDropDown                   matlab.ui.control.DropDown
        RepeatTrajectoryPanel           matlab.ui.container.Panel
        RepeatTrajectoryLabel           matlab.ui.control.Label
        ChooseTrajectoryLabel           matlab.ui.control.Label
        ChangeVelocityLabel             matlab.ui.control.Label
        VelocitySlider                  matlab.ui.control.Slider
        CalibrateFTSensorLabel          matlab.ui.control.Label
        InitialPositionEmptyButton      matlab.ui.control.Button
        RunTrajectoryEmptyButton        matlab.ui.control.Button
        RunTrajectoryLabel              matlab.ui.control.Label
        InitialPositionRunButton        matlab.ui.control.Button
        RunTrajectoryButton             matlab.ui.control.Button
        RepetitionsSpinner              matlab.ui.control.Spinner
        GoToResultsButton               matlab.ui.control.Button
        BackToTrialMenuRepeatButton     matlab.ui.control.Button
        AdjustedTrajectoryDropDownLabel  matlab.ui.control.Label
        AdjustedTrajectoryDropDown      matlab.ui.control.DropDown
        VelocityLabel                   matlab.ui.control.Label
        RelaxMusclesEmptyButton         matlab.ui.control.StateButton
        RelaxMusclesRunButton           matlab.ui.control.StateButton
        ResultsPanel                    matlab.ui.container.Panel
        Trajectory2Label                matlab.ui.control.Label
        Joint2Label                     matlab.ui.control.Label
        q2Button                        matlab.ui.control.Button
        dq2Button                       matlab.ui.control.Button
        Eq2Button                       matlab.ui.control.Button
        Edq2Button                      matlab.ui.control.Button
        Cartesian2Label                 matlab.ui.control.Label
        x2Button                        matlab.ui.control.Button
        Ex2Button                       matlab.ui.control.Button
        Edx2Button                      matlab.ui.control.Button
        ThreeD2Button                   matlab.ui.control.Button
        dx2Button                       matlab.ui.control.Button
        ForceSensor2Label               matlab.ui.control.Label
        FT2Button                       matlab.ui.control.Button
        DelsysSensors2Label             matlab.ui.control.Label
        EMG2Button                      matlab.ui.control.Button
        Accelerations2Button            matlab.ui.control.Button
        AngularVelocity2Button          matlab.ui.control.Button
        ddq2Button                      matlab.ui.control.Button
        Eddq2Button                     matlab.ui.control.Button
        Epq2Button                      matlab.ui.control.Button
        Epdq2Button                     matlab.ui.control.Button
        Epddq2Button                    matlab.ui.control.Button
        Epx2Button                      matlab.ui.control.Button
        Epdx2Button                     matlab.ui.control.Button
        JointPrecision2Label            matlab.ui.control.Label
        JointAccuracy2Label             matlab.ui.control.Label
        CartesianPrecision2Label        matlab.ui.control.Label
        CartesianAccuracy2Label         matlab.ui.control.Label
        ResultsLabel                    matlab.ui.control.Label
        BackToTrialMenuResultsButton    matlab.ui.control.Button
        RepetitionPlotSpinnerLabel      matlab.ui.control.Label
        RepetitionPlotSpinner           matlab.ui.control.Spinner
        RepetitionPlotLabel             matlab.ui.control.Label
    end

    
    properties(Constant)
        
        PatientsDatabasesFilePath = [fileparts(which(mfilename)),'\patientsDB.mat'];
        NamesIDDatabasesFilePath = [fileparts(which(mfilename)),'\namesid.mat'];
        TrialsDatabasesFilePath = [fileparts(which(mfilename)),'\trials.mat'];
        %TrialsDatabaseBackupFolderPath = fullfile(extractBetween(which(mfilename), 1, 'RoboespasMatlab\'), '\RoboespasMatlab\Backups\', extractAfter(fileparts(which(mfilename)), find(fileparts(which(mfilename)) == '\', 1, 'last')));
        
        DelsysSensorsConnected = 0; 
        FTSensorConnected = 0; 
        ROSConnected = 0;
        
        TimeWaitBetweenRepetitions = 6 
        TrajectorySelectStr = {'Seleccione una trayectoria'};
        MovementSelectStr = {'Seleccione un movimiento'};
        
        MovementMap = containers.Map({'Flexión-Extensión Codo'}, {'flexext'});
        MaxSensors = 6;
        DefaultVelocity = 15
        
    end
    
    properties (Access = private)
        
        IIWAControlObject
        FTSensorObject
        DelsysSensorsObject
        IDPatient
        Arm
        NameMovement
        WorkingPlane
        Month
        Return
        
        ExecutionTimes = {};
        TrialsLoad
        TrialsFile
        
    end
    
    methods (Access = private)
        
        function UpdateInputDropDown(app)
            try
                app.InputDropDown.Items=[app.TrajectorySelectStr, app.IIWAControlObject.DataInputList();];
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error')
            end
        end
  
        function UpdateAdjustedTrajectoryDropDown(app)
            try
                app.AdjustedTrajectoryDropDown.Items=[app.TrajectorySelectStr, app.IIWAControlObject.DataCommandedList()];
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end
        
        function UpdateMovementDropDown(app)
            try
                app.MovementDropDown.Items = [app.MovementSelectStr, keys(app.MovementMap)];
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');                
            end
        end
        
        function SaveTrial(app, i_trial)
            try
                tic
                app.TrialsFile = matfile(app.TrialsDatabasesFilePath, 'Writable', true);
                if (~isempty(who(app.TrialsFile)))
                    ntrial=size(app.TrialsFile.Trials,1);
                    IDTrial=ntrial+1;
                else
                    IDTrial=1;
                end
                Trial.IDTrial = IDTrial;
                Trial.IDPatient = app.IDPatient;
                Trial.Conditions.NameData = app.IIWAControlObject.NameData;
                Trial.Conditions.Arm = app.Arm;
                Trial.Conditions.WorkingPlane = app.WorkingPlane;
                Trial.Conditions.DateCaptured = app.IIWAControlObject.CapturedDate;
                Trial.Conditions.DateExecuted = datestr(now, 'yymmdd_HHMMSS');
                Trial.Trajectory=app.IIWAControlObject.GetStruct(i_trial);
                Trial.FTSensor=app.FTSensorObject.GetStruct(i_trial);
                Trial.DelsysSensors=app.DelsysSensorsObject.GetStruct(i_trial);
                
                if (~isempty(app.ExecutionTimes))
                    Trial.ExecutionTimes = app.ExecutionTimes{i_trial}; 
                else
                    Trial.ExecutionTimes=[];
                end
                app.TrialsFile.Trials(IDTrial,1) = Trial;
                disp(['Salvado el trial ', num2str(IDTrial)]);
%                saveEveryNTrials=1;
%                 if (mod(IDTrial,saveEveryNTrials) == 0) %Save backup every trial
%                     TrialsBackupFilePath = [app.TrialsDatabaseBackupFolderPath{1}, 'trials', datestr(now, '_yyyy_mm_dd_HH_MM'), '.mat'];
%                     [status, msg] = copyfile(app.TrialsDatabasesFilePath, TrialsBackupFilePath, 'f');
%                     if (status~=1)
%                         uialert(app.UIFigure, msg, '');
%                     else
%                         %uialert(app.UIFigure, ['Backup saved in', TrialsBackupFilePath], '', 'Icon', 'success');
%                     end
%                 end
                toc
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end
        
        function SetCurrentSliderVelocity(app)
            prg=uiprogressdlg(app.UIFigure, 'Message', 'Ajustando trayectoria a la velocidad requerida');
            try
                v=round(app.VelocitySlider.Value/app.IIWAControlObject.InputAngularVelocity,2);
                prg.Message=['Ajustando trayectoria a velocidad ', num2str(app.VelocitySlider.Value), char(176), '/s'];
                app.IIWAControlObject.ChangeVelocity(v);
                app.VelocityLabel.Text=[num2str(round(app.VelocitySlider.Value,2)), ' ', char(176), '/s'];
                app.InitialPositionRunButton.Enable='off';
                app.RunTrajectoryButton.Enable='off';
                app.InitialPositionEmptyButton.Enable='on';
                close(prg);
            catch ME
                close(prg);
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end
        
        function SaveSensorsPosition(app)
            try
                posArray = zeros(app.DelsysSensorsObject.NumSensors,3);
                for i = 1:app.DelsysSensorsObject.NumSensors
                    posEditField = sprintf('Sensor%dPosEditField', i);
                    value = app.(posEditField).Value;
                    valueArray = str2double(split(erase(value, {'[',']'}),{' ',','}));
                    posArray(i,:) = valueArray(~isnan(valueArray))';
                end
                app.DelsysSensorsObject.SetSensorsPosition(posArray);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');                
            end
            
        end
        
        function ShowCheckedPlots(app)
            try
                if app.PlotCartesianPosCheckBox.Value == 1
                    app.IIWAControlObject.PlotCartesianPositionTrajectories('adjust', 1, figure);
                end
                if app.PlotCartesianVelCheckBox.Value == 1
                    app.IIWAControlObject.PlotCartesianVelocityTrajectories('adjust', 1, figure);
                end
                if app.Plot3DCartesianPosCheckBox.Value == 1
                    app.IIWAControlObject.Plot3DCartesianPositionTrajectories('adjust', 1, figure);
                end
                if app.PlotErrorCartesianPosCheckBox.Value == 1
                    app.IIWAControlObject.PlotCartesianPositionError('adjust', 1, figure);
                end
                if app.PlotErrorCartesianVelCheckBox.Value == 1
                    app.IIWAControlObject.PlotCartesianVelocityError('adjust', 1, figure);
                end
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error')
            end
        end
        
        function EnablePlotButtons(app, origin, value)
            try
                if value
                    state = 'on';
                else
                    state = 'off';
                end
                switch(origin)
                    case 'iiwa'
                        app.q2Button.Enable = state;
                        app.dq2Button.Enable = state;
                        app.ddq2Button.Enable = state;
                        app.Epq2Button.Enable = state;
                        app.Epdq2Button.Enable = state;
                        app.Epddq2Button.Enable = state;
                        app.x2Button.Enable = state;
                        app.dx2Button.Enable = state;
                        app.ThreeD2Button.Enable = state;
                        app.Epx2Button.Enable = state;
                        app.Epdx2Button.Enable = state;
                    case 'ft'
                        app.FT2Button.Enable = state;
                    case 'delsys'
                        app.EMG2Button.Enable = state;
                        app.Accelerations2Button.Enable = state;
                        app.AngularVelocity2Button.Enable = state;                    
                end
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error')
            end
        end
        
        function EMGrealTimePlot(app, value)
            try
                if(value)
                    app.DelsysSensorsObject.StartEMGplot(app.RepeatTrajectoryPanel);
                else
                    app.DelsysSensorsObject.StopEMGplot();
                end
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error')
            end
        end
        
        function ResizeApp(app)
            try
            
                %StartPanel
                
                app.StartPanel.Position = [0 0 app.UIFigure.Position(3) app.UIFigure.Position(4)];
                
                app.TitleLabel.Position(4)=app.StartPanel.Position(4)*(1/10);
                app.TitleLabel.Position(3)=app.TitleLabel.Position(4)*(6.3);
                app.TitleLabel.Position(1)=app.StartPanel.Position(3)*(1/2)-app.TitleLabel.Position(3)*(1/2);
                app.TitleLabel.Position(2)=app.StartPanel.Position(4)*(9/10);
                app.TitleLabel.FontSize=app.TitleLabel.Position(4)-app.TitleLabel.Position(4)*0.45;
                
                app.RoboespasLabel.Position(4)=app.StartPanel.Position(4)*(1/4);
                app.RoboespasLabel.Position(3)=app.RoboespasLabel.Position(4)*(2.4);
                app.RoboespasLabel.Position(1)=app.StartPanel.Position(3)*(1/2)-app.RoboespasLabel.Position(3)*(1/2);
                app.RoboespasLabel.Position(2)=app.StartPanel.Position(4)*(6.5/10);
                app.RoboespasLabel.FontSize=app.RoboespasLabel.Position(4)-app.RoboespasLabel.Position(4)*0.45;
                
                app.NewPatientButton.Position(3)=app.StartPanel.Position(3)*(1/4);
                app.NewPatientButton.Position(4)=app.NewPatientButton.Position(3)*(0.18);
                app.NewPatientButton.Position(1)=app.StartPanel.Position(3)*(1/2)-app.NewPatientButton.Position(3)*(1/2);
                app.NewPatientButton.Position(2)=app.StartPanel.Position(4)*(5/10);
                app.NewPatientButton.FontSize=app.NewPatientButton.Position(4)-app.NewPatientButton.Position(4)*0.45;
                
                app.LoadPatientButton.Position(3)=app.StartPanel.Position(3)*(1/4);
                app.LoadPatientButton.Position(4)=app.LoadPatientButton.Position(3)*(0.18);
                app.LoadPatientButton.Position(1)=app.StartPanel.Position(3)*(1/2)-app.LoadPatientButton.Position(3)*(1/2);
                app.LoadPatientButton.Position(2)=app.StartPanel.Position(4)*(3/10);
                app.LoadPatientButton.FontSize=app.LoadPatientButton.Position(4)-app.LoadPatientButton.Position(4)*0.45;
                
                app.RoboespasStartImage.Position(3)=app.StartPanel.Position(3)*(1/3);
                app.RoboespasStartImage.Position(4)=app.RoboespasStartImage.Position(3)*(0.84);
                app.RoboespasStartImage.Position(1)=app.StartPanel.Position(1);
                app.RoboespasStartImage.Position(2)=app.StartPanel.Position(4)*(4.5/10)-app.RoboespasStartImage.Position(4)*(1/2);
                
                app.Uc3mStartImage.Position(3)=app.StartPanel.Position(3)*(1/4);
                app.Uc3mStartImage.Position(4)=app.Uc3mStartImage.Position(3)*(0.54);
                app.Uc3mStartImage.Position(1)=app.StartPanel.Position(3)-app.Uc3mStartImage.Position(3)-app.Uc3mStartImage.Position(3)*(2/10);
                app.Uc3mStartImage.Position(2)=app.StartPanel.Position(4)*(4.5/10)-app.Uc3mStartImage.Position(4)*(1/2);
                
                app.DPILabel.Position(4)=app.StartPanel.Position(4)*(1/20);
                app.DPILabel.Position(3)=app.DPILabel.Position(4)*(8.4);
                app.DPILabel.Position(1)=app.StartPanel.Position(3)*(1/2)-app.DPILabel.Position(3)*(1/2);
                app.DPILabel.Position(2)=app.StartPanel.Position(4)*(1/10);
                app.DPILabel.FontSize=app.DPILabel.Position(4)-app.DPILabel.Position(4)*0.45;
                
                %PatientDataPanel
                
                app.PatientDataPanel.Position = [0 0 app.UIFigure.Position(3) app.UIFigure.Position(4)];
                
                app.PatientDataLabel.Position(4)=app.PatientDataPanel.Position(4)*(1/14);
                app.PatientDataLabel.Position(3)=app.PatientDataLabel.Position(4)*(8);
                app.PatientDataLabel.Position(1)=app.PatientDataPanel.Position(3)*(1/2)-app.PatientDataLabel.Position(3)*(1/2);
                app.PatientDataLabel.Position(2)=app.PatientDataPanel.Position(4)*(25/28);
                app.PatientDataLabel.FontSize=app.PatientDataLabel.Position(4)-app.PatientDataLabel.Position(4)*0.45;
                
                app.NameEditField.Position(3)=app.PatientDataPanel.Position(3)*(1/10);
                app.NameEditField.Position(4)=app.PatientDataPanel.Position(4)*(1/28);
                app.NameEditField.Position(1)=app.PatientDataPanel.Position(3)*(1/5)-app.NameEditField.Position(3)*(1/2);
                app.NameEditField.Position(2)=app.PatientDataPanel.Position(4)*(11/14);
                app.NameEditField.FontSize=app.NameEditField.Position(4)-app.NameEditField.Position(4)*0.45;
                app.NameEditFieldLabel.Position(3)=app.NameEditField.Position(3);
                app.NameEditFieldLabel.Position(4)=app.NameEditField.Position(4);
                app.NameEditFieldLabel.Position(1)=app.NameEditField.Position(1)-app.NameEditFieldLabel.Position(3)-app.NameEditField.Position(3)*(1/10);
                app.NameEditFieldLabel.Position(2)=app.PatientDataPanel.Position(4)*(11/14);
                app.NameEditFieldLabel.FontSize=app.NameEditField.FontSize;
                
                app.SurnamesEditField.Position(3)=app.PatientDataPanel.Position(3)*(1/5);
                app.SurnamesEditField.Position(4)=app.PatientDataPanel.Position(4)*(1/28);
                app.SurnamesEditField.Position(1)=app.PatientDataPanel.Position(3)*(1/2)-app.SurnamesEditField.Position(3)*(1/2);
                app.SurnamesEditField.Position(2)=app.PatientDataPanel.Position(4)*(11/14);
                app.SurnamesEditField.FontSize=app.SurnamesEditField.Position(4)-app.SurnamesEditField.Position(4)*0.45;
                app.SurnamesEditFieldLabel.Position(3)=app.SurnamesEditField.Position(3);
                app.SurnamesEditFieldLabel.Position(4)=app.SurnamesEditField.Position(4);
                app.SurnamesEditFieldLabel.Position(1)=app.SurnamesEditField.Position(1)-app.SurnamesEditFieldLabel.Position(3)-app.SurnamesEditField.Position(3)*(1/10);
                app.SurnamesEditFieldLabel.Position(2)=app.PatientDataPanel.Position(4)*(11/14);
                app.SurnamesEditFieldLabel.FontSize=app.SurnamesEditField.FontSize;
                
                app.IDEditField.Position(3)=app.PatientDataPanel.Position(3)*(1/10);
                app.IDEditField.Position(4)=app.PatientDataPanel.Position(4)*(1/28);
                app.IDEditField.Position(1)=app.PatientDataPanel.Position(3)*(1/5)-app.IDEditField.Position(3)*(1/2);
                app.IDEditField.Position(2)=app.PatientDataPanel.Position(4)*(10/14);
                app.IDEditField.FontSize=app.IDEditField.Position(4)-app.IDEditField.Position(4)*0.45;
                app.IDEditFieldLabel.Position(3)=app.IDEditField.Position(3);
                app.IDEditFieldLabel.Position(4)=app.IDEditField.Position(4);
                app.IDEditFieldLabel.Position(1)=app.IDEditField.Position(1)-app.IDEditFieldLabel.Position(3)-app.IDEditField.Position(3)*(1/10);
                app.IDEditFieldLabel.Position(2)=app.PatientDataPanel.Position(4)*(10/14);
                app.IDEditFieldLabel.FontSize=app.IDEditField.FontSize;
                
                app.SpasticityLevelEditField.Position(3)=app.PatientDataPanel.Position(3)*(1/10);
                app.SpasticityLevelEditField.Position(4)=app.PatientDataPanel.Position(4)*(1/28);
                app.SpasticityLevelEditField.Position(1)=app.SurnamesEditField.Position(1)+app.SurnamesEditField.Position(3)-app.SpasticityLevelEditField.Position(3);
                app.SpasticityLevelEditField.Position(2)=app.PatientDataPanel.Position(4)*(10/14);
                app.SpasticityLevelEditField.FontSize=app.SpasticityLevelEditField.Position(4)-app.SpasticityLevelEditField.Position(4)*0.45;
                app.SpasticityLevelEditFieldLabel.Position(3)=app.SpasticityLevelEditField.Position(3)*(2.5);
                app.SpasticityLevelEditFieldLabel.Position(4)=app.SpasticityLevelEditField.Position(4);
                app.SpasticityLevelEditFieldLabel.Position(1)=app.SpasticityLevelEditField.Position(1)-app.SpasticityLevelEditFieldLabel.Position(3)-app.SpasticityLevelEditField.Position(3)*(1/10);
                app.SpasticityLevelEditFieldLabel.Position(2)=app.PatientDataPanel.Position(4)*(10/14);
                app.SpasticityLevelEditFieldLabel.FontSize=app.SpasticityLevelEditField.FontSize;
                
                app.AgeEditField.Position(3)=app.PatientDataPanel.Position(3)*(1/10);
                app.AgeEditField.Position(4)=app.PatientDataPanel.Position(4)*(1/28);
                app.AgeEditField.Position(1)=app.PatientDataPanel.Position(3)*(4/5)-app.AgeEditField.Position(3)*(1/2);
                app.AgeEditField.Position(2)=app.PatientDataPanel.Position(4)*(10/14);
                app.AgeEditField.FontSize=app.AgeEditField.Position(4)-app.AgeEditField.Position(4)*0.45;
                app.AgeEditFieldLabel.Position(3)=app.AgeEditField.Position(3);
                app.AgeEditFieldLabel.Position(4)=app.AgeEditField.Position(4);
                app.AgeEditFieldLabel.Position(1)=app.AgeEditField.Position(1)-app.AgeEditFieldLabel.Position(3)-app.AgeEditField.Position(3)*(1/10);
                app.AgeEditFieldLabel.Position(2)=app.PatientDataPanel.Position(4)*(10/14);
                app.AgeEditFieldLabel.FontSize=app.AgeEditField.FontSize;
                
                app.HeightEditField.Position(3)=app.PatientDataPanel.Position(3)*(1/10);
                app.HeightEditField.Position(4)=app.PatientDataPanel.Position(4)*(1/28);
                app.HeightEditField.Position(1)=app.PatientDataPanel.Position(3)*(1/5)-app.HeightEditField.Position(3)*(1/2);
                app.HeightEditField.Position(2)=app.PatientDataPanel.Position(4)*(9/14);
                app.HeightEditField.FontSize=app.HeightEditField.Position(4)-app.HeightEditField.Position(4)*0.45;
                app.HeightEditFieldLabel.Position(3)=app.HeightEditField.Position(3);
                app.HeightEditFieldLabel.Position(4)=app.HeightEditField.Position(4);
                app.HeightEditFieldLabel.Position(1)=app.HeightEditField.Position(1)-app.HeightEditFieldLabel.Position(3)-app.HeightEditField.Position(3)*(1/10);
                app.HeightEditFieldLabel.Position(2)=app.PatientDataPanel.Position(4)*(9/14);
                app.HeightEditFieldLabel.FontSize=app.HeightEditField.FontSize;
                
                app.WeightEditField.Position(3)=app.PatientDataPanel.Position(3)*(1/10);
                app.WeightEditField.Position(4)=app.PatientDataPanel.Position(4)*(1/28);
                app.WeightEditField.Position(1)=app.SurnamesEditField.Position(1)+app.SurnamesEditField.Position(3)-app.WeightEditField.Position(3);
                app.WeightEditField.Position(2)=app.PatientDataPanel.Position(4)*(9/14);
                app.WeightEditField.FontSize=app.WeightEditField.Position(4)-app.WeightEditField.Position(4)*0.45;
                app.WeightEditFieldLabel.Position(3)=app.WeightEditField.Position(3);
                app.WeightEditFieldLabel.Position(4)=app.WeightEditField.Position(4);
                app.WeightEditFieldLabel.Position(1)=app.WeightEditField.Position(1)-app.WeightEditFieldLabel.Position(3)-app.WeightEditField.Position(3)*(1/10);
                app.WeightEditFieldLabel.Position(2)=app.PatientDataPanel.Position(4)*(9/14);
                app.WeightEditFieldLabel.FontSize=app.WeightEditField.FontSize;
                
                app.GenderDropDown.Position(3)=app.PatientDataPanel.Position(3)*(1/8);
                app.GenderDropDown.Position(4)=app.PatientDataPanel.Position(4)*(1/28);
                app.GenderDropDown.Position(1)=app.AgeEditField.Position(1)+app.AgeEditField.Position(3)-app.GenderDropDown.Position(3);
                app.GenderDropDown.Position(2)=app.PatientDataPanel.Position(4)*(9/14);
                app.GenderDropDown.FontSize=app.GenderDropDown.Position(4)-app.GenderDropDown.Position(4)*0.45;
                app.GenderDropDownLabel.Position(3)=app.GenderDropDown.Position(3);
                app.GenderDropDownLabel.Position(4)=app.GenderDropDown.Position(4);
                app.GenderDropDownLabel.Position(1)=app.GenderDropDown.Position(1)-app.GenderDropDownLabel.Position(3)-app.GenderDropDown.Position(3)*(1/10);
                app.GenderDropDownLabel.Position(2)=app.PatientDataPanel.Position(4)*(9/14);
                app.GenderDropDownLabel.FontSize=app.GenderDropDown.FontSize;
                
                app.LeftArmLabel.Position(3)=app.PatientDataPanel.Position(3)*(1/5);
                app.LeftArmLabel.Position(4)=app.PatientDataPanel.Position(4)*(1/28);
                app.LeftArmLabel.Position(1)=app.PatientDataPanel.Position(3)*(1/3)-app.LeftArmLabel.Position(3)*(1/2);
                app.LeftArmLabel.Position(2)=app.PatientDataPanel.Position(4)*(7/14);
                app.LeftArmLabel.FontSize=app.LeftArmLabel.Position(4)-app.LeftArmLabel.Position(4)*0.45;
                
                app.RightArmLabel.Position(3)=app.PatientDataPanel.Position(3)*(1/5);
                app.RightArmLabel.Position(4)=app.PatientDataPanel.Position(4)*(1/28);
                app.RightArmLabel.Position(1)=app.PatientDataPanel.Position(3)*(3/4)-app.RightArmLabel.Position(3)*(1/2);
                app.RightArmLabel.Position(2)=app.PatientDataPanel.Position(4)*(7/14);
                app.RightArmLabel.FontSize=app.RightArmLabel.Position(4)-app.RightArmLabel.Position(4)*0.45;
                
                app.LeftWristElbowEditField.Position(3)=app.PatientDataPanel.Position(3)*(1/5);
                app.LeftWristElbowEditField.Position(4)=app.PatientDataPanel.Position(4)*(1/28);
                app.LeftWristElbowEditField.Position(1)=app.PatientDataPanel.Position(3)*(1/3)-app.LeftWristElbowEditField.Position(3)*(1/2);
                app.LeftWristElbowEditField.Position(2)=app.PatientDataPanel.Position(4)*(6/14);
                app.LeftWristElbowEditField.FontSize=app.LeftWristElbowEditField.Position(4)-app.LeftWristElbowEditField.Position(4)*0.45;
                app.LeftWristElbowEditFieldLabel.Position(3)=app.LeftWristElbowEditField.Position(3);
                app.LeftWristElbowEditFieldLabel.Position(4)=app.LeftWristElbowEditField.Position(4);
                app.LeftWristElbowEditFieldLabel.Position(1)=app.LeftWristElbowEditField.Position(1)-app.LeftWristElbowEditFieldLabel.Position(3)-app.LeftWristElbowEditField.Position(3)*(1/10);
                app.LeftWristElbowEditFieldLabel.Position(2)=app.PatientDataPanel.Position(4)*(6/14);
                app.LeftWristElbowEditFieldLabel.FontSize=app.LeftWristElbowEditField.FontSize;
                
                app.RightWristElbowEditField.Position(3)=app.PatientDataPanel.Position(3)*(1/5);
                app.RightWristElbowEditField.Position(4)=app.PatientDataPanel.Position(4)*(1/28);
                app.RightWristElbowEditField.Position(1)=app.PatientDataPanel.Position(3)*(3/4)-app.RightWristElbowEditField.Position(3)*(1/2);
                app.RightWristElbowEditField.Position(2)=app.PatientDataPanel.Position(4)*(6/14);
                app.RightWristElbowEditField.FontSize=app.RightWristElbowEditField.Position(4)-app.RightWristElbowEditField.Position(4)*0.45;
                app.RightWristElbowEditFieldLabel.Position(3)=app.RightWristElbowEditField.Position(3);
                app.RightWristElbowEditFieldLabel.Position(4)=app.RightWristElbowEditField.Position(4);
                app.RightWristElbowEditFieldLabel.Position(1)=app.RightWristElbowEditField.Position(1)-app.RightWristElbowEditFieldLabel.Position(3)-app.RightWristElbowEditField.Position(3)*(1/10);
                app.RightWristElbowEditFieldLabel.Position(2)=app.PatientDataPanel.Position(4)*(6/14);
                app.RightWristElbowEditFieldLabel.FontSize=app.RightWristElbowEditField.FontSize;
                
                app.LeftElbowShoulderEditField.Position(3)=app.PatientDataPanel.Position(3)*(1/5);
                app.LeftElbowShoulderEditField.Position(4)=app.PatientDataPanel.Position(4)*(1/28);
                app.LeftElbowShoulderEditField.Position(1)=app.PatientDataPanel.Position(3)*(1/3)-app.LeftElbowShoulderEditField.Position(3)*(1/2);
                app.LeftElbowShoulderEditField.Position(2)=app.PatientDataPanel.Position(4)*(5/14);
                app.LeftElbowShoulderEditField.FontSize=app.LeftElbowShoulderEditField.Position(4)-app.LeftElbowShoulderEditField.Position(4)*0.45;
                app.LeftElbowShoulderEditFieldLabel.Position(3)=app.LeftElbowShoulderEditField.Position(3);
                app.LeftElbowShoulderEditFieldLabel.Position(4)=app.LeftElbowShoulderEditField.Position(4);
                app.LeftElbowShoulderEditFieldLabel.Position(1)=app.LeftElbowShoulderEditField.Position(1)-app.LeftElbowShoulderEditFieldLabel.Position(3)-app.LeftElbowShoulderEditField.Position(3)*(1/10);
                app.LeftElbowShoulderEditFieldLabel.Position(2)=app.PatientDataPanel.Position(4)*(5/14);
                app.LeftElbowShoulderEditFieldLabel.FontSize=app.LeftElbowShoulderEditField.FontSize;
                
                app.RightElbowShoulderEditField.Position(3)=app.PatientDataPanel.Position(3)*(1/5);
                app.RightElbowShoulderEditField.Position(4)=app.PatientDataPanel.Position(4)*(1/28);
                app.RightElbowShoulderEditField.Position(1)=app.PatientDataPanel.Position(3)*(3/4)-app.RightElbowShoulderEditField.Position(3)*(1/2);
                app.RightElbowShoulderEditField.Position(2)=app.PatientDataPanel.Position(4)*(5/14);
                app.RightElbowShoulderEditField.FontSize=app.RightElbowShoulderEditField.Position(4)-app.RightElbowShoulderEditField.Position(4)*0.45;
                app.RightElbowShoulderEditFieldLabel.Position(3)=app.RightElbowShoulderEditField.Position(3);
                app.RightElbowShoulderEditFieldLabel.Position(4)=app.RightElbowShoulderEditField.Position(4);
                app.RightElbowShoulderEditFieldLabel.Position(1)=app.RightElbowShoulderEditField.Position(1)-app.RightElbowShoulderEditFieldLabel.Position(3)-app.RightElbowShoulderEditField.Position(3)*(1/10);
                app.RightElbowShoulderEditFieldLabel.Position(2)=app.PatientDataPanel.Position(4)*(5/14);
                app.RightElbowShoulderEditFieldLabel.FontSize=app.RightElbowShoulderEditField.FontSize;
                
                app.LeftShoulderBreastboneEditField.Position(3)=app.PatientDataPanel.Position(3)*(1/5);
                app.LeftShoulderBreastboneEditField.Position(4)=app.PatientDataPanel.Position(4)*(1/28);
                app.LeftShoulderBreastboneEditField.Position(1)=app.PatientDataPanel.Position(3)*(1/3)-app.LeftShoulderBreastboneEditField.Position(3)*(1/2);
                app.LeftShoulderBreastboneEditField.Position(2)=app.PatientDataPanel.Position(4)*(4/14);
                app.LeftShoulderBreastboneEditField.FontSize=app.LeftShoulderBreastboneEditField.Position(4)-app.LeftShoulderBreastboneEditField.Position(4)*0.45;
                app.LeftShoulderBreastboneEditFieldLabel.Position(3)=app.LeftShoulderBreastboneEditField.Position(3);
                app.LeftShoulderBreastboneEditFieldLabel.Position(4)=app.LeftShoulderBreastboneEditField.Position(4);
                app.LeftShoulderBreastboneEditFieldLabel.Position(1)=app.LeftShoulderBreastboneEditField.Position(1)-app.LeftShoulderBreastboneEditFieldLabel.Position(3)-app.LeftShoulderBreastboneEditField.Position(3)*(1/10);
                app.LeftShoulderBreastboneEditFieldLabel.Position(2)=app.PatientDataPanel.Position(4)*(4/14);
                app.LeftShoulderBreastboneEditFieldLabel.FontSize=app.LeftShoulderBreastboneEditField.FontSize;
                
                app.RightShoulderBreastboneEditField.Position(3)=app.PatientDataPanel.Position(3)*(1/5);
                app.RightShoulderBreastboneEditField.Position(4)=app.PatientDataPanel.Position(4)*(1/28);
                app.RightShoulderBreastboneEditField.Position(1)=app.PatientDataPanel.Position(3)*(3/4)-app.RightShoulderBreastboneEditField.Position(3)*(1/2);
                app.RightShoulderBreastboneEditField.Position(2)=app.PatientDataPanel.Position(4)*(4/14);
                app.RightShoulderBreastboneEditField.FontSize=app.RightShoulderBreastboneEditField.Position(4)-app.RightShoulderBreastboneEditField.Position(4)*0.45;
                app.RightShoulderBreastboneEditFieldLabel.Position(3)=app.RightShoulderBreastboneEditField.Position(3);
                app.RightShoulderBreastboneEditFieldLabel.Position(4)=app.RightShoulderBreastboneEditField.Position(4);
                app.RightShoulderBreastboneEditFieldLabel.Position(1)=app.RightShoulderBreastboneEditField.Position(1)-app.RightShoulderBreastboneEditFieldLabel.Position(3)-app.RightShoulderBreastboneEditField.Position(3)*(1/10);
                app.RightShoulderBreastboneEditFieldLabel.Position(2)=app.PatientDataPanel.Position(4)*(4/14);
                app.RightShoulderBreastboneEditFieldLabel.FontSize=app.RightShoulderBreastboneEditField.FontSize;
                
                app.LeftWristHandleEditField.Position(3)=app.PatientDataPanel.Position(3)*(1/5);
                app.LeftWristHandleEditField.Position(4)=app.PatientDataPanel.Position(4)*(1/28);
                app.LeftWristHandleEditField.Position(1)=app.PatientDataPanel.Position(3)*(1/3)-app.LeftWristHandleEditField.Position(3)*(1/2);
                app.LeftWristHandleEditField.Position(2)=app.PatientDataPanel.Position(4)*(3/14);
                app.LeftWristHandleEditField.FontSize=app.LeftWristHandleEditField.Position(4)-app.LeftWristHandleEditField.Position(4)*0.45;
                app.LeftWristHandleEditFieldLabel.Position(3)=app.LeftWristHandleEditField.Position(3);
                app.LeftWristHandleEditFieldLabel.Position(4)=app.LeftWristHandleEditField.Position(4);
                app.LeftWristHandleEditFieldLabel.Position(1)=app.LeftWristHandleEditField.Position(1)-app.LeftWristHandleEditFieldLabel.Position(3)-app.LeftWristHandleEditField.Position(3)*(1/10);
                app.LeftWristHandleEditFieldLabel.Position(2)=app.PatientDataPanel.Position(4)*(3/14);
                app.LeftWristHandleEditFieldLabel.FontSize=app.LeftWristHandleEditField.FontSize;
                
                app.RightWristHandleEditField.Position(3)=app.PatientDataPanel.Position(3)*(1/5);
                app.RightWristHandleEditField.Position(4)=app.PatientDataPanel.Position(4)*(1/28);
                app.RightWristHandleEditField.Position(1)=app.PatientDataPanel.Position(3)*(3/4)-app.RightWristHandleEditField.Position(3)*(1/2);
                app.RightWristHandleEditField.Position(2)=app.PatientDataPanel.Position(4)*(3/14);
                app.RightWristHandleEditField.FontSize=app.RightWristHandleEditField.Position(4)-app.RightWristHandleEditField.Position(4)*0.45;
                app.RightWristHandleEditFieldLabel.Position(3)=app.RightWristHandleEditField.Position(3);
                app.RightWristHandleEditFieldLabel.Position(4)=app.RightWristHandleEditField.Position(4);
                app.RightWristHandleEditFieldLabel.Position(1)=app.RightWristHandleEditField.Position(1)-app.RightWristHandleEditFieldLabel.Position(3)-app.RightWristHandleEditField.Position(3)*(1/10);
                app.RightWristHandleEditFieldLabel.Position(2)=app.PatientDataPanel.Position(4)*(3/14);
                app.RightWristHandleEditFieldLabel.FontSize=app.RightWristHandleEditField.FontSize;
                
                app.BackToStartPatientDataButton.Position(3)=app.PatientDataPanel.Position(3)*(1/9);
                app.BackToStartPatientDataButton.Position(4)=app.BackToStartPatientDataButton.Position(3)*(0.19);
                app.BackToStartPatientDataButton.Position(1)=app.PatientDataPanel.Position(3)*(1/3)-app.BackToStartPatientDataButton.Position(3)*(1/2);
                app.BackToStartPatientDataButton.Position(2)=app.PatientDataPanel.Position(4)*(1/14);
                app.BackToStartPatientDataButton.FontSize=app.BackToStartPatientDataButton.Position(4)-app.BackToStartPatientDataButton.Position(4)*0.45;
                
                app.BackToMenuPatientDataButton.Position(3)=app.PatientDataPanel.Position(3)*(1/9);
                app.BackToMenuPatientDataButton.Position(4)=app.BackToMenuPatientDataButton.Position(3)*(0.19);
                app.BackToMenuPatientDataButton.Position(1)=app.PatientDataPanel.Position(3)*(1/3)-app.BackToMenuPatientDataButton.Position(3)*(1/2);
                app.BackToMenuPatientDataButton.Position(2)=app.PatientDataPanel.Position(4)*(1/14);
                app.BackToMenuPatientDataButton.FontSize=app.BackToMenuPatientDataButton.Position(4)-app.BackToMenuPatientDataButton.Position(4)*0.45;
                
                app.SavePatientButton.Position(3)=app.PatientDataPanel.Position(3)*(1/9);
                app.SavePatientButton.Position(4)=app.SavePatientButton.Position(3)*(0.19);
                app.SavePatientButton.Position(1)=app.PatientDataPanel.Position(3)*(2/3)-app.SavePatientButton.Position(3)*(1/2);
                app.SavePatientButton.Position(2)=app.PatientDataPanel.Position(4)*(1/14);
                app.SavePatientButton.FontSize=app.SavePatientButton.Position(4)-app.SavePatientButton.Position(4)*0.45;
                
                %LoadPatientPanel
                
                app.LoadPatientPanel.Position = [0 0 app.UIFigure.Position(3) app.UIFigure.Position(4)];
                
                app.LoadPatientTable.Position(1)=app.LoadPatientPanel.Position(3)*(1/10);
                app.LoadPatientTable.Position(2)=app.LoadPatientPanel.Position(4)*(3/14);
                app.LoadPatientTable.Position(3)=app.LoadPatientPanel.Position(3)*(8/10);
                app.LoadPatientTable.Position(4)=app.LoadPatientPanel.Position(4)*(10/14);
                app.LoadPatientTable.FontSize=app.LoadPatientTable.Position(4)*(0.03);
                
                app.BackToStartLoadPatientButton.Position(3)=app.LoadPatientPanel.Position(3)*(1/9);
                app.BackToStartLoadPatientButton.Position(4)=app.BackToStartLoadPatientButton.Position(3)*(0.17);
                app.BackToStartLoadPatientButton.Position(1)=app.LoadPatientPanel.Position(3)*(1/3)-app.BackToStartLoadPatientButton.Position(3)*(1/2);
                app.BackToStartLoadPatientButton.Position(2)=app.LoadPatientPanel.Position(4)*(1/14);
                app.BackToStartLoadPatientButton.FontSize=app.BackToStartLoadPatientButton.Position(4)-app.BackToStartLoadPatientButton.Position(4)*0.45;
                
                app.ChoosePatientButton.Position(3)=app.LoadPatientPanel.Position(3)*(1/9);
                app.ChoosePatientButton.Position(4)=app.ChoosePatientButton.Position(3)*(0.17);
                app.ChoosePatientButton.Position(1)=app.LoadPatientPanel.Position(3)*(2/3)-app.ChoosePatientButton.Position(3)*(1/2);
                app.ChoosePatientButton.Position(2)=app.LoadPatientPanel.Position(4)*(1/14);
                app.ChoosePatientButton.FontSize=app.ChoosePatientButton.Position(4)-app.ChoosePatientButton.Position(4)*0.45;
                
                %PatientMenuPanel
                
                app.PatientMenuPanel.Position = [0 0 app.UIFigure.Position(3) app.UIFigure.Position(4)];
                
                app.PatientMenuEditField.Position(3)=app.PatientDataPanel.Position(3)*(1/10);
                app.PatientMenuEditField.Position(4)=app.PatientDataPanel.Position(4)*(1/28);
                app.PatientMenuEditField.Position(1)=app.PatientDataPanel.Position(3)*(1/5)-app.PatientMenuEditField.Position(3)*(1/2);
                app.PatientMenuEditField.Position(2)=app.PatientDataPanel.Position(4)*(13/14);
                app.PatientMenuEditField.FontSize=app.PatientMenuEditField.Position(4)-app.PatientMenuEditField.Position(4)*0.45;
                app.PatientMenuEditFieldLabel.Position(3)=app.PatientMenuEditField.Position(3);
                app.PatientMenuEditFieldLabel.Position(4)=app.PatientMenuEditField.Position(4);
                app.PatientMenuEditFieldLabel.Position(1)=app.PatientMenuEditField.Position(1)-app.PatientMenuEditFieldLabel.Position(3)-app.PatientMenuEditField.Position(3)*(1/10);
                app.PatientMenuEditFieldLabel.Position(2)=app.PatientDataPanel.Position(4)*(13/14);
                app.PatientMenuEditFieldLabel.FontSize=app.PatientMenuEditField.FontSize;
                
                app.EditPatientButton.Position(4)=app.PatientMenuPanel.Position(4)*(1/18);
                app.EditPatientButton.Position(3)=app.EditPatientButton.Position(4)*(8.9);
                app.EditPatientButton.Position(1)=app.PatientMenuPanel.Position(3)*(1/2)-app.EditPatientButton.Position(3)*(1/2);
                app.EditPatientButton.Position(2)=app.PatientMenuPanel.Position(4)*(6/9);
                app.EditPatientButton.FontSize=app.EditPatientButton.Position(4)-app.EditPatientButton.Position(4)*0.45;
                
                app.StartTrialButton.Position(4)=app.PatientMenuPanel.Position(4)*(1/18);
                app.StartTrialButton.Position(3)=app.EditPatientButton.Position(4)*(5.9);
                app.StartTrialButton.Position(1)=app.PatientMenuPanel.Position(3)*(1/2)-app.StartTrialButton.Position(3)*(1/2);
                app.StartTrialButton.Position(2)=app.PatientMenuPanel.Position(4)*(5/9);
                app.StartTrialButton.FontSize=app.StartTrialButton.Position(4)-app.StartTrialButton.Position(4)*0.45;
                
                app.ShowMedicalHistoryButton.Position(4)=app.PatientMenuPanel.Position(4)*(1/18);
                app.ShowMedicalHistoryButton.Position(3)=app.EditPatientButton.Position(4)*(9.2);
                app.ShowMedicalHistoryButton.Position(1)=app.PatientMenuPanel.Position(3)*(1/2)-app.ShowMedicalHistoryButton.Position(3)*(1/2);
                app.ShowMedicalHistoryButton.Position(2)=app.PatientMenuPanel.Position(4)*(4/9);
                app.ShowMedicalHistoryButton.FontSize=app.ShowMedicalHistoryButton.Position(4)-app.ShowMedicalHistoryButton.Position(4)*0.45;
                
                app.BackToStartPatientMenuButton.Position(4)=app.PatientMenuPanel.Position(4)*(1/18);
                app.BackToStartPatientMenuButton.Position(3)=app.EditPatientButton.Position(4)*(5.6);
                app.BackToStartPatientMenuButton.Position(1)=app.PatientMenuPanel.Position(3)*(1/2)-app.BackToStartPatientMenuButton.Position(3)*(1/2);
                app.BackToStartPatientMenuButton.Position(2)=app.PatientMenuPanel.Position(4)*(3/9);
                app.BackToStartPatientMenuButton.FontSize=app.BackToStartPatientMenuButton.Position(4)-app.BackToStartPatientMenuButton.Position(4)*0.45;
                
                app.RoboespasMenuImage.Position(3)=app.PatientMenuPanel.Position(3)*(1/3);
                app.RoboespasMenuImage.Position(4)=app.RoboespasMenuImage.Position(3)*(0.84);
                app.RoboespasMenuImage.Position(1)=app.PatientMenuPanel.Position(1);
                app.RoboespasMenuImage.Position(2)=app.PatientMenuPanel.Position(4)*(4.5/9)-app.RoboespasMenuImage.Position(4)*(1/2);
                
                app.Uc3mMenuImage.Position(3)=app.PatientMenuPanel.Position(3)*(1/4);
                app.Uc3mMenuImage.Position(4)=app.Uc3mMenuImage.Position(3)*(0.54);
                app.Uc3mMenuImage.Position(1)=app.PatientMenuPanel.Position(3)-app.Uc3mMenuImage.Position(3)-app.Uc3mMenuImage.Position(3)*(2/10);
                app.Uc3mMenuImage.Position(2)=app.PatientMenuPanel.Position(4)*(4.5/9)-app.Uc3mMenuImage.Position(4)*(1/2);
                
                %MedicalHistoryPanel
                
                app.MedicalHistoryPanel.Position = [0 0 app.UIFigure.Position(3) app.UIFigure.Position(4)];
                
                app.BackToMenuMedicalHistoryButton.Position(3)=app.MedicalHistoryPanel.Position(3)*(1/9);
                app.BackToMenuMedicalHistoryButton.Position(4)=app.BackToMenuMedicalHistoryButton.Position(3)*(0.2);
                app.BackToMenuMedicalHistoryButton.Position(1)=app.MedicalHistoryPanel.Position(3)*(1/5)-app.BackToMenuMedicalHistoryButton.Position(3)*(1/2);
                app.BackToMenuMedicalHistoryButton.Position(2)=app.MedicalHistoryPanel.Position(4)*(1/28);
                app.BackToMenuMedicalHistoryButton.FontSize=app.BackToMenuMedicalHistoryButton.Position(4)-app.BackToMenuMedicalHistoryButton.Position(4)*0.45;
                
                %MedicalHistoryTree
                    
                app.MedicalHistoryTree.Position(1)=app.MedicalHistoryPanel.Position(1);
                app.MedicalHistoryTree.Position(2)=app.MedicalHistoryPanel.Position(4)*(2/14);
                app.MedicalHistoryTree.Position(3)=app.MedicalHistoryPanel.Position(3)*(1/4);
                app.MedicalHistoryTree.Position(4)=app.MedicalHistoryPanel.Position(4)*(12/14);
               
                    %PersonalDataPanel
                    
                app.PersonalDataPanel.Position(1)=app.MedicalHistoryPanel.Position(3)*(1/4);
                app.PersonalDataPanel.Position(2)=app.MedicalHistoryPanel.Position(4)*(2/14);
                app.PersonalDataPanel.Position(3)=app.MedicalHistoryPanel.Position(3)*(3/4);
                app.PersonalDataPanel.Position(4)=app.MedicalHistoryPanel.Position(4)*(12/14);
                
                app.NameEditField2.Position(3)=app.PersonalDataPanel.Position(3)*(1/10);
                app.NameEditField2.Position(4)=app.PersonalDataPanel.Position(4)*(1/28);
                app.NameEditField2.Position(1)=app.PersonalDataPanel.Position(3)*(1/5)-app.NameEditField2.Position(3)*(1/2);
                app.NameEditField2.Position(2)=app.PersonalDataPanel.Position(4)*(11/14);
                app.NameEditField2.FontSize=app.NameEditField2.Position(4)-app.NameEditField2.Position(4)*0.45;
                app.NameEditFieldLabel2.Position(3)=app.NameEditField2.Position(3);
                app.NameEditFieldLabel2.Position(4)=app.NameEditField2.Position(4);
                app.NameEditFieldLabel2.Position(1)=app.NameEditField2.Position(1)-app.NameEditFieldLabel2.Position(3)-app.NameEditField2.Position(3)*(1/10);
                app.NameEditFieldLabel2.Position(2)=app.PersonalDataPanel.Position(4)*(11/14);
                app.NameEditFieldLabel2.FontSize=app.NameEditField2.FontSize;
                
                app.SurnamesEditField2.Position(3)=app.PersonalDataPanel.Position(3)*(1/5);
                app.SurnamesEditField2.Position(4)=app.PersonalDataPanel.Position(4)*(1/28);
                app.SurnamesEditField2.Position(1)=app.PersonalDataPanel.Position(3)*(1/2)-app.SurnamesEditField2.Position(3)*(1/2);
                app.SurnamesEditField2.Position(2)=app.PersonalDataPanel.Position(4)*(11/14);
                app.SurnamesEditField2.FontSize=app.SurnamesEditField2.Position(4)-app.SurnamesEditField2.Position(4)*0.45;
                app.SurnamesEditFieldLabel2.Position(3)=app.SurnamesEditField2.Position(3);
                app.SurnamesEditFieldLabel2.Position(4)=app.SurnamesEditField2.Position(4);
                app.SurnamesEditFieldLabel2.Position(1)=app.SurnamesEditField2.Position(1)-app.SurnamesEditFieldLabel2.Position(3)-app.SurnamesEditField2.Position(3)*(1/10);
                app.SurnamesEditFieldLabel2.Position(2)=app.PersonalDataPanel.Position(4)*(11/14);
                app.SurnamesEditFieldLabel2.FontSize=app.SurnamesEditField2.FontSize;
                
                app.IDEditField2.Position(3)=app.PersonalDataPanel.Position(3)*(1/10);
                app.IDEditField2.Position(4)=app.PersonalDataPanel.Position(4)*(1/28);
                app.IDEditField2.Position(1)=app.PersonalDataPanel.Position(3)*(1/5)-app.IDEditField2.Position(3)*(1/2);
                app.IDEditField2.Position(2)=app.PersonalDataPanel.Position(4)*(10/14);
                app.IDEditField2.FontSize=app.IDEditField2.Position(4)-app.IDEditField2.Position(4)*0.45;
                app.IDEditFieldLabel2.Position(3)=app.IDEditField2.Position(3);
                app.IDEditFieldLabel2.Position(4)=app.IDEditField2.Position(4);
                app.IDEditFieldLabel2.Position(1)=app.IDEditField2.Position(1)-app.IDEditFieldLabel2.Position(3)-app.IDEditField2.Position(3)*(1/10);
                app.IDEditFieldLabel2.Position(2)=app.PersonalDataPanel.Position(4)*(10/14);
                app.IDEditFieldLabel2.FontSize=app.IDEditField2.FontSize;
                
                app.SpasticityLevelEditField2.Position(3)=app.PersonalDataPanel.Position(3)*(1/10);
                app.SpasticityLevelEditField2.Position(4)=app.PersonalDataPanel.Position(4)*(1/28);
                app.SpasticityLevelEditField2.Position(1)=app.SurnamesEditField2.Position(1)+app.SurnamesEditField2.Position(3)-app.SpasticityLevelEditField2.Position(3);
                app.SpasticityLevelEditField2.Position(2)=app.PersonalDataPanel.Position(4)*(10/14);
                app.SpasticityLevelEditField2.FontSize=app.SpasticityLevelEditField2.Position(4)-app.SpasticityLevelEditField2.Position(4)*0.45;
                app.SpasticityLevelEditFieldLabel2.Position(3)=app.SpasticityLevelEditField2.Position(3)*(2.5);
                app.SpasticityLevelEditFieldLabel2.Position(4)=app.SpasticityLevelEditField2.Position(4);
                app.SpasticityLevelEditFieldLabel2.Position(1)=app.SpasticityLevelEditField2.Position(1)-app.SpasticityLevelEditFieldLabel2.Position(3)-app.SpasticityLevelEditField2.Position(3)*(1/10);
                app.SpasticityLevelEditFieldLabel2.Position(2)=app.PersonalDataPanel.Position(4)*(10/14);
                app.SpasticityLevelEditFieldLabel2.FontSize=app.SpasticityLevelEditField2.FontSize;
                
                app.AgeEditField2.Position(3)=app.PersonalDataPanel.Position(3)*(1/10);
                app.AgeEditField2.Position(4)=app.PersonalDataPanel.Position(4)*(1/28);
                app.AgeEditField2.Position(1)=app.PersonalDataPanel.Position(3)*(4/5)-app.AgeEditField2.Position(3)*(1/2);
                app.AgeEditField2.Position(2)=app.PersonalDataPanel.Position(4)*(10/14);
                app.AgeEditField2.FontSize=app.AgeEditField2.Position(4)-app.AgeEditField2.Position(4)*0.45;
                app.AgeEditFieldLabel2.Position(3)=app.AgeEditField2.Position(3);
                app.AgeEditFieldLabel2.Position(4)=app.AgeEditField2.Position(4);
                app.AgeEditFieldLabel2.Position(1)=app.AgeEditField2.Position(1)-app.AgeEditFieldLabel2.Position(3)-app.AgeEditField2.Position(3)*(1/10);
                app.AgeEditFieldLabel2.Position(2)=app.PersonalDataPanel.Position(4)*(10/14);
                app.AgeEditFieldLabel2.FontSize=app.AgeEditField2.FontSize;
                
                app.HeightEditField2.Position(3)=app.PersonalDataPanel.Position(3)*(1/10);
                app.HeightEditField2.Position(4)=app.PersonalDataPanel.Position(4)*(1/28);
                app.HeightEditField2.Position(1)=app.PersonalDataPanel.Position(3)*(1/5)-app.HeightEditField2.Position(3)*(1/2);
                app.HeightEditField2.Position(2)=app.PersonalDataPanel.Position(4)*(9/14);
                app.HeightEditField2.FontSize=app.HeightEditField2.Position(4)-app.HeightEditField2.Position(4)*0.45;
                app.HeightEditFieldLabel2.Position(3)=app.HeightEditField2.Position(3);
                app.HeightEditFieldLabel2.Position(4)=app.HeightEditField2.Position(4);
                app.HeightEditFieldLabel2.Position(1)=app.HeightEditField2.Position(1)-app.HeightEditFieldLabel2.Position(3)-app.HeightEditField2.Position(3)*(1/10);
                app.HeightEditFieldLabel2.Position(2)=app.PersonalDataPanel.Position(4)*(9/14);
                app.HeightEditFieldLabel2.FontSize=app.HeightEditField2.FontSize;
                
                app.WeightEditField2.Position(3)=app.PersonalDataPanel.Position(3)*(1/10);
                app.WeightEditField2.Position(4)=app.PersonalDataPanel.Position(4)*(1/28);
                app.WeightEditField2.Position(1)=app.SurnamesEditField2.Position(1)+app.SurnamesEditField2.Position(3)-app.WeightEditField2.Position(3);
                app.WeightEditField2.Position(2)=app.PersonalDataPanel.Position(4)*(9/14);
                app.WeightEditField2.FontSize=app.WeightEditField2.Position(4)-app.WeightEditField2.Position(4)*0.45;
                app.WeightEditFieldLabel2.Position(3)=app.WeightEditField2.Position(3);
                app.WeightEditFieldLabel2.Position(4)=app.WeightEditField2.Position(4);
                app.WeightEditFieldLabel2.Position(1)=app.WeightEditField2.Position(1)-app.WeightEditFieldLabel2.Position(3)-app.WeightEditField2.Position(3)*(1/10);
                app.WeightEditFieldLabel2.Position(2)=app.PersonalDataPanel.Position(4)*(9/14);
                app.WeightEditFieldLabel2.FontSize=app.WeightEditField2.FontSize;
                
                app.GenderEditField2.Position(3)=app.PersonalDataPanel.Position(3)*(1/10);
                app.GenderEditField2.Position(4)=app.PersonalDataPanel.Position(4)*(1/28);
                app.GenderEditField2.Position(1)=app.AgeEditField2.Position(1)+app.AgeEditField2.Position(3)-app.GenderEditField2.Position(3);
                app.GenderEditField2.Position(2)=app.PersonalDataPanel.Position(4)*(9/14);
                app.GenderEditField2.FontSize=app.GenderEditField2.Position(4)-app.GenderEditField2.Position(4)*0.45;
                app.GenderEditFieldLabel2.Position(3)=app.GenderEditField2.Position(3);
                app.GenderEditFieldLabel2.Position(4)=app.GenderEditField2.Position(4);
                app.GenderEditFieldLabel2.Position(1)=app.GenderEditField2.Position(1)-app.GenderEditFieldLabel2.Position(3)-app.GenderEditField2.Position(3)*(1/10);
                app.GenderEditFieldLabel2.Position(2)=app.PersonalDataPanel.Position(4)*(9/14);
                app.GenderEditFieldLabel2.FontSize=app.GenderEditField2.FontSize;
                
                app.LeftArmLabel2.Position(3)=app.PersonalDataPanel.Position(3)*(1/5);
                app.LeftArmLabel2.Position(4)=app.PersonalDataPanel.Position(4)*(1/28);
                app.LeftArmLabel2.Position(1)=app.PersonalDataPanel.Position(3)*(1/3)-app.LeftArmLabel2.Position(3)*(1/2);
                app.LeftArmLabel2.Position(2)=app.PersonalDataPanel.Position(4)*(7/14);
                app.LeftArmLabel2.FontSize=app.LeftArmLabel2.Position(4)-app.LeftArmLabel2.Position(4)*0.45;
                
                app.RightArmLabel2.Position(3)=app.PersonalDataPanel.Position(3)*(1/5);
                app.RightArmLabel2.Position(4)=app.PersonalDataPanel.Position(4)*(1/28);
                app.RightArmLabel2.Position(1)=app.PersonalDataPanel.Position(3)*(3/4)-app.RightArmLabel2.Position(3)*(1/2);
                app.RightArmLabel2.Position(2)=app.PersonalDataPanel.Position(4)*(7/14);
                app.RightArmLabel2.FontSize=app.RightArmLabel2.Position(4)-app.RightArmLabel2.Position(4)*0.45;
                
                app.LeftWristElbowEditField2.Position(3)=app.PersonalDataPanel.Position(3)*(1/5);
                app.LeftWristElbowEditField2.Position(4)=app.PersonalDataPanel.Position(4)*(1/28);
                app.LeftWristElbowEditField2.Position(1)=app.PersonalDataPanel.Position(3)*(1/3)-app.LeftWristElbowEditField2.Position(3)*(1/2);
                app.LeftWristElbowEditField2.Position(2)=app.PersonalDataPanel.Position(4)*(6/14);
                app.LeftWristElbowEditField2.FontSize=app.LeftWristElbowEditField2.Position(4)-app.LeftWristElbowEditField2.Position(4)*0.45;
                app.LeftWristElbowEditFieldLabel2.Position(3)=app.LeftWristElbowEditField2.Position(3);
                app.LeftWristElbowEditFieldLabel2.Position(4)=app.LeftWristElbowEditField2.Position(4);
                app.LeftWristElbowEditFieldLabel2.Position(1)=app.LeftWristElbowEditField2.Position(1)-app.LeftWristElbowEditFieldLabel2.Position(3)-app.LeftWristElbowEditField2.Position(3)*(1/10);
                app.LeftWristElbowEditFieldLabel2.Position(2)=app.PersonalDataPanel.Position(4)*(6/14);
                app.LeftWristElbowEditFieldLabel2.FontSize=app.LeftWristElbowEditField2.FontSize;
                
                app.RightWristElbowEditField2.Position(3)=app.PersonalDataPanel.Position(3)*(1/5);
                app.RightWristElbowEditField2.Position(4)=app.PersonalDataPanel.Position(4)*(1/28);
                app.RightWristElbowEditField2.Position(1)=app.PersonalDataPanel.Position(3)*(3/4)-app.RightWristElbowEditField2.Position(3)*(1/2);
                app.RightWristElbowEditField2.Position(2)=app.PersonalDataPanel.Position(4)*(6/14);
                app.RightWristElbowEditField2.FontSize=app.RightWristElbowEditField2.Position(4)-app.RightWristElbowEditField2.Position(4)*0.45;
                app.RightWristElbowEditFieldLabel2.Position(3)=app.RightWristElbowEditField2.Position(3);
                app.RightWristElbowEditFieldLabel2.Position(4)=app.RightWristElbowEditField2.Position(4);
                app.RightWristElbowEditFieldLabel2.Position(1)=app.RightWristElbowEditField2.Position(1)-app.RightWristElbowEditFieldLabel2.Position(3)-app.RightWristElbowEditField2.Position(3)*(1/10);
                app.RightWristElbowEditFieldLabel2.Position(2)=app.PersonalDataPanel.Position(4)*(6/14);
                app.RightWristElbowEditFieldLabel2.FontSize=app.RightWristElbowEditField2.FontSize;
                
                app.LeftElbowShoulderEditField2.Position(3)=app.PersonalDataPanel.Position(3)*(1/5);
                app.LeftElbowShoulderEditField2.Position(4)=app.PersonalDataPanel.Position(4)*(1/28);
                app.LeftElbowShoulderEditField2.Position(1)=app.PersonalDataPanel.Position(3)*(1/3)-app.LeftElbowShoulderEditField2.Position(3)*(1/2);
                app.LeftElbowShoulderEditField2.Position(2)=app.PersonalDataPanel.Position(4)*(5/14);
                app.LeftElbowShoulderEditField2.FontSize=app.LeftElbowShoulderEditField2.Position(4)-app.LeftElbowShoulderEditField2.Position(4)*0.45;
                app.LeftElbowShoulderEditFieldLabel2.Position(3)=app.LeftElbowShoulderEditField2.Position(3);
                app.LeftElbowShoulderEditFieldLabel2.Position(4)=app.LeftElbowShoulderEditField2.Position(4);
                app.LeftElbowShoulderEditFieldLabel2.Position(1)=app.LeftElbowShoulderEditField2.Position(1)-app.LeftElbowShoulderEditFieldLabel2.Position(3)-app.LeftElbowShoulderEditField2.Position(3)*(1/10);
                app.LeftElbowShoulderEditFieldLabel2.Position(2)=app.PersonalDataPanel.Position(4)*(5/14);
                app.LeftElbowShoulderEditFieldLabel2.FontSize=app.LeftElbowShoulderEditField2.FontSize;
                
                app.RightElbowShoulderEditField2.Position(3)=app.PersonalDataPanel.Position(3)*(1/5);
                app.RightElbowShoulderEditField2.Position(4)=app.PersonalDataPanel.Position(4)*(1/28);
                app.RightElbowShoulderEditField2.Position(1)=app.PersonalDataPanel.Position(3)*(3/4)-app.RightElbowShoulderEditField2.Position(3)*(1/2);
                app.RightElbowShoulderEditField2.Position(2)=app.PersonalDataPanel.Position(4)*(5/14);
                app.RightElbowShoulderEditField2.FontSize=app.RightElbowShoulderEditField2.Position(4)-app.RightElbowShoulderEditField2.Position(4)*0.45;
                app.RightElbowShoulderEditFieldLabel2.Position(3)=app.RightElbowShoulderEditField2.Position(3);
                app.RightElbowShoulderEditFieldLabel2.Position(4)=app.RightElbowShoulderEditField2.Position(4);
                app.RightElbowShoulderEditFieldLabel2.Position(1)=app.RightElbowShoulderEditField2.Position(1)-app.RightElbowShoulderEditFieldLabel2.Position(3)-app.RightElbowShoulderEditField2.Position(3)*(1/10);
                app.RightElbowShoulderEditFieldLabel2.Position(2)=app.PersonalDataPanel.Position(4)*(5/14);
                app.RightElbowShoulderEditFieldLabel2.FontSize=app.RightElbowShoulderEditField2.FontSize;
                
                app.LeftShoulderBreastboneEditField2.Position(3)=app.PersonalDataPanel.Position(3)*(1/5);
                app.LeftShoulderBreastboneEditField2.Position(4)=app.PersonalDataPanel.Position(4)*(1/28);
                app.LeftShoulderBreastboneEditField2.Position(1)=app.PersonalDataPanel.Position(3)*(1/3)-app.LeftShoulderBreastboneEditField2.Position(3)*(1/2);
                app.LeftShoulderBreastboneEditField2.Position(2)=app.PersonalDataPanel.Position(4)*(4/14);
                app.LeftShoulderBreastboneEditField2.FontSize=app.LeftShoulderBreastboneEditField2.Position(4)-app.LeftShoulderBreastboneEditField2.Position(4)*0.45;
                app.LeftShoulderBreastboneEditFieldLabel2.Position(3)=app.LeftShoulderBreastboneEditField2.Position(3);
                app.LeftShoulderBreastboneEditFieldLabel2.Position(4)=app.LeftShoulderBreastboneEditField2.Position(4);
                app.LeftShoulderBreastboneEditFieldLabel2.Position(1)=app.LeftShoulderBreastboneEditField2.Position(1)-app.LeftShoulderBreastboneEditFieldLabel2.Position(3)-app.LeftShoulderBreastboneEditField2.Position(3)*(1/10);
                app.LeftShoulderBreastboneEditFieldLabel2.Position(2)=app.PersonalDataPanel.Position(4)*(4/14);
                app.LeftShoulderBreastboneEditFieldLabel2.FontSize=app.LeftShoulderBreastboneEditField2.FontSize;
                
                app.RightShoulderBreastboneEditField2.Position(3)=app.PersonalDataPanel.Position(3)*(1/5);
                app.RightShoulderBreastboneEditField2.Position(4)=app.PersonalDataPanel.Position(4)*(1/28);
                app.RightShoulderBreastboneEditField2.Position(1)=app.PersonalDataPanel.Position(3)*(3/4)-app.RightShoulderBreastboneEditField2.Position(3)*(1/2);
                app.RightShoulderBreastboneEditField2.Position(2)=app.PersonalDataPanel.Position(4)*(4/14);
                app.RightShoulderBreastboneEditField2.FontSize=app.RightShoulderBreastboneEditField2.Position(4)-app.RightShoulderBreastboneEditField2.Position(4)*0.45;
                app.RightShoulderBreastboneEditFieldLabel2.Position(3)=app.RightShoulderBreastboneEditField2.Position(3);
                app.RightShoulderBreastboneEditFieldLabel2.Position(4)=app.RightShoulderBreastboneEditField2.Position(4);
                app.RightShoulderBreastboneEditFieldLabel2.Position(1)=app.RightShoulderBreastboneEditField2.Position(1)-app.RightShoulderBreastboneEditFieldLabel2.Position(3)-app.RightShoulderBreastboneEditField2.Position(3)*(1/10);
                app.RightShoulderBreastboneEditFieldLabel2.Position(2)=app.PersonalDataPanel.Position(4)*(4/14);
                app.RightShoulderBreastboneEditFieldLabel2.FontSize=app.RightShoulderBreastboneEditField2.FontSize;
                
                app.LeftWristHandleEditField2.Position(3)=app.PersonalDataPanel.Position(3)*(1/5);
                app.LeftWristHandleEditField2.Position(4)=app.PersonalDataPanel.Position(4)*(1/28);
                app.LeftWristHandleEditField2.Position(1)=app.PersonalDataPanel.Position(3)*(1/3)-app.LeftWristHandleEditField2.Position(3)*(1/2);
                app.LeftWristHandleEditField2.Position(2)=app.PersonalDataPanel.Position(4)*(3/14);
                app.LeftWristHandleEditField2.FontSize=app.LeftWristHandleEditField2.Position(4)-app.LeftWristHandleEditField2.Position(4)*0.45;
                app.LeftWristHandleEditFieldLabel2.Position(3)=app.LeftWristHandleEditField2.Position(3);
                app.LeftWristHandleEditFieldLabel2.Position(4)=app.LeftWristHandleEditField2.Position(4);
                app.LeftWristHandleEditFieldLabel2.Position(1)=app.LeftWristHandleEditField2.Position(1)-app.LeftWristHandleEditFieldLabel2.Position(3)-app.LeftWristHandleEditField2.Position(3)*(1/10);
                app.LeftWristHandleEditFieldLabel2.Position(2)=app.PersonalDataPanel.Position(4)*(3/14);
                app.LeftWristHandleEditFieldLabel2.FontSize=app.LeftWristHandleEditField2.FontSize;
                
                app.RightWristHandleEditField2.Position(3)=app.PersonalDataPanel.Position(3)*(1/5);
                app.RightWristHandleEditField2.Position(4)=app.PersonalDataPanel.Position(4)*(1/28);
                app.RightWristHandleEditField2.Position(1)=app.PersonalDataPanel.Position(3)*(3/4)-app.RightWristHandleEditField2.Position(3)*(1/2);
                app.RightWristHandleEditField2.Position(2)=app.PersonalDataPanel.Position(4)*(3/14);
                app.RightWristHandleEditField2.FontSize=app.RightWristHandleEditField2.Position(4)-app.RightWristHandleEditField2.Position(4)*0.45;
                app.RightWristHandleEditFieldLabel2.Position(3)=app.RightWristHandleEditField2.Position(3);
                app.RightWristHandleEditFieldLabel2.Position(4)=app.RightWristHandleEditField2.Position(4);
                app.RightWristHandleEditFieldLabel2.Position(1)=app.RightWristHandleEditField2.Position(1)-app.RightWristHandleEditFieldLabel2.Position(3)-app.RightWristHandleEditField2.Position(3)*(1/10);
                app.RightWristHandleEditFieldLabel2.Position(2)=app.PersonalDataPanel.Position(4)*(3/14);
                app.RightWristHandleEditFieldLabel2.FontSize=app.RightWristHandleEditField2.FontSize;
                
                    %ResultsButtonsPanel
                    
                app.ResultsButtonsPanel.Position(1)=app.MedicalHistoryPanel.Position(3)*(1/4);
                app.ResultsButtonsPanel.Position(2)=app.MedicalHistoryPanel.Position(4)*(2/14);
                app.ResultsButtonsPanel.Position(3)=app.MedicalHistoryPanel.Position(3)*(3/8);
                app.ResultsButtonsPanel.Position(4)=app.MedicalHistoryPanel.Position(4)*(12/14);
                
                app.TrajectoryLabel.Position(4)=app.ResultsButtonsPanel.Position(4)*(1/24);
                app.TrajectoryLabel.Position(3)=app.TrajectoryLabel.Position(4)*(3.4);
                app.TrajectoryLabel.Position(1)=app.ResultsButtonsPanel.Position(3)*(1/10);
                app.TrajectoryLabel.Position(2)=app.ResultsButtonsPanel.Position(4)*(15/16);
                app.TrajectoryLabel.FontSize=app.TrajectoryLabel.Position(4)-app.TrajectoryLabel.Position(4)*0.45;
                
                app.JointLabel.Position(4)=app.ResultsButtonsPanel.Position(4)*(1/24);
                app.JointLabel.Position(3)=app.JointLabel.Position(4)*(2.6);
                app.JointLabel.Position(1)=app.ResultsButtonsPanel.Position(3)*(1/10)+10;
                app.JointLabel.Position(2)=app.ResultsButtonsPanel.Position(4)*(14/16);
                app.JointLabel.FontSize=app.JointLabel.Position(4)-app.JointLabel.Position(4)*0.45;
                
                app.qButton.Position(4)=app.ResultsButtonsPanel.Position(4)*(1/24);
                app.qButton.Position(3)=app.qButton.Position(4)*(1);
                app.qButton.Position(1)=app.ResultsButtonsPanel.Position(3)*(1/10)+20;
                app.qButton.Position(2)=app.ResultsButtonsPanel.Position(4)*(13/16);
                app.qButton.FontSize=app.qButton.Position(4)-app.qButton.Position(4)*0.45;
                
                app.dqButton.Position(4)=app.ResultsButtonsPanel.Position(4)*(1/24);
                app.dqButton.Position(3)=app.dqButton.Position(4)*(1.3);
                app.dqButton.Position(1)=app.qButton.Position(1)+app.qButton.Position(3)+10;
                app.dqButton.Position(2)=app.ResultsButtonsPanel.Position(4)*(13/16);
                app.dqButton.FontSize=app.dqButton.Position(4)-app.dqButton.Position(4)*0.45;
                
                app.ddqButton.Position(4)=app.ResultsButtonsPanel.Position(4)*(1/24);
                app.ddqButton.Position(3)=app.ddqButton.Position(4)*(1.6);
                app.ddqButton.Position(1)=app.dqButton.Position(1)+app.dqButton.Position(3)+10;
                app.ddqButton.Position(2)=app.ResultsButtonsPanel.Position(4)*(13/16);
                app.ddqButton.FontSize=app.ddqButton.Position(4)-app.ddqButton.Position(4)*0.45;
                
                app.JointPrecisionLabel.Position(4)=app.ResultsButtonsPanel.Position(4)*(1/24);
                app.JointPrecisionLabel.Position(3)=app.JointPrecisionLabel.Position(4)*(2.8);
                app.JointPrecisionLabel.Position(1)=app.ResultsButtonsPanel.Position(3)*(1/10)+30;
                app.JointPrecisionLabel.Position(2)=app.ResultsButtonsPanel.Position(4)*(12/16);
                app.JointPrecisionLabel.FontSize=app.JointPrecisionLabel.Position(4)-app.JointPrecisionLabel.Position(4)*0.45;
                
                app.EpqButton.Position(4)=app.ResultsButtonsPanel.Position(4)*(1/24);
                app.EpqButton.Position(3)=app.EpqButton.Position(4)*(2);
                app.EpqButton.Position(1)=app.JointPrecisionLabel.Position(1)+app.JointPrecisionLabel.Position(3)+10;
                app.EpqButton.Position(2)=app.ResultsButtonsPanel.Position(4)*(12/16);
                app.EpqButton.FontSize=app.EpqButton.Position(4)-app.EpqButton.Position(4)*0.45;
                
                app.EpdqButton.Position(4)=app.ResultsButtonsPanel.Position(4)*(1/24);
                app.EpdqButton.Position(3)=app.EpdqButton.Position(4)*(2.4);
                app.EpdqButton.Position(1)=app.EpqButton.Position(1)+app.EpqButton.Position(3)+10;
                app.EpdqButton.Position(2)=app.ResultsButtonsPanel.Position(4)*(12/16);
                app.EpdqButton.FontSize=app.EpdqButton.Position(4)-app.EpdqButton.Position(4)*0.45;
                
                app.EpddqButton.Position(4)=app.ResultsButtonsPanel.Position(4)*(1/24);
                app.EpddqButton.Position(3)=app.EpddqButton.Position(4)*(2.7);
                app.EpddqButton.Position(1)=app.EpdqButton.Position(1)+app.EpdqButton.Position(3)+10;
                app.EpddqButton.Position(2)=app.ResultsButtonsPanel.Position(4)*(12/16);
                app.EpddqButton.FontSize=app.EpddqButton.Position(4)-app.EpddqButton.Position(4)*0.45;
                
                app.JointAccuracyLabel.Position(4)=app.ResultsButtonsPanel.Position(4)*(1/24);
                app.JointAccuracyLabel.Position(3)=app.JointAccuracyLabel.Position(4)*(2.9);
                app.JointAccuracyLabel.Position(1)=app.ResultsButtonsPanel.Position(3)*(1/10)+30;
                app.JointAccuracyLabel.Position(2)=app.ResultsButtonsPanel.Position(4)*(11/16);
                app.JointAccuracyLabel.FontSize=app.JointAccuracyLabel.Position(4)-app.JointAccuracyLabel.Position(4)*0.45;
                
                app.EqButton.Position(4)=app.ResultsButtonsPanel.Position(4)*(1/24);
                app.EqButton.Position(3)=app.EqButton.Position(4)*(1.8);
                app.EqButton.Position(1)=app.JointAccuracyLabel.Position(1)+app.JointAccuracyLabel.Position(3)+10;
                app.EqButton.Position(2)=app.ResultsButtonsPanel.Position(4)*(11/16);
                app.EqButton.FontSize=app.EqButton.Position(4)-app.EqButton.Position(4)*0.45;
                
                app.EdqButton.Position(4)=app.ResultsButtonsPanel.Position(4)*(1/24);
                app.EdqButton.Position(3)=app.EdqButton.Position(4)*(2.1);
                app.EdqButton.Position(1)=app.EqButton.Position(1)+app.EqButton.Position(3)+10;
                app.EdqButton.Position(2)=app.ResultsButtonsPanel.Position(4)*(11/16);
                app.EdqButton.FontSize=app.EdqButton.Position(4)-app.EdqButton.Position(4)*0.45;
                
                app.EddqButton.Position(4)=app.ResultsButtonsPanel.Position(4)*(1/24);
                app.EddqButton.Position(3)=app.EddqButton.Position(4)*(2.2);
                app.EddqButton.Position(1)=app.EdqButton.Position(1)+app.EdqButton.Position(3)+10;
                app.EddqButton.Position(2)=app.ResultsButtonsPanel.Position(4)*(11/16);
                app.EddqButton.FontSize=app.EddqButton.Position(4)-app.EddqButton.Position(4)*0.45;
                
                app.CartesianLabel.Position(4)=app.ResultsButtonsPanel.Position(4)*(1/24);
                app.CartesianLabel.Position(3)=app.CartesianLabel.Position(4)*(3.2);
                app.CartesianLabel.Position(1)=app.ResultsButtonsPanel.Position(3)*(1/10)+10;
                app.CartesianLabel.Position(2)=app.ResultsButtonsPanel.Position(4)*(10/16);
                app.CartesianLabel.FontSize=app.CartesianLabel.Position(4)-app.CartesianLabel.Position(4)*0.45;
                
                app.xButton.Position(4)=app.ResultsButtonsPanel.Position(4)*(1/24);
                app.xButton.Position(3)=app.xButton.Position(4)*(1);
                app.xButton.Position(1)=app.ResultsButtonsPanel.Position(3)*(1/10)+20;
                app.xButton.Position(2)=app.ResultsButtonsPanel.Position(4)*(9/16);
                app.xButton.FontSize=app.xButton.Position(4)-app.xButton.Position(4)*0.45;
                
                app.dxButton.Position(4)=app.ResultsButtonsPanel.Position(4)*(1/24);
                app.dxButton.Position(3)=app.dxButton.Position(4)*(1.3);
                app.dxButton.Position(1)=app.xButton.Position(1)+app.xButton.Position(3)+10;
                app.dxButton.Position(2)=app.ResultsButtonsPanel.Position(4)*(9/16);
                app.dxButton.FontSize=app.dxButton.Position(4)-app.dxButton.Position(4)*0.45;
                
                app.ThreeDButton.Position(4)=app.ResultsButtonsPanel.Position(4)*(1/24);
                app.ThreeDButton.Position(3)=app.ThreeDButton.Position(4)*(1.4);
                app.ThreeDButton.Position(1)=app.dxButton.Position(1)+app.dxButton.Position(3)+10;
                app.ThreeDButton.Position(2)=app.ResultsButtonsPanel.Position(4)*(9/16);
                app.ThreeDButton.FontSize=app.ThreeDButton.Position(4)-app.ThreeDButton.Position(4)*0.45;
                
                app.CartesianPrecisionLabel.Position(4)=app.ResultsButtonsPanel.Position(4)*(1/24);
                app.CartesianPrecisionLabel.Position(3)=app.CartesianPrecisionLabel.Position(4)*(2.8);
                app.CartesianPrecisionLabel.Position(1)=app.ResultsButtonsPanel.Position(3)*(1/10)+30;
                app.CartesianPrecisionLabel.Position(2)=app.ResultsButtonsPanel.Position(4)*(8/16);
                app.CartesianPrecisionLabel.FontSize=app.CartesianPrecisionLabel.Position(4)-app.CartesianPrecisionLabel.Position(4)*0.45;
                
                app.EpxButton.Position(4)=app.ResultsButtonsPanel.Position(4)*(1/24);
                app.EpxButton.Position(3)=app.EpxButton.Position(4)*(2);
                app.EpxButton.Position(1)=app.CartesianPrecisionLabel.Position(1)+app.CartesianPrecisionLabel.Position(3)+10;
                app.EpxButton.Position(2)=app.ResultsButtonsPanel.Position(4)*(8/16);
                app.EpxButton.FontSize=app.EpxButton.Position(4)-app.EpxButton.Position(4)*0.45;
                
                app.EpdxButton.Position(4)=app.ResultsButtonsPanel.Position(4)*(1/24);
                app.EpdxButton.Position(3)=app.EpdxButton.Position(4)*(2.3);
                app.EpdxButton.Position(1)=app.EpxButton.Position(1)+app.EpxButton.Position(3)+10;
                app.EpdxButton.Position(2)=app.ResultsButtonsPanel.Position(4)*(8/16);
                app.EpdxButton.FontSize=app.EpdxButton.Position(4)-app.EpdxButton.Position(4)*0.45;
                
                app.CartesianAccuracyLabel.Position(4)=app.ResultsButtonsPanel.Position(4)*(1/24);
                app.CartesianAccuracyLabel.Position(3)=app.CartesianAccuracyLabel.Position(4)*(2.9);
                app.CartesianAccuracyLabel.Position(1)=app.ResultsButtonsPanel.Position(3)*(1/10)+30;
                app.CartesianAccuracyLabel.Position(2)=app.ResultsButtonsPanel.Position(4)*(7/16);
                app.CartesianAccuracyLabel.FontSize=app.CartesianAccuracyLabel.Position(4)-app.CartesianAccuracyLabel.Position(4)*0.45;
                
                app.ExButton.Position(4)=app.ResultsButtonsPanel.Position(4)*(1/24);
                app.ExButton.Position(3)=app.ExButton.Position(4)*(1.8);
                app.ExButton.Position(1)=app.CartesianAccuracyLabel.Position(1)+app.CartesianAccuracyLabel.Position(3)+10;
                app.ExButton.Position(2)=app.ResultsButtonsPanel.Position(4)*(7/16);
                app.ExButton.FontSize=app.ExButton.Position(4)-app.ExButton.Position(4)*0.45;
                
                app.EdxButton.Position(4)=app.ResultsButtonsPanel.Position(4)*(1/24);
                app.EdxButton.Position(3)=app.EdxButton.Position(4)*(2);
                app.EdxButton.Position(1)=app.ExButton.Position(1)+app.ExButton.Position(3)+10;
                app.EdxButton.Position(2)=app.ResultsButtonsPanel.Position(4)*(7/16);
                app.EdxButton.FontSize=app.EdxButton.Position(4)-app.EdxButton.Position(4)*0.45;
                
                app.ForceSensorLabel.Position(4)=app.ResultsButtonsPanel.Position(4)*(1/24);
                app.ForceSensorLabel.Position(3)=app.ForceSensorLabel.Position(4)*(5.1);
                app.ForceSensorLabel.Position(1)=app.ResultsButtonsPanel.Position(3)*(1/10);
                app.ForceSensorLabel.Position(2)=app.ResultsButtonsPanel.Position(4)*(6/16);
                app.ForceSensorLabel.FontSize=app.ForceSensorLabel.Position(4)-app.ForceSensorLabel.Position(4)*0.45;
                
                app.FTButton.Position(4)=app.ResultsButtonsPanel.Position(4)*(1/24);
                app.FTButton.Position(3)=app.FTButton.Position(4)*(1.4);
                app.FTButton.Position(1)=app.ResultsButtonsPanel.Position(3)*(1/10)+10;
                app.FTButton.Position(2)=app.ResultsButtonsPanel.Position(4)*(5/16);
                app.FTButton.FontSize=app.FTButton.Position(4)-app.FTButton.Position(4)*0.45;
                
                app.DelsysSensorsLabel.Position(4)=app.ResultsButtonsPanel.Position(4)*(1/24);
                app.DelsysSensorsLabel.Position(3)=app.DelsysSensorsLabel.Position(4)*(4.5);
                app.DelsysSensorsLabel.Position(1)=app.ResultsButtonsPanel.Position(3)*(1/10);
                app.DelsysSensorsLabel.Position(2)=app.ResultsButtonsPanel.Position(4)*(4/16);
                app.DelsysSensorsLabel.FontSize=app.DelsysSensorsLabel.Position(4)-app.DelsysSensorsLabel.Position(4)*0.45;
                
                app.EMGButton.Position(4)=app.ResultsButtonsPanel.Position(4)*(1/24);
                app.EMGButton.Position(3)=app.EMGButton.Position(4)*(2);
                app.EMGButton.Position(1)=app.ResultsButtonsPanel.Position(3)*(1/10)+10;
                app.EMGButton.Position(2)=app.ResultsButtonsPanel.Position(4)*(3/16);
                app.EMGButton.FontSize=app.EMGButton.Position(4)-app.EMGButton.Position(4)*0.45;
                
                app.AccelerationsButton.Position(4)=app.ResultsButtonsPanel.Position(4)*(1/24);
                app.AccelerationsButton.Position(3)=app.AccelerationsButton.Position(4)*(4.3);
                app.AccelerationsButton.Position(1)=app.ResultsButtonsPanel.Position(3)*(1/10)+10;
                app.AccelerationsButton.Position(2)=app.ResultsButtonsPanel.Position(4)*(2/16);
                app.AccelerationsButton.FontSize=app.AccelerationsButton.Position(4)-app.AccelerationsButton.Position(4)*0.45;
                
                app.AngularVelocityButton.Position(4)=app.ResultsButtonsPanel.Position(4)*(1/24);
                app.AngularVelocityButton.Position(3)=app.AngularVelocityButton.Position(4)*(5.3);
                app.AngularVelocityButton.Position(1)=app.ResultsButtonsPanel.Position(3)*(1/10)+10;
                app.AngularVelocityButton.Position(2)=app.ResultsButtonsPanel.Position(4)*(1/16);
                app.AngularVelocityButton.FontSize=app.AngularVelocityButton.Position(4)-app.AngularVelocityButton.Position(4)*0.45;
                
                    %TrialDataPanel
                    
                app.TrialDataPanel.Position(1)=app.MedicalHistoryPanel.Position(3)*(5/8);
                app.TrialDataPanel.Position(2)=app.MedicalHistoryPanel.Position(4)*(2/14);
                app.TrialDataPanel.Position(3)=app.MedicalHistoryPanel.Position(3)*(3/8);
                app.TrialDataPanel.Position(4)=app.MedicalHistoryPanel.Position(4)*(12/14);
                
                app.TrialDataLabel.Position(4)=app.TrialDataPanel.Position(4)*(1/24);
                app.TrialDataLabel.Position(3)=app.TrialDataLabel.Position(4)*(8.6);
                app.TrialDataLabel.Position(1)=app.TrialDataPanel.Position(3)*(1/2)-app.TrialDataLabel.Position(3)*(1/2);
                app.TrialDataLabel.Position(2)=app.TrialDataPanel.Position(4)*(15/16);
                app.TrialDataLabel.FontSize=app.TrialDataLabel.Position(4)-app.TrialDataLabel.Position(4)*0.45;
                
                app.TrialDataPatientEditField.Position(3)=app.TrialDataPanel.Position(3)*(9/10);
                app.TrialDataPatientEditField.Position(4)=app.TrialDataPanel.Position(4)*(1/24);
                app.TrialDataPatientEditField.Position(1)=app.TrialDataPanel.Position(3)*(1/2)-app.TrialDataPatientEditField.Position(3)*(1/2);
                app.TrialDataPatientEditField.Position(2)=app.TrialDataPanel.Position(4)*(6/7);
                app.TrialDataPatientEditField.FontSize=app.TrialDataPatientEditField.Position(4)-app.TrialDataPatientEditField.Position(4)*0.45;
                
                app.TrialDataChoosedArmEditField.Position(3)=app.TrialDataPanel.Position(3)*(9/10);
                app.TrialDataChoosedArmEditField.Position(4)=app.TrialDataPanel.Position(4)*(1/24);
                app.TrialDataChoosedArmEditField.Position(1)=app.TrialDataPanel.Position(3)*(1/2)-app.TrialDataChoosedArmEditField.Position(3)*(1/2);
                app.TrialDataChoosedArmEditField.Position(2)=app.TrialDataPanel.Position(4)*(5/7);
                app.TrialDataChoosedArmEditField.FontSize=app.TrialDataChoosedArmEditField.Position(4)-app.TrialDataChoosedArmEditField.Position(4)*0.45;
                
                app.TrialDataChoosedWorkingPlaneEditField.Position(3)=app.TrialDataPanel.Position(3)*(9/10);
                app.TrialDataChoosedWorkingPlaneEditField.Position(4)=app.TrialDataPanel.Position(4)*(1/24);
                app.TrialDataChoosedWorkingPlaneEditField.Position(1)=app.TrialDataPanel.Position(3)*(1/2)-app.TrialDataChoosedArmEditField.Position(3)*(1/2);
                app.TrialDataChoosedWorkingPlaneEditField.Position(2)=app.TrialDataPanel.Position(4)*(4/7);
                app.TrialDataChoosedWorkingPlaneEditField.FontSize=app.TrialDataChoosedWorkingPlaneEditField.Position(4)-app.TrialDataChoosedWorkingPlaneEditField.Position(4)*0.45;
                
                app.TrialDataTrajectoryDateCapturedEditField.Position(3)=app.TrialDataPanel.Position(3)*(9/10);
                app.TrialDataTrajectoryDateCapturedEditField.Position(4)=app.TrialDataPanel.Position(4)*(1/24);
                app.TrialDataTrajectoryDateCapturedEditField.Position(1)=app.TrialDataPanel.Position(3)*(1/2)-app.TrialDataTrajectoryDateCapturedEditField.Position(3)*(1/2);
                app.TrialDataTrajectoryDateCapturedEditField.Position(2)=app.TrialDataPanel.Position(4)*(3/7);
                app.TrialDataTrajectoryDateCapturedEditField.FontSize=app.TrialDataTrajectoryDateCapturedEditField.Position(4)-app.TrialDataTrajectoryDateCapturedEditField.Position(4)*0.45;
                
                app.TrialDataTrajectoryDateExecutedEditField.Position(3)=app.TrialDataPanel.Position(3)*(9/10);
                app.TrialDataTrajectoryDateExecutedEditField.Position(4)=app.TrialDataPanel.Position(4)*(1/24);
                app.TrialDataTrajectoryDateExecutedEditField.Position(1)=app.TrialDataPanel.Position(3)*(1/2)-app.TrialDataTrajectoryDateExecutedEditField.Position(3)*(1/2);
                app.TrialDataTrajectoryDateExecutedEditField.Position(2)=app.TrialDataPanel.Position(4)*(2/7);
                app.TrialDataTrajectoryDateExecutedEditField.FontSize=app.TrialDataTrajectoryDateExecutedEditField.Position(4)-app.TrialDataTrajectoryDateExecutedEditField.Position(4)*0.45;
                
                %PlotsPanel
                
                app.PlotsPanel.Position = [0 0 app.UIFigure.Position(3) app.UIFigure.Position(4)];
                
                app.CloseImage.Position(1)=app.PlotsPanel.Position(3)-app.CloseImage.Position(3)-10;
                app.CloseImage.Position(2)=app.PlotsPanel.Position(4)-app.CloseImage.Position(4)-10;
                
                %SessionInfoPanel
                
                app.SessionInfoPanel.Position = [0 0 app.UIFigure.Position(3) app.UIFigure.Position(4)];
                

                app.SessionInfoLabel.Position(4)=app.SessionInfoPanel.Position(4)*(1/14);
                app.SessionInfoLabel.Position(3)=app.SessionInfoLabel.Position(4)*(11.5);
                app.SessionInfoLabel.Position(1)=app.SessionInfoPanel.Position(3)*(1/2)-app.SessionInfoLabel.Position(3)*(1/2);
                app.SessionInfoLabel.Position(2)=app.SessionInfoPanel.Position(4)*(9/10);
                app.SessionInfoLabel.FontSize=app.SessionInfoLabel.Position(4)-app.SessionInfoLabel.Position(4)*0.45;
                
                app.ConnectSensorsLabel.Position(4)=app.SessionInfoPanel.Position(4)*(1/22);
                app.ConnectSensorsLabel.Position(3)=app.ConnectSensorsLabel.Position(4)*(9.2);
                app.ConnectSensorsLabel.Position(1)=app.SessionInfoPanel.Position(3)*(1/8);
                app.ConnectSensorsLabel.Position(2)=app.SessionInfoPanel.Position(4)*(8/10);
                app.ConnectSensorsLabel.FontSize=app.ConnectSensorsLabel.Position(4)-app.ConnectSensorsLabel.Position(4)*0.45;
                
                app.IIWACheckBox.Position(4)=app.SessionInfoPanel.Position(4)*(1/22);
                app.IIWACheckBox.Position(3)=app.IIWACheckBox.Position(4)*(2.3);
                app.IIWACheckBox.Position(1)=app.SessionInfoPanel.Position(3)*(1/4)-app.IIWACheckBox.Position(3)*(1/2);
                app.IIWACheckBox.Position(2)=app.SessionInfoPanel.Position(4)*(7/10);
                app.IIWACheckBox.FontSize=app.IIWACheckBox.Position(4)-app.IIWACheckBox.Position(4)*0.45;
                
                app.FTSensorCheckBox.Position(4)=app.SessionInfoPanel.Position(4)*(1/22);
                app.FTSensorCheckBox.Position(3)=app.FTSensorCheckBox.Position(4)*(7);
                app.FTSensorCheckBox.Position(1)=app.SessionInfoPanel.Position(3)*(2/4)-app.FTSensorCheckBox.Position(3)*(1/2);
                app.FTSensorCheckBox.Position(2)=app.SessionInfoPanel.Position(4)*(7/10);
                app.FTSensorCheckBox.FontSize=app.FTSensorCheckBox.Position(4)-app.FTSensorCheckBox.Position(4)*0.45;
                
                app.DelsysSensorsCheckBox.Position(4)=app.SessionInfoPanel.Position(4)*(1/22);
                app.DelsysSensorsCheckBox.Position(3)=app.DelsysSensorsCheckBox.Position(4)*(5.3);
                app.DelsysSensorsCheckBox.Position(1)=app.SessionInfoPanel.Position(3)*(3/4)-app.DelsysSensorsCheckBox.Position(3)*(1/2);
                app.DelsysSensorsCheckBox.Position(2)=app.SessionInfoPanel.Position(4)*(7/10);
                app.DelsysSensorsCheckBox.FontSize=app.DelsysSensorsCheckBox.Position(4)-app.DelsysSensorsCheckBox.Position(4)*0.45;
                
                app.ChooseArmLabel.Position(4)=app.SessionInfoPanel.Position(4)*(1/22);
                app.ChooseArmLabel.Position(3)=app.ChooseArmLabel.Position(4)*(8.4);
                app.ChooseArmLabel.Position(1)=app.SessionInfoPanel.Position(3)*(1/8);
                app.ChooseArmLabel.Position(2)=app.SessionInfoPanel.Position(4)*(6/10);
                app.ChooseArmLabel.FontSize=app.ChooseArmLabel.Position(4)-app.ChooseArmLabel.Position(4)*0.45;
                
                app.ChoosePlaneLabel.Position(4)=app.SessionInfoPanel.Position(4)*(1/22);
                app.ChoosePlaneLabel.Position(3)=app.ChoosePlaneLabel.Position(4)*(10);
                app.ChoosePlaneLabel.Position(1)=app.SessionInfoPanel.Position(3)*(5/8);
                app.ChoosePlaneLabel.Position(2)=app.SessionInfoPanel.Position(4)*(6/10);
                app.ChoosePlaneLabel.FontSize=app.ChoosePlaneLabel.Position(4)-app.ChoosePlaneLabel.Position(4)*0.45;
                
                app.ArmSwitch.Position(1)=app.ChooseArmLabel.Position(1)+app.ChooseArmLabel.Position(3)*(1/2)-app.ArmSwitch.Position(3)*(1/2);
                app.ArmSwitch.Position(2)=app.SessionInfoPanel.Position(4)*(5/10)+app.ArmSwitch.Position(4)*(1/2);
                app.ArmSwitch.FontSize=app.ChoosePlaneLabel.FontSize;
                
                app.PlaneSwitch.Position(1)=app.ChoosePlaneLabel.Position(1)+app.ChoosePlaneLabel.Position(3)*(1/2)-app.PlaneSwitch.Position(3)*(1/2);
                app.PlaneSwitch.Position(2)=app.SessionInfoPanel.Position(4)*(5/10)+app.PlaneSwitch.Position(4)*(1/2);
                app.PlaneSwitch.FontSize=app.ChoosePlaneLabel.FontSize;
                
                app.ChooseMovementLabel.Position(4)=app.SessionInfoPanel.Position(4)*(1/22);
                app.ChooseMovementLabel.Position(3)=app.ChooseMovementLabel.Position(4)*(12.5);
                app.ChooseMovementLabel.Position(1)=app.SessionInfoPanel.Position(3)*(1/8);
                app.ChooseMovementLabel.Position(2)=app.SessionInfoPanel.Position(4)*(4/10);
                app.ChooseMovementLabel.FontSize=app.ChooseMovementLabel.Position(4)-app.ChooseMovementLabel.Position(4)*0.45;
                
                app.MovementDropDown.Position(4)=app.SessionInfoPanel.Position(4)*(1/22);
                app.MovementDropDown.Position(3)=app.SessionInfoPanel.Position(3)*(1/3);
                app.MovementDropDown.Position(1)=app.SessionInfoPanel.Position(3)*(1/8)+app.ChooseMovementLabel.Position(3)*(1/3);
                app.MovementDropDown.Position(2)=app.SessionInfoPanel.Position(4)*(3/10)+app.MovementDropDown.Position(4)*(1/2);
                app.MovementDropDown.FontSize=app.MovementDropDown.Position(4)-app.MovementDropDown.Position(4)*0.45;
                
                app.GoToSensorsPanelButton.Position(4)=app.SessionInfoPanel.Position(4)*(1/22);
                app.GoToSensorsPanelButton.Position(3)=app.GoToSensorsPanelButton.Position(4)*(5.7);
                app.GoToSensorsPanelButton.Position(1)=app.SessionInfoPanel.Position(3)*(1/8);
                app.GoToSensorsPanelButton.Position(2)=app.SessionInfoPanel.Position(4)*(2/10);
                app.GoToSensorsPanelButton.FontSize=app.GoToSensorsPanelButton.Position(4)-app.GoToSensorsPanelButton.Position(4)*0.45;
                
                app.BackToMenuSessionInfoButton.Position(4)=app.SessionInfoPanel.Position(4)*(1/22);
                app.BackToMenuSessionInfoButton.Position(3)=app.BackToMenuSessionInfoButton.Position(4)*(4.3);
                app.BackToMenuSessionInfoButton.Position(1)=app.SessionInfoPanel.Position(3)*(1/3)-app.BackToMenuSessionInfoButton.Position(3)*(1/2);
                app.BackToMenuSessionInfoButton.Position(2)=app.SessionInfoPanel.Position(4)*(1/10);
                app.BackToMenuSessionInfoButton.FontSize=app.BackToMenuSessionInfoButton.Position(4)-app.BackToMenuSessionInfoButton.Position(4)*0.45;
                
                app.GoToTrialMenuButton.Position(4)=app.SessionInfoPanel.Position(4)*(1/22);
                app.GoToTrialMenuButton.Position(3)=app.GoToTrialMenuButton.Position(4)*(4.3);
                app.GoToTrialMenuButton.Position(1)=app.SessionInfoPanel.Position(3)*(2/3)-app.GoToTrialMenuButton.Position(3)*(1/2);
                app.GoToTrialMenuButton.Position(2)=app.SessionInfoPanel.Position(4)*(1/11);
                app.GoToTrialMenuButton.FontSize=app.GoToTrialMenuButton.Position(4)-app.GoToTrialMenuButton.Position(4)*0.45;
                
                %SensorsPanel
                
                app.SensorsPanel.Position = [0 0 app.UIFigure.Position(3) app.UIFigure.Position(4)];
                
                app.SensorsLabel.Position(4)=app.SensorsPanel.Position(4)*(1/14);
                app.SensorsLabel.Position(3)=app.SensorsLabel.Position(4)*(14.6);
                app.SensorsLabel.Position(1)=app.SensorsPanel.Position(3)*(1/2)-app.SensorsLabel.Position(3)*(1/2);
                app.SensorsLabel.Position(2)=app.SensorsPanel.Position(4)*(12/13);
                app.SensorsLabel.FontSize=app.SensorsLabel.Position(4)-app.SensorsLabel.Position(4)*0.45;
                              
                app.Sensor1Label.Position(4)=app.SensorsPanel.Position(4)*(1/22);
                app.Sensor1Label.Position(3)=app.Sensor1Label.Position(4)*(6.3);
                app.Sensor1Label.Position(1)=app.SensorsPanel.Position(3)*(1/3)-app.Sensor1Label.Position(3)*(1/2);
                app.Sensor1Label.Position(2)=app.SensorsPanel.Position(4)*(11/13);
                app.Sensor1Label.FontSize=app.Sensor1Label.Position(4)-app.Sensor1Label.Position(4)*0.45;
                
                app.Sensor2Label.Position(4)=app.SensorsPanel.Position(4)*(1/22);
                app.Sensor2Label.Position(3)=app.Sensor2Label.Position(4)*(8.3);
                app.Sensor2Label.Position(1)=app.SensorsPanel.Position(3)*(2/3)-app.Sensor2Label.Position(3)*(1/2);
                app.Sensor2Label.Position(2)=app.SensorsPanel.Position(4)*(11/13);
                app.Sensor2Label.FontSize=app.Sensor2Label.Position(4)-app.Sensor2Label.Position(4)*0.45;
                
                app.Sensor3Label.Position(4)=app.SensorsPanel.Position(4)*(1/22);
                app.Sensor3Label.Position(3)=app.Sensor3Label.Position(4)*(7);
                app.Sensor3Label.Position(1)=app.SensorsPanel.Position(3)*(1/3)-app.Sensor3Label.Position(3)*(1/2);
                app.Sensor3Label.Position(2)=app.SensorsPanel.Position(4)*(6/13);
                app.Sensor3Label.FontSize=app.Sensor3Label.Position(4)-app.Sensor3Label.Position(4)*0.45;
                
                app.Sensor4Label.Position(4)=app.SensorsPanel.Position(4)*(1/22);
                app.Sensor4Label.Position(3)=app.Sensor4Label.Position(4)*(8.2);
                app.Sensor4Label.Position(1)=app.SensorsPanel.Position(3)*(2/3)-app.Sensor4Label.Position(3)*(1/2);
                app.Sensor4Label.Position(2)=app.SensorsPanel.Position(4)*(6/13);
                app.Sensor4Label.FontSize=app.Sensor4Label.Position(4)-app.Sensor4Label.Position(4)*0.45;
                                                
                app.Sensor1PosEditField.Position(4)=app.SensorsPanel.Position(4)*(1/22);
                app.Sensor1PosEditField.Position(3)=app.Sensor1PosEditField.Position(4)*(4.7);
                app.Sensor1PosEditField.Position(1)=app.SensorsPanel.Position(3)*(1/3)-app.Sensor1PosEditField.Position(3)*(1/2);
                app.Sensor1PosEditField.Position(2)=app.SensorsPanel.Position(4)*(7/13);
                app.Sensor1PosEditField.FontSize=app.Sensor1PosEditField.Position(4)-app.Sensor1PosEditField.Position(4)*0.45;
                
                app.Sensor2PosEditField.Position(4)=app.SensorsPanel.Position(4)*(1/22);
                app.Sensor2PosEditField.Position(3)=app.Sensor2PosEditField.Position(4)*(4.7);
                app.Sensor2PosEditField.Position(1)=app.SensorsPanel.Position(3)*(2/3)-app.Sensor2PosEditField.Position(3)*(1/2);
                app.Sensor2PosEditField.Position(2)=app.SensorsPanel.Position(4)*(7/13);
                app.Sensor2PosEditField.FontSize=app.Sensor2PosEditField.Position(4)-app.Sensor2PosEditField.Position(4)*0.45;
                
                app.Sensor3PosEditField.Position(4)=app.SensorsPanel.Position(4)*(1/22);
                app.Sensor3PosEditField.Position(3)=app.Sensor3PosEditField.Position(4)*(4.7);
                app.Sensor3PosEditField.Position(1)=app.SensorsPanel.Position(3)*(1/3)-app.Sensor3PosEditField.Position(3)*(1/2);
                app.Sensor3PosEditField.Position(2)=app.SensorsPanel.Position(4)*(2/13);
                app.Sensor3PosEditField.FontSize=app.Sensor3PosEditField.Position(4)-app.Sensor3PosEditField.Position(4)*0.45;
                
                app.Sensor4PosEditField.Position(4)=app.SensorsPanel.Position(4)*(1/22);
                app.Sensor4PosEditField.Position(3)=app.Sensor4PosEditField.Position(4)*(4.7);
                app.Sensor4PosEditField.Position(1)=app.SensorsPanel.Position(3)*(2/3)-app.Sensor4PosEditField.Position(3)*(1/2);
                app.Sensor4PosEditField.Position(2)=app.SensorsPanel.Position(4)*(2/13);
                app.Sensor4PosEditField.FontSize=app.Sensor4PosEditField.Position(4)-app.Sensor4PosEditField.Position(4)*0.45;
                
                app.Sensor1Image.Position(4)=app.SensorsPanel.Position(4)*(4/13);
                app.Sensor1Image.Position(3)=app.Sensor1Image.Position(4)*(1);
                app.Sensor1Image.Position(1)=app.SensorsPanel.Position(3)*(1/3)-app.Sensor1Image.Position(3)*(1/2);
                app.Sensor1Image.Position(2)=app.SensorsPanel.Position(4)*(7/13)+app.Sensor1PosEditField.Position(4)*(0.6);
                
                app.Sensor2Image.Position(4)=app.SensorsPanel.Position(4)*(4/13);
                app.Sensor2Image.Position(3)=app.Sensor2Image.Position(4)*(1);
                app.Sensor2Image.Position(1)=app.SensorsPanel.Position(3)*(2/3)-app.Sensor2Image.Position(3)*(1/2);
                app.Sensor2Image.Position(2)=app.SensorsPanel.Position(4)*(7/13)+app.Sensor2PosEditField.Position(4)*(0.6);
                
                app.Sensor3Image.Position(4)=app.SensorsPanel.Position(4)*(4/13);
                app.Sensor3Image.Position(3)=app.Sensor3Image.Position(4)*(1);
                app.Sensor3Image.Position(1)=app.SensorsPanel.Position(3)*(1/3)-app.Sensor3Image.Position(3)*(1/2);
                app.Sensor3Image.Position(2)=app.SensorsPanel.Position(4)*(2/13)+app.Sensor3PosEditField.Position(4)*(0.6);
                
                app.Sensor4Image.Position(4)=app.SensorsPanel.Position(4)*(4/13);
                app.Sensor4Image.Position(3)=app.Sensor4Image.Position(4)*(1);
                app.Sensor4Image.Position(1)=app.SensorsPanel.Position(3)*(2/3)-app.Sensor4Image.Position(3)*(1/2);
                app.Sensor4Image.Position(2)=app.SensorsPanel.Position(4)*(2/13)+app.Sensor4PosEditField.Position(4)*(0.6);
                
                app.BackToSessionInfoSensorsButton.Position(4)=app.SensorsPanel.Position(4)*(1/22);
                app.BackToSessionInfoSensorsButton.Position(3)=app.BackToSessionInfoSensorsButton.Position(4)*(4.7);
                app.BackToSessionInfoSensorsButton.Position(1)=app.SensorsPanel.Position(3)*(1/2)-app.BackToSessionInfoSensorsButton.Position(3)*(1/2);
                app.BackToSessionInfoSensorsButton.Position(2)=app.SensorsPanel.Position(4)*(1/13);
                app.BackToSessionInfoSensorsButton.FontSize=app.BackToSessionInfoSensorsButton.Position(4)-app.BackToSessionInfoSensorsButton.Position(4)*0.45;
                
                %TrialMenuPanel
                
                app.TrialMenuPanel.Position = [0 0 app.UIFigure.Position(3) app.UIFigure.Position(4)];
                
                app.GoToCaptureButton.Position(4)=app.TrialMenuPanel.Position(4)*(1/20);
                app.GoToCaptureButton.Position(3)=app.GoToCaptureButton.Position(4)*(7.7);
                app.GoToCaptureButton.Position(1)=app.TrialMenuPanel.Position(3)*(1/2)-app.GoToCaptureButton.Position(3)*(1/2);
                app.GoToCaptureButton.Position(2)=app.TrialMenuPanel.Position(4)*(4/10);
                app.GoToCaptureButton.FontSize=app.GoToCaptureButton.Position(4)-app.GoToCaptureButton.Position(4)*0.45;
                
                app.GoToRepeatButton.Position(4)=app.TrialMenuPanel.Position(4)*(1/20);
                app.GoToRepeatButton.Position(3)=app.GoToRepeatButton.Position(4)*(8.5);
                app.GoToRepeatButton.Position(1)=app.TrialMenuPanel.Position(3)*(1/2)-app.GoToRepeatButton.Position(3)*(1/2);
                app.GoToRepeatButton.Position(2)=app.TrialMenuPanel.Position(4)*(3/10);
                app.GoToRepeatButton.FontSize=app.GoToRepeatButton.Position(4)-app.GoToRepeatButton.Position(4)*0.45;
                
                app.BackToSessionInfoTrialMenuButton.Position(4)=app.TrialMenuPanel.Position(4)*(1/20);
                app.BackToSessionInfoTrialMenuButton.Position(3)=app.BackToSessionInfoTrialMenuButton.Position(4)*(6.8);
                app.BackToSessionInfoTrialMenuButton.Position(1)=app.TrialMenuPanel.Position(3)*(1/2)-app.BackToSessionInfoTrialMenuButton.Position(3)*(1/2);
                app.BackToSessionInfoTrialMenuButton.Position(2)=app.TrialMenuPanel.Position(4)*(2/10);
                app.BackToSessionInfoTrialMenuButton.FontSize=app.BackToSessionInfoTrialMenuButton.Position(4)-app.BackToSessionInfoTrialMenuButton.Position(4)*0.45;
                
                app.BackToMenuTrialMenuButton.Position(4)=app.TrialMenuPanel.Position(4)*(1/20);
                app.BackToMenuTrialMenuButton.Position(3)=app.BackToMenuTrialMenuButton.Position(4)*(4.3);
                app.BackToMenuTrialMenuButton.Position(1)=app.TrialMenuPanel.Position(3)*(1/2)-app.BackToMenuTrialMenuButton.Position(3)*(1/2);
                app.BackToMenuTrialMenuButton.Position(2)=app.TrialMenuPanel.Position(4)*(1/10);
                app.BackToMenuTrialMenuButton.FontSize=app.BackToMenuTrialMenuButton.Position(4)-app.BackToMenuTrialMenuButton.Position(4)*0.45;
                
                    %SummaryPanel
                    
                app.SummaryPanel.Position(1)=app.TrialMenuPanel.Position(3)*(1/10);
                app.SummaryPanel.Position(2)=app.TrialMenuPanel.Position(4)*(5/10);
                app.SummaryPanel.Position(3)=app.TrialMenuPanel.Position(3)*(8/10);
                app.SummaryPanel.Position(4)=app.TrialMenuPanel.Position(4)*(4/10);
                
                app.SummaryLabel.Position(4)=app.SummaryPanel.Position(4)*(1/10);
                app.SummaryLabel.Position(3)=app.SummaryLabel.Position(4)*(8.3);
                app.SummaryLabel.Position(1)=app.SummaryPanel.Position(3)*(1/2)-app.SummaryLabel.Position(3)*(1/2);
                app.SummaryLabel.Position(2)=app.SummaryPanel.Position(4)*(6/7);
                app.SummaryLabel.FontSize=app.SummaryLabel.Position(4)-app.SummaryLabel.Position(4)*0.45;
                
                app.IIWASummaryLabel.Position(4)=app.SummaryPanel.Position(4)*(1/12);
                app.IIWASummaryLabel.Position(3)=app.IIWASummaryLabel.Position(4)*(5.5);
                app.IIWASummaryLabel.Position(1)=app.SummaryPanel.Position(3)*(1/10);
                app.IIWASummaryLabel.Position(2)=app.SummaryPanel.Position(4)*(5/7);
                app.IIWASummaryLabel.FontSize=app.IIWASummaryLabel.Position(4)-app.IIWASummaryLabel.Position(4)*0.45;
                
                app.FTSensorSummaryLabel.Position(4)=app.SummaryPanel.Position(4)*(1/12);
                app.FTSensorSummaryLabel.Position(3)=app.FTSensorSummaryLabel.Position(4)*(10.5);
                app.FTSensorSummaryLabel.Position(1)=app.SummaryPanel.Position(3)*(3/10);
                app.FTSensorSummaryLabel.Position(2)=app.SummaryPanel.Position(4)*(5/7);
                app.FTSensorSummaryLabel.FontSize=app.FTSensorSummaryLabel.Position(4)-app.FTSensorSummaryLabel.Position(4)*0.45;
                
                app.DelsysSensorsSummaryLabel.Position(4)=app.SummaryPanel.Position(4)*(1/12);
                app.DelsysSensorsSummaryLabel.Position(3)=app.DelsysSensorsSummaryLabel.Position(4)*(9);
                app.DelsysSensorsSummaryLabel.Position(1)=app.SummaryPanel.Position(3)*(6/10);
                app.DelsysSensorsSummaryLabel.Position(2)=app.SummaryPanel.Position(4)*(5/7);
                app.DelsysSensorsSummaryLabel.FontSize=app.DelsysSensorsSummaryLabel.Position(4)-app.DelsysSensorsSummaryLabel.Position(4)*0.45;
                
                app.ArmSummaryLabel.Position(4)=app.SummaryPanel.Position(4)*(1/12);
                app.ArmSummaryLabel.Position(3)=app.ArmSummaryLabel.Position(4)*(7.9);
                app.ArmSummaryLabel.Position(1)=app.SummaryPanel.Position(3)*(1/10);
                app.ArmSummaryLabel.Position(2)=app.SummaryPanel.Position(4)*(4/7);
                app.ArmSummaryLabel.FontSize=app.ArmSummaryLabel.Position(4)-app.ArmSummaryLabel.Position(4)*0.45;
                
                app.PlaneSummaryLabel.Position(4)=app.SummaryPanel.Position(4)*(1/12);
                app.PlaneSummaryLabel.Position(3)=app.PlaneSummaryLabel.Position(4)*(8.4);
                app.PlaneSummaryLabel.Position(1)=app.SummaryPanel.Position(3)*(6/10);
                app.PlaneSummaryLabel.Position(2)=app.SummaryPanel.Position(4)*(4/7);
                app.PlaneSummaryLabel.FontSize=app.PlaneSummaryLabel.Position(4)-app.PlaneSummaryLabel.Position(4)*0.45;
                
                app.Sensor1SummaryLabel.Position(4)=app.SummaryPanel.Position(4)*(1/12);
                app.Sensor1SummaryLabel.Position(3)=app.Sensor1SummaryLabel.Position(4)*(15);
                app.Sensor1SummaryLabel.Position(1)=app.SummaryPanel.Position(3)*(1/10);
                app.Sensor1SummaryLabel.Position(2)=app.SummaryPanel.Position(4)*(2/7);
                app.Sensor1SummaryLabel.FontSize=app.Sensor1SummaryLabel.Position(4)-app.Sensor1SummaryLabel.Position(4)*0.45;
                
                app.Sensor2SummaryLabel.Position(4)=app.SummaryPanel.Position(4)*(1/12);
                app.Sensor2SummaryLabel.Position(3)=app.Sensor2SummaryLabel.Position(4)*(15);
                app.Sensor2SummaryLabel.Position(1)=app.SummaryPanel.Position(3)*(6/10);
                app.Sensor2SummaryLabel.Position(2)=app.SummaryPanel.Position(4)*(2/7);
                app.Sensor2SummaryLabel.FontSize=app.Sensor2SummaryLabel.Position(4)-app.Sensor2SummaryLabel.Position(4)*0.45;
                
                app.Sensor3SummaryLabel.Position(4)=app.SummaryPanel.Position(4)*(1/12);
                app.Sensor3SummaryLabel.Position(3)=app.Sensor3SummaryLabel.Position(4)*(15);
                app.Sensor3SummaryLabel.Position(1)=app.SummaryPanel.Position(3)*(1/10);
                app.Sensor3SummaryLabel.Position(2)=app.SummaryPanel.Position(4)*(1/7);
                app.Sensor3SummaryLabel.FontSize=app.Sensor3SummaryLabel.Position(4)-app.Sensor3SummaryLabel.Position(4)*0.45;
                
                app.Sensor4SummaryLabel.Position(4)=app.SummaryPanel.Position(4)*(1/12);
                app.Sensor4SummaryLabel.Position(3)=app.Sensor4SummaryLabel.Position(4)*(15);
                app.Sensor4SummaryLabel.Position(1)=app.SummaryPanel.Position(3)*(6/10);
                app.Sensor4SummaryLabel.Position(2)=app.SummaryPanel.Position(4)*(1/7);
                app.Sensor4SummaryLabel.FontSize=app.Sensor4SummaryLabel.Position(4)-app.Sensor4SummaryLabel.Position(4)*0.45;
                
                app.MovementSummaryLabel.Position(4)=app.SummaryPanel.Position(4)*(1/12);
                app.MovementSummaryLabel.Position(3)=app.SummaryPanel.Position(3)*(9/10);
                app.MovementSummaryLabel.Position(1)=app.SummaryPanel.Position(3)*(1/10);
                app.MovementSummaryLabel.Position(2)=app.SummaryPanel.Position(4)*(3/7);
                app.MovementSummaryLabel.FontSize=app.MovementSummaryLabel.Position(4)-app.MovementSummaryLabel.Position(4)*0.45;
                
                %CaptureTrajectory1Panel
                
                app.CaptureTrajectory1Panel.Position = [0 0 app.UIFigure.Position(3) app.UIFigure.Position(4)];
                
                app.Capture1Label.Position(4)=app.CaptureTrajectory1Panel.Position(4)*(1/14);
                app.Capture1Label.Position(3)=app.Capture1Label.Position(4)*(7.7);
                app.Capture1Label.Position(1)=app.CaptureTrajectory1Panel.Position(3)*(1/2)-app.Capture1Label.Position(3)*(1/2);
                app.Capture1Label.Position(2)=app.CaptureTrajectory1Panel.Position(4)*(11/12);
                app.Capture1Label.FontSize=app.Capture1Label.Position(4)-app.Capture1Label.Position(4)*0.45;
                
                app.MoveReferencePosLabel.Position(4)=app.CaptureTrajectory1Panel.Position(4)*(1/24);
                app.MoveReferencePosLabel.Position(3)=app.MoveReferencePosLabel.Position(4)*(12.9);
                app.MoveReferencePosLabel.Position(1)=app.CaptureTrajectory1Panel.Position(3)*(1/10);
                app.MoveReferencePosLabel.Position(2)=app.CaptureTrajectory1Panel.Position(4)*(10/12);
                app.MoveReferencePosLabel.FontSize=app.MoveReferencePosLabel.Position(4)-app.MoveReferencePosLabel.Position(4)*0.45;
                
                app.LeftReferencePosButton.Position(4)=app.CaptureTrajectory1Panel.Position(4)*(1/24);
                app.LeftReferencePosButton.Position(3)=app.LeftReferencePosButton.Position(4)*(4.7);
                app.LeftReferencePosButton.Position(1)=app.CaptureTrajectory1Panel.Position(3)*(1/3)-app.LeftReferencePosButton.Position(3)*(1/2);
                app.LeftReferencePosButton.Position(2)=app.CaptureTrajectory1Panel.Position(4)*(9/12);
                app.LeftReferencePosButton.FontSize=app.LeftReferencePosButton.Position(4)-app.LeftReferencePosButton.Position(4)*0.45;
                
                app.RightReferencePosButton.Position(4)=app.CaptureTrajectory1Panel.Position(4)*(1/24);
                app.RightReferencePosButton.Position(3)=app.RightReferencePosButton.Position(4)*(4.7);
                app.RightReferencePosButton.Position(1)=app.CaptureTrajectory1Panel.Position(3)*(2/3)-app.RightReferencePosButton.Position(3)*(1/2);
                app.RightReferencePosButton.Position(2)=app.CaptureTrajectory1Panel.Position(4)*(9/12);
                app.RightReferencePosButton.FontSize=app.RightReferencePosButton.Position(4)-app.RightReferencePosButton.Position(4)*0.45;
                
                app.ConfigureTrajectorySettingsLabel.Position(4)=app.CaptureTrajectory1Panel.Position(4)*(1/24);
                app.ConfigureTrajectorySettingsLabel.Position(3)=app.ConfigureTrajectorySettingsLabel.Position(4)*(9.6);
                app.ConfigureTrajectorySettingsLabel.Position(1)=app.CaptureTrajectory1Panel.Position(3)*(1/10);
                app.ConfigureTrajectorySettingsLabel.Position(2)=app.CaptureTrajectory1Panel.Position(4)*(8/12);
                app.ConfigureTrajectorySettingsLabel.FontSize=app.ConfigureTrajectorySettingsLabel.Position(4)-app.ConfigureTrajectorySettingsLabel.Position(4)*0.45;
                
                app.SmoothingLabel.Position(4)=app.CaptureTrajectory1Panel.Position(4)*(1/24);
                app.SmoothingLabel.Position(3)=app.SmoothingLabel.Position(4)*(7.7);
                app.SmoothingLabel.Position(1)=app.CaptureTrajectory1Panel.Position(3)*(2/10);
                app.SmoothingLabel.Position(2)=app.CaptureTrajectory1Panel.Position(4)*(7/12);
                app.SmoothingLabel.FontSize=app.SmoothingLabel.Position(4)-app.SmoothingLabel.Position(4)*0.45;
                
                app.ChoosePlotsLabel.Position(4)=app.CaptureTrajectory1Panel.Position(4)*(1/24);
                app.ChoosePlotsLabel.Position(3)=app.ChoosePlotsLabel.Position(4)*(13);
                app.ChoosePlotsLabel.Position(1)=app.CaptureTrajectory1Panel.Position(3)*(2/10);
                app.ChoosePlotsLabel.Position(2)=app.CaptureTrajectory1Panel.Position(4)*(5/12);
                app.ChoosePlotsLabel.FontSize=app.ChoosePlotsLabel.Position(4)-app.ChoosePlotsLabel.Position(4)*0.45;
                
                app.PlotAdjustmentCheckBox.Position(4)=app.CaptureTrajectory1Panel.Position(4)*(1/24);
                app.PlotAdjustmentCheckBox.Position(3)=app.PlotAdjustmentCheckBox.Position(4)*(10.6);
                app.PlotAdjustmentCheckBox.Position(1)=app.CaptureTrajectory1Panel.Position(3)*(2/10);
                app.PlotAdjustmentCheckBox.Position(2)=app.CaptureTrajectory1Panel.Position(4)*(4/12);
                app.PlotAdjustmentCheckBox.FontSize=app.PlotAdjustmentCheckBox.Position(4)-app.PlotAdjustmentCheckBox.Position(4)*0.45;
                
                app.PlotCartesianPosCheckBox.Position(4)=app.CaptureTrajectory1Panel.Position(4)*(1/24);
                app.PlotCartesianPosCheckBox.Position(3)=app.PlotCartesianPosCheckBox.Position(4)*(6.2);
                app.PlotCartesianPosCheckBox.Position(1)=app.CaptureTrajectory1Panel.Position(3)*(2/10);
                app.PlotCartesianPosCheckBox.Position(2)=app.CaptureTrajectory1Panel.Position(4)*(3/12);
                app.PlotCartesianPosCheckBox.FontSize=app.PlotCartesianPosCheckBox.Position(4)-app.PlotCartesianPosCheckBox.Position(4)*0.45;
                
                app.PlotCartesianVelCheckBox.Position(4)=app.CaptureTrajectory1Panel.Position(4)*(1/24);
                app.PlotCartesianVelCheckBox.Position(3)=app.PlotCartesianVelCheckBox.Position(4)*(6.7);
                app.PlotCartesianVelCheckBox.Position(1)=app.CaptureTrajectory1Panel.Position(3)*(2/10);
                app.PlotCartesianVelCheckBox.Position(2)=app.CaptureTrajectory1Panel.Position(4)*(2/12);
                app.PlotCartesianVelCheckBox.FontSize=app.PlotCartesianVelCheckBox.Position(4)-app.PlotCartesianVelCheckBox.Position(4)*0.45;
                
                app.PlotErrorCartesianPosCheckBox.Position(4)=app.CaptureTrajectory1Panel.Position(4)*(1/24);
                app.PlotErrorCartesianPosCheckBox.Position(3)=app.PlotErrorCartesianPosCheckBox.Position(4)*(7.7);
                app.PlotErrorCartesianPosCheckBox.Position(1)=app.CaptureTrajectory1Panel.Position(3)*(6/10);
                app.PlotErrorCartesianPosCheckBox.Position(2)=app.CaptureTrajectory1Panel.Position(4)*(4/12);
                app.PlotErrorCartesianPosCheckBox.FontSize=app.PlotErrorCartesianPosCheckBox.Position(4)-app.PlotErrorCartesianPosCheckBox.Position(4)*0.45;
                
                app.PlotErrorCartesianVelCheckBox.Position(4)=app.CaptureTrajectory1Panel.Position(4)*(1/24);
                app.PlotErrorCartesianVelCheckBox.Position(3)=app.PlotErrorCartesianVelCheckBox.Position(4)*(8.1);
                app.PlotErrorCartesianVelCheckBox.Position(1)=app.CaptureTrajectory1Panel.Position(3)*(6/10);
                app.PlotErrorCartesianVelCheckBox.Position(2)=app.CaptureTrajectory1Panel.Position(4)*(3/12);
                app.PlotErrorCartesianVelCheckBox.FontSize=app.PlotErrorCartesianVelCheckBox.Position(4)-app.PlotErrorCartesianVelCheckBox.Position(4)*0.45;
                
                app.Plot3DCartesianPosCheckBox.Position(4)=app.CaptureTrajectory1Panel.Position(4)*(1/24);
                app.Plot3DCartesianPosCheckBox.Position(3)=app.Plot3DCartesianPosCheckBox.Position(4)*(7);
                app.Plot3DCartesianPosCheckBox.Position(1)=app.CaptureTrajectory1Panel.Position(3)*(6/10);
                app.Plot3DCartesianPosCheckBox.Position(2)=app.CaptureTrajectory1Panel.Position(4)*(2/12);
                app.Plot3DCartesianPosCheckBox.FontSize=app.Plot3DCartesianPosCheckBox.Position(4)-app.Plot3DCartesianPosCheckBox.Position(4)*0.45;
                
                app.GoToCapture2Button.Position(4)=app.CaptureTrajectory1Panel.Position(4)*(1/24);
                app.GoToCapture2Button.Position(3)=app.GoToCapture2Button.Position(4)*(4.7);
                app.GoToCapture2Button.Position(1)=app.CaptureTrajectory1Panel.Position(3)*(1/2)-app.GoToCapture2Button.Position(3)*(1/2);
                app.GoToCapture2Button.Position(2)=app.CaptureTrajectory1Panel.Position(4)*(1/12);
                app.GoToCapture2Button.FontSize=app.GoToCapture2Button.Position(4)-app.GoToCapture2Button.Position(4)*0.45;
                
                app.InputSmoothingEditFieldLabel.Position(4)=app.CaptureTrajectory1Panel.Position(4)*(1/24);
                app.InputSmoothingEditFieldLabel.Position(3)=app.InputSmoothingEditFieldLabel.Position(4)*(5.8);
                app.InputSmoothingEditFieldLabel.Position(1)=app.CaptureTrajectory1Panel.Position(3)*(1/4)-app.InputSmoothingEditFieldLabel.Position(3)*(1/2);
                app.InputSmoothingEditFieldLabel.Position(2)=app.CaptureTrajectory1Panel.Position(4)*(6/12);
                app.InputSmoothingEditFieldLabel.FontSize=app.InputSmoothingEditFieldLabel.Position(4)-app.InputSmoothingEditFieldLabel.Position(4)*0.45;
                app.InputSmoothingEditField.Position(4)=app.InputSmoothingEditFieldLabel.Position(4);
                app.InputSmoothingEditField.Position(3)=app.InputSmoothingEditFieldLabel.Position(3)*(1/3);
                app.InputSmoothingEditField.Position(1)=app.InputSmoothingEditFieldLabel.Position(1)+app.InputSmoothingEditFieldLabel.Position(3)+app.InputSmoothingEditFieldLabel.Position(3)*(1/10);
                app.InputSmoothingEditField.Position(2)=app.InputSmoothingEditFieldLabel.Position(2);
                app.InputSmoothingEditField.FontSize=app.InputSmoothingEditFieldLabel.FontSize;
                
                app.CommandedSmoothingEditFieldLabel.Position(4)=app.CaptureTrajectory1Panel.Position(4)*(1/24);
                app.CommandedSmoothingEditFieldLabel.Position(3)=app.CommandedSmoothingEditFieldLabel.Position(4)*(5.8);
                app.CommandedSmoothingEditFieldLabel.Position(1)=app.CaptureTrajectory1Panel.Position(3)*(2/4)-app.CommandedSmoothingEditFieldLabel.Position(3)*(1/2);
                app.CommandedSmoothingEditFieldLabel.Position(2)=app.CaptureTrajectory1Panel.Position(4)*(6/12);
                app.CommandedSmoothingEditFieldLabel.FontSize=app.CommandedSmoothingEditFieldLabel.Position(4)-app.CommandedSmoothingEditFieldLabel.Position(4)*0.45;
                app.CommandedSmoothingEditField.Position(4)=app.CommandedSmoothingEditFieldLabel.Position(4);
                app.CommandedSmoothingEditField.Position(3)=app.CommandedSmoothingEditFieldLabel.Position(3)*(1/3);
                app.CommandedSmoothingEditField.Position(1)=app.CommandedSmoothingEditFieldLabel.Position(1)+app.CommandedSmoothingEditFieldLabel.Position(3)+app.CommandedSmoothingEditFieldLabel.Position(3)*(1/10);
                app.CommandedSmoothingEditField.Position(2)=app.CommandedSmoothingEditFieldLabel.Position(2);
                app.CommandedSmoothingEditField.FontSize=app.CommandedSmoothingEditFieldLabel.FontSize;
                
                app.OutputSmoothingEditFieldLabel.Position(4)=app.CaptureTrajectory1Panel.Position(4)*(1/24);
                app.OutputSmoothingEditFieldLabel.Position(3)=app.OutputSmoothingEditFieldLabel.Position(4)*(6.2);
                app.OutputSmoothingEditFieldLabel.Position(1)=app.CaptureTrajectory1Panel.Position(3)*(3/4)-app.OutputSmoothingEditFieldLabel.Position(3)*(1/2);
                app.OutputSmoothingEditFieldLabel.Position(2)=app.CaptureTrajectory1Panel.Position(4)*(6/12);
                app.OutputSmoothingEditFieldLabel.FontSize=app.OutputSmoothingEditFieldLabel.Position(4)-app.OutputSmoothingEditFieldLabel.Position(4)*0.45;
                app.OutputSmoothingEditField.Position(4)=app.OutputSmoothingEditFieldLabel.Position(4);
                app.OutputSmoothingEditField.Position(3)=app.OutputSmoothingEditFieldLabel.Position(3)*(1/3);
                app.OutputSmoothingEditField.Position(1)=app.OutputSmoothingEditFieldLabel.Position(1)+app.OutputSmoothingEditFieldLabel.Position(3)+app.OutputSmoothingEditFieldLabel.Position(3)*(1/10);
                app.OutputSmoothingEditField.Position(2)=app.OutputSmoothingEditFieldLabel.Position(2);
                app.OutputSmoothingEditField.FontSize=app.OutputSmoothingEditFieldLabel.FontSize;
                
                %CaptureTrajectory2Panel
                
                app.CaptureTrajectory2Panel.Position = [0 0 app.UIFigure.Position(3) app.UIFigure.Position(4)];
                
                app.Capture2Label.Position(4)=app.CaptureTrajectory2Panel.Position(4)*(1/14);
                app.Capture2Label.Position(3)=app.Capture2Label.Position(4)*(7.7);
                app.Capture2Label.Position(1)=app.CaptureTrajectory2Panel.Position(3)*(1/2)-app.Capture2Label.Position(3)*(1/2);
                app.Capture2Label.Position(2)=app.CaptureTrajectory2Panel.Position(4)*(8/9);
                app.Capture2Label.FontSize=app.Capture2Label.Position(4)-app.Capture2Label.Position(4)*0.45;
                
                app.InitialPositionLabel.Position(4)=app.CaptureTrajectory2Panel.Position(4)*(1/24);
                app.InitialPositionLabel.Position(3)=app.InitialPositionLabel.Position(4)*(7.9);
                app.InitialPositionLabel.Position(1)=app.CaptureTrajectory2Panel.Position(3)*(1/10);
                app.InitialPositionLabel.Position(2)=app.CaptureTrajectory2Panel.Position(4)*(7/9);
                app.InitialPositionLabel.FontSize=app.InitialPositionLabel.Position(4)-app.InitialPositionLabel.Position(4)*0.45;
                
                app.FreeBlockRobotButton.Position(4)=app.CaptureTrajectory2Panel.Position(4)*(1/24);
                app.FreeBlockRobotButton.Position(3)=app.FreeBlockRobotButton.Position(4)*(4.7)*3;
                app.FreeBlockRobotButton.Position(1)=app.CaptureTrajectory2Panel.Position(3)*(1/2)-app.FreeBlockRobotButton.Position(3)*(1/2);
                app.FreeBlockRobotButton.Position(2)=app.CaptureTrajectory2Panel.Position(4)*(6/9)+app.CaptureTrajectory2Panel.Position(4)*(1/48);
                app.FreeBlockRobotButton.FontSize=app.FreeBlockRobotButton.Position(4)-app.FreeBlockRobotButton.Position(4)*0.45;
                
                app.FreeXButton.Position(4)=app.CaptureTrajectory2Panel.Position(4)*(1/24);
                app.FreeXButton.Position(3)=app.FreeXButton.Position(4)*(4.7);
                app.FreeXButton.Position(1)=app.CaptureTrajectory2Panel.Position(3)*(1/2)-app.FreeXButton.Position(3)*(1/2)-app.FreeXButton.Position(3);
                app.FreeXButton.Position(2)=app.CaptureTrajectory2Panel.Position(4)*(6/9)-app.CaptureTrajectory2Panel.Position(4)*(1/48);
                app.FreeXButton.FontSize=app.FreeXButton.Position(4)-app.FreeXButton.Position(4)*0.45;
                
                app.FreeYButton.Position(4)=app.CaptureTrajectory2Panel.Position(4)*(1/24);
                app.FreeYButton.Position(3)=app.FreeYButton.Position(4)*(4.7);
                app.FreeYButton.Position(1)=app.CaptureTrajectory2Panel.Position(3)*(1/2)-app.FreeYButton.Position(3)*(1/2);
                app.FreeYButton.Position(2)=app.CaptureTrajectory2Panel.Position(4)*(6/9)-app.CaptureTrajectory2Panel.Position(4)*(1/48);
                app.FreeYButton.FontSize=app.FreeYButton.Position(4)-app.FreeYButton.Position(4)*0.45;
                
                app.FreeZButton.Position(4)=app.CaptureTrajectory2Panel.Position(4)*(1/24);
                app.FreeZButton.Position(3)=app.FreeZButton.Position(4)*(4.7);
                app.FreeZButton.Position(1)=app.CaptureTrajectory2Panel.Position(3)*(1/2)-app.FreeZButton.Position(3)*(1/2)+app.FreeZButton.Position(3);
                app.FreeZButton.Position(2)=app.CaptureTrajectory2Panel.Position(4)*(6/9)-app.CaptureTrajectory2Panel.Position(4)*(1/48);
                app.FreeZButton.FontSize=app.FreeZButton.Position(4)-app.FreeZButton.Position(4)*0.45;
                
                app.CaptureTrajectoryLabel.Position(4)=app.CaptureTrajectory2Panel.Position(4)*(1/24);
                app.CaptureTrajectoryLabel.Position(3)=app.CaptureTrajectoryLabel.Position(4)*(6.5);
                app.CaptureTrajectoryLabel.Position(1)=app.CaptureTrajectory2Panel.Position(3)*(1/10);
                app.CaptureTrajectoryLabel.Position(2)=app.CaptureTrajectory2Panel.Position(4)*(5/9);
                app.CaptureTrajectoryLabel.FontSize=app.CaptureTrajectoryLabel.Position(4)-app.CaptureTrajectoryLabel.Position(4)*0.45;
                
                app.StartCaptureButton.Position(4)=app.CaptureTrajectory2Panel.Position(4)*(1/24);
                app.StartCaptureButton.Position(3)=app.StartCaptureButton.Position(4)*(4.7);
                app.StartCaptureButton.Position(1)=app.CaptureTrajectory2Panel.Position(3)*(1/4)-app.StartCaptureButton.Position(3)*(1/2);
                app.StartCaptureButton.Position(2)=app.CaptureTrajectory2Panel.Position(4)*(4/9);
                app.StartCaptureButton.FontSize=app.StartCaptureButton.Position(4)-app.StartCaptureButton.Position(4)*0.45;
                
                app.EndCaptureButton.Position(4)=app.CaptureTrajectory2Panel.Position(4)*(1/24);
                app.EndCaptureButton.Position(3)=app.EndCaptureButton.Position(4)*(5);
                app.EndCaptureButton.Position(1)=app.CaptureTrajectory2Panel.Position(3)*(2/4)-app.EndCaptureButton.Position(3)*(1/2);
                app.EndCaptureButton.Position(2)=app.CaptureTrajectory2Panel.Position(4)*(4/9);
                app.EndCaptureButton.FontSize=app.EndCaptureButton.Position(4)-app.EndCaptureButton.Position(4)*0.45;
                
                app.GenerateSaveTrajectoryLabel.Position(4)=app.CaptureTrajectory2Panel.Position(4)*(1/24);
                app.GenerateSaveTrajectoryLabel.Position(3)=app.GenerateSaveTrajectoryLabel.Position(4)*(8.9);
                app.GenerateSaveTrajectoryLabel.Position(1)=app.CaptureTrajectory2Panel.Position(3)*(1/10);
                app.GenerateSaveTrajectoryLabel.Position(2)=app.CaptureTrajectory2Panel.Position(4)*(3/9);
                app.GenerateSaveTrajectoryLabel.FontSize=app.GenerateSaveTrajectoryLabel.Position(4)-app.GenerateSaveTrajectoryLabel.Position(4)*0.45;
                
                app.SaveTrajectoryButton.Position(4)=app.CaptureTrajectory2Panel.Position(4)*(1/24);
                app.SaveTrajectoryButton.Position(3)=app.SaveTrajectoryButton.Position(4)*(5.7);
                app.SaveTrajectoryButton.Position(1)=app.CaptureTrajectory2Panel.Position(3)*(1/2)-app.SaveTrajectoryButton.Position(3)*(1/2);
                app.SaveTrajectoryButton.Position(2)=app.CaptureTrajectory2Panel.Position(4)*(1/9);
                app.SaveTrajectoryButton.FontSize=app.SaveTrajectoryButton.Position(4)-app.SaveTrajectoryButton.Position(4)*0.45;
                
                app.RepeatCaptureButton.Position(4)=app.CaptureTrajectory2Panel.Position(4)*(1/24);
                app.RepeatCaptureButton.Position(3)=app.RepeatCaptureButton.Position(4)*(4.7);
                app.RepeatCaptureButton.Position(1)=app.CaptureTrajectory2Panel.Position(3)*(3/4)-app.RepeatCaptureButton.Position(3)*(1/2);
                app.RepeatCaptureButton.Position(2)=app.CaptureTrajectory2Panel.Position(4)*(4/9);
                app.RepeatCaptureButton.FontSize=app.RepeatCaptureButton.Position(4)-app.RepeatCaptureButton.Position(4)*0.45;
                
                app.InputDropDownLabel.Position(4)=app.CaptureTrajectory2Panel.Position(4)*(1/24);
                app.InputDropDownLabel.Position(3)=app.InputDropDownLabel.Position(4)*(6.5);
                app.InputDropDownLabel.Position(1)=app.CaptureTrajectory2Panel.Position(3)*(1/10)+20;
                app.InputDropDownLabel.Position(2)=app.CaptureTrajectory2Panel.Position(4)*(2/9);
                app.InputDropDownLabel.FontSize=app.InputDropDownLabel.Position(4)-app.InputDropDownLabel.Position(4)*0.45;
                app.InputDropDown.Position(4)=app.InputDropDownLabel.Position(4);
                app.InputDropDown.Position(3)=app.CaptureTrajectory2Panel.Position(3)*(1/3);
                app.InputDropDown.Position(1)=app.InputDropDownLabel.Position(1)+app.InputDropDownLabel.Position(3)+app.InputDropDownLabel.Position(3)*(1/10);
                app.InputDropDown.Position(2)=app.InputDropDownLabel.Position(2);
                app.InputDropDown.FontSize=app.InputDropDownLabel.FontSize;
                
                %RepeatTrajectoryPanel
                
                app.RepeatTrajectoryPanel.Position = [0 0 app.UIFigure.Position(3) app.UIFigure.Position(4)];
                
                app.RepeatTrajectoryLabel.Position(4)=app.RepeatTrajectoryPanel.Position(4)*(1/14);
                app.RepeatTrajectoryLabel.Position(3)=app.RepeatTrajectoryLabel.Position(4)*(5.4);
                app.RepeatTrajectoryLabel.Position(1)=app.RepeatTrajectoryPanel.Position(3)*(1/2)-app.RepeatTrajectoryLabel.Position(3)*(1/2);
                app.RepeatTrajectoryLabel.Position(2)=app.RepeatTrajectoryPanel.Position(4)*(12/13);
                app.RepeatTrajectoryLabel.FontSize=app.RepeatTrajectoryLabel.Position(4)-app.RepeatTrajectoryLabel.Position(4)*0.45;
                
                app.ChooseTrajectoryLabel.Position(4)=app.RepeatTrajectoryPanel.Position(4)*(1/24);
                app.ChooseTrajectoryLabel.Position(3)=app.ChooseTrajectoryLabel.Position(4)*(9.1);
                app.ChooseTrajectoryLabel.Position(1)=app.RepeatTrajectoryPanel.Position(3)*(1/10);
                app.ChooseTrajectoryLabel.Position(2)=app.RepeatTrajectoryPanel.Position(4)*(11/13);
                app.ChooseTrajectoryLabel.FontSize=app.ChooseTrajectoryLabel.Position(4)-app.ChooseTrajectoryLabel.Position(4)*0.45;
                
                app.ChangeVelocityLabel.Position(4)=app.RepeatTrajectoryPanel.Position(4)*(1/24);
                app.ChangeVelocityLabel.Position(3)=app.ChangeVelocityLabel.Position(4)*(5.9);
                app.ChangeVelocityLabel.Position(1)=app.RepeatTrajectoryPanel.Position(3)*(6/10);
                app.ChangeVelocityLabel.Position(2)=app.RepeatTrajectoryPanel.Position(4)*(11/13);
                app.ChangeVelocityLabel.FontSize=app.ChangeVelocityLabel.Position(4)-app.ChangeVelocityLabel.Position(4)*0.45;
                
                app.VelocitySlider.Position(3)=app.RepeatTrajectoryPanel.Position(3)*(1/4);
                app.VelocitySlider.Position(1)=app.RepeatTrajectoryPanel.Position(3)*(6/10)+20;
                app.VelocitySlider.Position(2)=app.RepeatTrajectoryPanel.Position(4)*(10/13)+app.RepeatTrajectoryPanel.Position(4)*(1/48);
                app.VelocitySlider.FontSize=app.ChangeVelocityLabel.FontSize;
                
                app.CalibrateFTSensorLabel.Position(4)=app.RepeatTrajectoryPanel.Position(4)*(1/24);
                app.CalibrateFTSensorLabel.Position(3)=app.CalibrateFTSensorLabel.Position(4)*(14.7);
                app.CalibrateFTSensorLabel.Position(1)=app.RepeatTrajectoryPanel.Position(3)*(1/10);
                app.CalibrateFTSensorLabel.Position(2)=app.RepeatTrajectoryPanel.Position(4)*(9/13);
                app.CalibrateFTSensorLabel.FontSize=app.CalibrateFTSensorLabel.Position(4)-app.CalibrateFTSensorLabel.Position(4)*0.45;
                
                app.InitialPositionEmptyButton.Position(4)=app.RepeatTrajectoryPanel.Position(4)*(1/24);
                app.InitialPositionEmptyButton.Position(3)=app.InitialPositionEmptyButton.Position(4)*(7.5);
                app.InitialPositionEmptyButton.Position(1)=app.RepeatTrajectoryPanel.Position(3)*(1/10)+20;
                app.InitialPositionEmptyButton.Position(2)=app.RepeatTrajectoryPanel.Position(4)*(8/13);
                app.InitialPositionEmptyButton.FontSize=app.InitialPositionEmptyButton.Position(4)-app.InitialPositionEmptyButton.Position(4)*0.45;
                
                app.RelaxMusclesEmptyButton.Position(4)=app.RepeatTrajectoryPanel.Position(4)*(1/24);
                app.RelaxMusclesEmptyButton.Position(3)=app.RelaxMusclesEmptyButton.Position(4)*(8.7);
                app.RelaxMusclesEmptyButton.Position(1)=app.RepeatTrajectoryPanel.Position(3)*(1/10)+20;
                app.RelaxMusclesEmptyButton.Position(2)=app.RepeatTrajectoryPanel.Position(4)*(7/13);
                app.RelaxMusclesEmptyButton.FontSize=app.RelaxMusclesEmptyButton.Position(4)-app.RelaxMusclesEmptyButton.Position(4)*0.45;
                
                app.RunTrajectoryEmptyButton.Position(4)=app.RepeatTrajectoryPanel.Position(4)*(1/24);
                app.RunTrajectoryEmptyButton.Position(3)=app.RunTrajectoryEmptyButton.Position(4)*(8);
                app.RunTrajectoryEmptyButton.Position(1)=app.RepeatTrajectoryPanel.Position(3)*(1/10)+20;
                app.RunTrajectoryEmptyButton.Position(2)=app.RepeatTrajectoryPanel.Position(4)*(6/13);
                app.RunTrajectoryEmptyButton.FontSize=app.RunTrajectoryEmptyButton.Position(4)-app.RunTrajectoryEmptyButton.Position(4)*0.45;
                
                app.RunTrajectoryLabel.Position(4)=app.RepeatTrajectoryPanel.Position(4)*(1/24);
                app.RunTrajectoryLabel.Position(3)=app.RunTrajectoryLabel.Position(4)*(6.1);
                app.RunTrajectoryLabel.Position(1)=app.RepeatTrajectoryPanel.Position(3)*(1/10);
                app.RunTrajectoryLabel.Position(2)=app.RepeatTrajectoryPanel.Position(4)*(5/13);
                app.RunTrajectoryLabel.FontSize=app.RunTrajectoryLabel.Position(4)-app.RunTrajectoryLabel.Position(4)*0.45;
                
                app.InitialPositionRunButton.Position(4)=app.RepeatTrajectoryPanel.Position(4)*(1/24);
                app.InitialPositionRunButton.Position(3)=app.InitialPositionRunButton.Position(4)*(7.5);
                app.InitialPositionRunButton.Position(1)=app.RepeatTrajectoryPanel.Position(3)*(1/10)+20;
                app.InitialPositionRunButton.Position(2)=app.RepeatTrajectoryPanel.Position(4)*(4/13);
                app.InitialPositionRunButton.FontSize=app.InitialPositionRunButton.Position(4)-app.InitialPositionRunButton.Position(4)*0.45;
                
                app.RelaxMusclesRunButton.Position(4)=app.RepeatTrajectoryPanel.Position(4)*(1/24);
                app.RelaxMusclesRunButton.Position(3)=app.RelaxMusclesRunButton.Position(4)*(8.7);
                app.RelaxMusclesRunButton.Position(1)=app.RepeatTrajectoryPanel.Position(3)*(1/10)+20;
                app.RelaxMusclesRunButton.Position(2)=app.RepeatTrajectoryPanel.Position(4)*(3/13);
                app.RelaxMusclesRunButton.FontSize=app.RelaxMusclesRunButton.Position(4)-app.RelaxMusclesRunButton.Position(4)*0.45;
                
                app.RunTrajectoryButton.Position(4)=app.RepeatTrajectoryPanel.Position(4)*(1/24);
                app.RunTrajectoryButton.Position(3)=app.RunTrajectoryButton.Position(4)*(5.7);
                app.RunTrajectoryButton.Position(1)=app.RepeatTrajectoryPanel.Position(3)*(1/10)+20;
                app.RunTrajectoryButton.Position(2)=app.RepeatTrajectoryPanel.Position(4)*(2/13);
                app.RunTrajectoryButton.FontSize=app.RunTrajectoryButton.Position(4)-app.RunTrajectoryButton.Position(4)*0.45;
                
                app.RepetitionsSpinner.Position(4)=app.RepeatTrajectoryPanel.Position(4)*(1/24);
                app.RepetitionsSpinner.Position(3)=app.RepetitionsSpinner.Position(4)*(2.5);
                app.RepetitionsSpinner.Position(1)=app.RunTrajectoryButton.Position(1)+app.RunTrajectoryButton.Position(3)+20;
                app.RepetitionsSpinner.Position(2)=app.RepeatTrajectoryPanel.Position(4)*(2/13);
                app.RepetitionsSpinner.FontSize=app.RepetitionsSpinner.Position(4)-app.RepetitionsSpinner.Position(4)*0.45;
                
                app.GoToResultsButton.Position(4)=app.RepeatTrajectoryPanel.Position(4)*(1/24);
                app.GoToResultsButton.Position(3)=app.GoToResultsButton.Position(4)*(4.7);
                app.GoToResultsButton.Position(1)=app.RepeatTrajectoryPanel.Position(3)*(1/3)-app.GoToResultsButton.Position(3)*(1/2);
                app.GoToResultsButton.Position(2)=app.RepeatTrajectoryPanel.Position(4)*(1/13);
                app.GoToResultsButton.FontSize=app.GoToResultsButton.Position(4)-app.GoToResultsButton.Position(4)*0.45;
                
                app.BackToTrialMenuRepeatButton.Position(4)=app.RepeatTrajectoryPanel.Position(4)*(1/24);
                app.BackToTrialMenuRepeatButton.Position(3)=app.BackToTrialMenuRepeatButton.Position(4)*(4.7);
                app.BackToTrialMenuRepeatButton.Position(1)=app.RepeatTrajectoryPanel.Position(3)*(2/3)-app.BackToTrialMenuRepeatButton.Position(3)*(1/2);
                app.BackToTrialMenuRepeatButton.Position(2)=app.RepeatTrajectoryPanel.Position(4)*(1/13);
                app.BackToTrialMenuRepeatButton.FontSize=app.BackToTrialMenuRepeatButton.Position(4)-app.BackToTrialMenuRepeatButton.Position(4)*0.45;
                
                app.AdjustedTrajectoryDropDownLabel.Position(4)=app.RepeatTrajectoryPanel.Position(4)*(1/24);
                app.AdjustedTrajectoryDropDownLabel.Position(3)=app.AdjustedTrajectoryDropDownLabel.Position(4)*(3.4);
                app.AdjustedTrajectoryDropDownLabel.Position(1)=app.RepeatTrajectoryPanel.Position(3)*(1/10)+20;
                app.AdjustedTrajectoryDropDownLabel.Position(2)=app.RepeatTrajectoryPanel.Position(4)*(10/13);
                app.AdjustedTrajectoryDropDownLabel.FontSize=app.AdjustedTrajectoryDropDownLabel.Position(4)-app.AdjustedTrajectoryDropDownLabel.Position(4)*0.45;
                app.AdjustedTrajectoryDropDown.Position(4)=app.AdjustedTrajectoryDropDownLabel.Position(4);
                app.AdjustedTrajectoryDropDown.Position(3)=app.RepeatTrajectoryPanel.Position(3)*(1/3);
                app.AdjustedTrajectoryDropDown.Position(1)=app.AdjustedTrajectoryDropDownLabel.Position(1)+app.AdjustedTrajectoryDropDownLabel.Position(3)+app.AdjustedTrajectoryDropDownLabel.Position(3)*(1/10);
                app.AdjustedTrajectoryDropDown.Position(2)=app.AdjustedTrajectoryDropDownLabel.Position(2);
                app.AdjustedTrajectoryDropDown.FontSize=app.AdjustedTrajectoryDropDownLabel.FontSize;
                
                app.VelocityLabel.Position(4)=app.RepeatTrajectoryPanel.Position(4)*(1/24);
                app.VelocityLabel.Position(3)=app.VelocityLabel.Position(4)*(2.5);
                app.VelocityLabel.Position(1)=app.VelocitySlider.Position(1)+app.VelocitySlider.Position(3)+20;
                app.VelocityLabel.Position(2)=app.RepeatTrajectoryPanel.Position(4)*(10/13);
                app.VelocityLabel.FontSize=app.VelocityLabel.Position(4)-app.VelocityLabel.Position(4)*0.45;
                
                %ResultsPanel
                
                app.ResultsPanel.Position = [0 0 app.UIFigure.Position(3) app.UIFigure.Position(4)];
                
                app.Trajectory2Label.Position(4)=app.ResultsPanel.Position(4)*(1/24);
                app.Trajectory2Label.Position(3)=app.Trajectory2Label.Position(4)*(3.3);
                app.Trajectory2Label.Position(1)=app.ResultsPanel.Position(3)*(1/10);
                app.Trajectory2Label.Position(2)=app.ResultsPanel.Position(4)*(10/13);
                app.Trajectory2Label.FontSize=app.Trajectory2Label.Position(4)-app.Trajectory2Label.Position(4)*0.45;
                
                app.Joint2Label.Position(4)=app.ResultsPanel.Position(4)*(1/24);
                app.Joint2Label.Position(3)=app.Joint2Label.Position(4)*(2.6);
                app.Joint2Label.Position(1)=app.ResultsPanel.Position(3)*(1/10)+20;
                app.Joint2Label.Position(2)=app.ResultsPanel.Position(4)*(9/13);
                app.Joint2Label.FontSize=app.Joint2Label.Position(4)-app.Joint2Label.Position(4)*0.45;
                
                app.q2Button.Position(4)=app.ResultsPanel.Position(4)*(1/24);
                app.q2Button.Position(3)=app.q2Button.Position(4)*(1.2);
                app.q2Button.Position(1)=app.ResultsPanel.Position(3)*(1/10)+40;
                app.q2Button.Position(2)=app.ResultsPanel.Position(4)*(8/13);
                app.q2Button.FontSize=app.q2Button.Position(4)-app.q2Button.Position(4)*0.45;
                
                app.dq2Button.Position(4)=app.ResultsPanel.Position(4)*(1/24);
                app.dq2Button.Position(3)=app.dq2Button.Position(4)*(1.4);
                app.dq2Button.Position(1)=app.q2Button.Position(1)+app.q2Button.Position(3)+10;
                app.dq2Button.Position(2)=app.ResultsPanel.Position(4)*(8/13);
                app.dq2Button.FontSize=app.dq2Button.Position(4)-app.dq2Button.Position(4)*0.45;
                
                app.JointPrecision2Label.Position(4)=app.ResultsPanel.Position(4)*(1/24);
                app.JointPrecision2Label.Position(3)=app.JointPrecision2Label.Position(4)*(2.8);
                app.JointPrecision2Label.Position(1)=app.ResultsPanel.Position(3)*(1/10)+20;
                app.JointPrecision2Label.Position(2)=app.ResultsPanel.Position(4)*(7/13);
                app.JointPrecision2Label.FontSize=app.JointPrecision2Label.Position(4)-app.JointPrecision2Label.Position(4)*0.45;
                
                app.JointAccuracy2Label.Position(4)=app.ResultsPanel.Position(4)*(1/24);
                app.JointAccuracy2Label.Position(3)=app.JointAccuracy2Label.Position(4)*(2.8);
                app.JointAccuracy2Label.Position(1)=app.ResultsPanel.Position(3)*(1/10)+20;
                app.JointAccuracy2Label.Position(2)=app.ResultsPanel.Position(4)*(6/13);
                app.JointAccuracy2Label.FontSize=app.JointAccuracy2Label.Position(4)-app.JointAccuracy2Label.Position(4)*0.45;
                
                app.CartesianPrecision2Label.Position(4)=app.ResultsPanel.Position(4)*(1/24);
                app.CartesianPrecision2Label.Position(3)=app.CartesianPrecision2Label.Position(4)*(2.8);
                app.CartesianPrecision2Label.Position(1)=app.ResultsPanel.Position(3)*(6/10)+20;
                app.CartesianPrecision2Label.Position(2)=app.ResultsPanel.Position(4)*(7/13);
                app.CartesianPrecision2Label.FontSize=app.CartesianPrecision2Label.Position(4)-app.CartesianPrecision2Label.Position(4)*0.45;
                
                app.CartesianAccuracy2Label.Position(4)=app.ResultsPanel.Position(4)*(1/24);
                app.CartesianAccuracy2Label.Position(3)=app.CartesianAccuracy2Label.Position(4)*(2.8);
                app.CartesianAccuracy2Label.Position(1)=app.ResultsPanel.Position(3)*(6/10)+20;
                app.CartesianAccuracy2Label.Position(2)=app.ResultsPanel.Position(4)*(6/13);
                app.CartesianAccuracy2Label.FontSize=app.CartesianAccuracy2Label.Position(4)-app.CartesianAccuracy2Label.Position(4)*0.45;
                
                app.Eq2Button.Position(4)=app.ResultsPanel.Position(4)*(1/24);
                app.Eq2Button.Position(3)=app.Eq2Button.Position(4)*(1.8);
                app.Eq2Button.Position(1)=app.JointAccuracy2Label.Position(1)+app.JointAccuracy2Label.Position(3)+10;
                app.Eq2Button.Position(2)=app.ResultsPanel.Position(4)*(6/13);
                app.Eq2Button.FontSize=app.Eq2Button.Position(4)-app.Eq2Button.Position(4)*0.45;
                
                app.Edq2Button.Position(4)=app.ResultsPanel.Position(4)*(1/24);
                app.Edq2Button.Position(3)=app.Edq2Button.Position(4)*(2.2);
                app.Edq2Button.Position(1)=app.Eq2Button.Position(1)+app.Eq2Button.Position(3)+10;
                app.Edq2Button.Position(2)=app.ResultsPanel.Position(4)*(6/13);
                app.Edq2Button.FontSize=app.Edq2Button.Position(4)-app.Edq2Button.Position(4)*0.45;
                
                app.Cartesian2Label.Position(4)=app.ResultsPanel.Position(4)*(1/24);
                app.Cartesian2Label.Position(3)=app.Cartesian2Label.Position(4)*(3.2);
                app.Cartesian2Label.Position(1)=app.ResultsPanel.Position(3)*(6/10);
                app.Cartesian2Label.Position(2)=app.ResultsPanel.Position(4)*(9/13);
                app.Cartesian2Label.FontSize=app.Cartesian2Label.Position(4)-app.Cartesian2Label.Position(4)*0.45;
                
                app.x2Button.Position(4)=app.ResultsPanel.Position(4)*(1/24);
                app.x2Button.Position(3)=app.x2Button.Position(4)*(1.2);
                app.x2Button.Position(1)=app.ResultsPanel.Position(3)*(6/10)+20;
                app.x2Button.Position(2)=app.ResultsPanel.Position(4)*(8/13);
                app.x2Button.FontSize=app.x2Button.Position(4)-app.x2Button.Position(4)*0.45;
                
                app.Ex2Button.Position(4)=app.ResultsPanel.Position(4)*(1/24);
                app.Ex2Button.Position(3)=app.Ex2Button.Position(4)*(1.8);
                app.Ex2Button.Position(1)=app.CartesianAccuracy2Label.Position(1)+app.CartesianAccuracy2Label.Position(3)+10;
                app.Ex2Button.Position(2)=app.ResultsPanel.Position(4)*(6/13);
                app.Ex2Button.FontSize=app.Ex2Button.Position(4)-app.Ex2Button.Position(4)*0.45;
                
                app.Edx2Button.Position(4)=app.ResultsPanel.Position(4)*(1/24);
                app.Edx2Button.Position(3)=app.Edx2Button.Position(4)*(2.1);
                app.Edx2Button.Position(1)=app.Ex2Button.Position(1)+app.Ex2Button.Position(3)+10;
                app.Edx2Button.Position(2)=app.ResultsPanel.Position(4)*(6/13);
                app.Edx2Button.FontSize=app.Edx2Button.Position(4)-app.Edx2Button.Position(4)*0.45;
                
                app.dx2Button.Position(4)=app.ResultsPanel.Position(4)*(1/24);
                app.dx2Button.Position(3)=app.dx2Button.Position(4)*(1.4);
                app.dx2Button.Position(1)=app.x2Button.Position(1)+app.x2Button.Position(3)+10;
                app.dx2Button.Position(2)=app.ResultsPanel.Position(4)*(8/13);
                app.dx2Button.FontSize=app.dx2Button.Position(4)-app.dx2Button.Position(4)*0.45;
                
                app.ThreeD2Button.Position(4)=app.ResultsPanel.Position(4)*(1/24);
                app.ThreeD2Button.Position(3)=app.ThreeD2Button.Position(4)*(1.5);
                app.ThreeD2Button.Position(1)=app.dx2Button.Position(1)+app.dx2Button.Position(3)+10;
                app.ThreeD2Button.Position(2)=app.ResultsPanel.Position(4)*(8/13);
                app.ThreeD2Button.FontSize=app.ThreeD2Button.Position(4)-app.ThreeD2Button.Position(4)*0.45;
                
                app.ForceSensor2Label.Position(4)=app.ResultsPanel.Position(4)*(1/24);
                app.ForceSensor2Label.Position(3)=app.ForceSensor2Label.Position(4)*(5.1);
                app.ForceSensor2Label.Position(1)=app.ResultsPanel.Position(3)*(1/10);
                app.ForceSensor2Label.Position(2)=app.ResultsPanel.Position(4)*(5/13);
                app.ForceSensor2Label.FontSize=app.ForceSensor2Label.Position(4)-app.ForceSensor2Label.Position(4)*0.45;
                
                app.FT2Button.Position(4)=app.ResultsPanel.Position(4)*(1/24);
                app.FT2Button.Position(3)=app.FT2Button.Position(4)*(1.5);
                app.FT2Button.Position(1)=app.ResultsPanel.Position(3)*(1/10)+20;
                app.FT2Button.Position(2)=app.ResultsPanel.Position(4)*(4/13);
                app.FT2Button.FontSize=app.FT2Button.Position(4)-app.FT2Button.Position(4)*0.45;
                
                app.DelsysSensors2Label.Position(4)=app.ResultsPanel.Position(4)*(1/24);
                app.DelsysSensors2Label.Position(3)=app.DelsysSensors2Label.Position(4)*(4.6);
                app.DelsysSensors2Label.Position(1)=app.ResultsPanel.Position(3)*(1/10);
                app.DelsysSensors2Label.Position(2)=app.ResultsPanel.Position(4)*(3/13);
                app.DelsysSensors2Label.FontSize=app.DelsysSensors2Label.Position(4)-app.DelsysSensors2Label.Position(4)*0.45;
                
                app.EMG2Button.Position(4)=app.ResultsPanel.Position(4)*(1/24);
                app.EMG2Button.Position(3)=app.EMG2Button.Position(4)*(2.2);
                app.EMG2Button.Position(1)=app.ResultsPanel.Position(3)*(1/10)+20;
                app.EMG2Button.Position(2)=app.ResultsPanel.Position(4)*(2/13);
                app.EMG2Button.FontSize=app.EMG2Button.Position(4)-app.EMG2Button.Position(4)*0.45;
                
                app.Accelerations2Button.Position(4)=app.ResultsPanel.Position(4)*(1/24);
                app.Accelerations2Button.Position(3)=app.Accelerations2Button.Position(4)*(4.2);
                app.Accelerations2Button.Position(1)=app.EMG2Button.Position(1)+app.EMG2Button.Position(3)+10;
                app.Accelerations2Button.Position(2)=app.ResultsPanel.Position(4)*(2/13);
                app.Accelerations2Button.FontSize=app.Accelerations2Button.Position(4)-app.Accelerations2Button.Position(4)*0.45;
                
                app.AngularVelocity2Button.Position(4)=app.ResultsPanel.Position(4)*(1/24);
                app.AngularVelocity2Button.Position(3)=app.AngularVelocity2Button.Position(4)*(5.3);
                app.AngularVelocity2Button.Position(1)=app.Accelerations2Button.Position(1)+app.Accelerations2Button.Position(3)+10;
                app.AngularVelocity2Button.Position(2)=app.ResultsPanel.Position(4)*(2/13);
                app.AngularVelocity2Button.FontSize=app.AngularVelocity2Button.Position(4)-app.AngularVelocity2Button.Position(4)*0.45;
                
                app.ddq2Button.Position(4)=app.ResultsPanel.Position(4)*(1/24);
                app.ddq2Button.Position(3)=app.ddq2Button.Position(4)*(1.7);
                app.ddq2Button.Position(1)=app.dq2Button.Position(1)+app.dq2Button.Position(3)+10;
                app.ddq2Button.Position(2)=app.ResultsPanel.Position(4)*(8/13);
                app.ddq2Button.FontSize=app.ddq2Button.Position(4)-app.ddq2Button.Position(4)*0.45;
                
                app.Eddq2Button.Position(4)=app.ResultsPanel.Position(4)*(1/24);
                app.Eddq2Button.Position(3)=app.Eddq2Button.Position(4)*(2.3);
                app.Eddq2Button.Position(1)=app.Edq2Button.Position(1)+app.Edq2Button.Position(3)+10;
                app.Eddq2Button.Position(2)=app.ResultsPanel.Position(4)*(6/13);
                app.Eddq2Button.FontSize=app.Eddq2Button.Position(4)-app.Eddq2Button.Position(4)*0.45;
                
                app.Epq2Button.Position(4)=app.ResultsPanel.Position(4)*(1/24);
                app.Epq2Button.Position(3)=app.Epq2Button.Position(4)*(2.1);
                app.Epq2Button.Position(1)=app.JointPrecision2Label.Position(1)+app.JointPrecision2Label.Position(3)+10;
                app.Epq2Button.Position(2)=app.ResultsPanel.Position(4)*(7/13);
                app.Epq2Button.FontSize=app.Epq2Button.Position(4)-app.Epq2Button.Position(4)*0.45;
                
                app.Epdq2Button.Position(4)=app.ResultsPanel.Position(4)*(1/24);
                app.Epdq2Button.Position(3)=app.Epdq2Button.Position(4)*(2.3);
                app.Epdq2Button.Position(1)=app.Epq2Button.Position(1)+app.Epq2Button.Position(3)+10;
                app.Epdq2Button.Position(2)=app.ResultsPanel.Position(4)*(7/13);
                app.Epdq2Button.FontSize=app.Epdq2Button.Position(4)-app.Epdq2Button.Position(4)*0.45;
                
                app.Epddq2Button.Position(4)=app.ResultsPanel.Position(4)*(1/24);
                app.Epddq2Button.Position(3)=app.Epddq2Button.Position(4)*(2.8);
                app.Epddq2Button.Position(1)=app.Epdq2Button.Position(1)+app.Epdq2Button.Position(3)+10;
                app.Epddq2Button.Position(2)=app.ResultsPanel.Position(4)*(7/13);
                app.Epddq2Button.FontSize=app.Epddq2Button.Position(4)-app.Epddq2Button.Position(4)*0.45;
                
                app.Epx2Button.Position(4)=app.ResultsPanel.Position(4)*(1/24);
                app.Epx2Button.Position(3)=app.Epx2Button.Position(4)*(2.1);
                app.Epx2Button.Position(1)=app.CartesianPrecision2Label.Position(1)+app.CartesianPrecision2Label.Position(3)+10;
                app.Epx2Button.Position(2)=app.ResultsPanel.Position(4)*(7/13);
                app.Epx2Button.FontSize=app.Epx2Button.Position(4)-app.Epx2Button.Position(4)*0.45;
                
                app.Epdx2Button.Position(4)=app.ResultsPanel.Position(4)*(1/24);
                app.Epdx2Button.Position(3)=app.Epdx2Button.Position(4)*(2.1);
                app.Epdx2Button.Position(1)=app.Epx2Button.Position(1)+app.Epx2Button.Position(3)+10;
                app.Epdx2Button.Position(2)=app.ResultsPanel.Position(4)*(7/13);
                app.Epdx2Button.FontSize=app.Epdx2Button.Position(4)-app.Epdx2Button.Position(4)*0.45;
                
                app.ResultsLabel.Position(4)=app.ResultsPanel.Position(4)*(1/14);
                app.ResultsLabel.Position(3)=app.ResultsLabel.Position(4)*(3.2);
                app.ResultsLabel.Position(1)=app.ResultsPanel.Position(3)*(1/2)-app.ResultsLabel.Position(3)*(1/2);
                app.ResultsLabel.Position(2)=app.ResultsPanel.Position(4)*(12/13);
                app.ResultsLabel.FontSize=app.ResultsLabel.Position(4)-app.ResultsLabel.Position(4)*0.45;
                
                app.BackToTrialMenuResultsButton.Position(4)=app.ResultsPanel.Position(4)*(1/24);
                app.BackToTrialMenuResultsButton.Position(3)=app.BackToTrialMenuResultsButton.Position(4)*(4.7);
                app.BackToTrialMenuResultsButton.Position(1)=app.ResultsPanel.Position(3)*(1/2)-app.BackToTrialMenuResultsButton.Position(3)*(1/2);
                app.BackToTrialMenuResultsButton.Position(2)=app.ResultsPanel.Position(4)*(1/13);
                app.BackToTrialMenuResultsButton.FontSize=app.BackToTrialMenuResultsButton.Position(4)-app.BackToTrialMenuResultsButton.Position(4)*0.45;
                
                app.RepetitionPlotSpinnerLabel.Position(4)=app.ResultsPanel.Position(4)*(1/24);
                app.RepetitionPlotSpinnerLabel.Position(3)=app.RepetitionPlotSpinnerLabel.Position(4)*(9.2);
                app.RepetitionPlotSpinnerLabel.Position(1)=app.ResultsPanel.Position(3)*(1/10);
                app.RepetitionPlotSpinnerLabel.Position(2)=app.ResultsPanel.Position(4)*(11/13);
                app.RepetitionPlotSpinnerLabel.FontSize=app.RepetitionPlotSpinnerLabel.Position(4)-app.RepetitionPlotSpinnerLabel.Position(4)*0.45;
                app.RepetitionPlotSpinner.Position(4)=app.RepetitionPlotSpinnerLabel.Position(4);
                app.RepetitionPlotSpinner.Position(3)=app.ResultsPanel.Position(3)*(1/10);
                app.RepetitionPlotSpinner.Position(1)=app.RepetitionPlotSpinnerLabel.Position(1)+app.RepetitionPlotSpinnerLabel.Position(3)+app.RepetitionPlotSpinnerLabel.Position(3)*(1/10);
                app.RepetitionPlotSpinner.Position(2)=app.RepetitionPlotSpinnerLabel.Position(2);
                app.RepetitionPlotSpinner.FontSize=app.RepetitionPlotSpinnerLabel.FontSize;
                
                app.RepetitionPlotLabel.Position(4)=app.ResultsPanel.Position(4)*(1/24);
                app.RepetitionPlotLabel.Position(3)=app.RepetitionPlotLabel.Position(4)*(13.7);
                app.RepetitionPlotLabel.Position(1)=app.RepetitionPlotSpinner.Position(1)+app.RepetitionPlotSpinner.Position(3)+app.RepetitionPlotSpinnerLabel.Position(3)*(1/10);
                app.RepetitionPlotLabel.Position(2)=app.ResultsPanel.Position(4)*(11/13);
                app.RepetitionPlotLabel.FontSize=app.RepetitionPlotLabel.Position(4)-app.RepetitionPlotLabel.Position(4)*0.45;
                
                drawnow;
                
             catch ME
                 uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');
             end
            
        end
        
        function getMonth(app,n)
            try
                switch (n)
                    case '00'
                        app.Month='Prueba';
                    case '01'
                        app.Month='Enero';
                    case '02'
                        app.Month='Febrero';
                    case '03'
                        app.Month='Marzo';
                    case '04'
                        app.Month='Abril';
                    case '05'
                        app.Month='Mayo';
                    case '06'
                        app.Month='Junio';
                    case '07'
                        app.Month='Julio';
                    case '08'
                        app.Month='Agosto';
                    case '09'
                        app.Month='Septiembre';
                    case '10'
                        app.Month='Octubre';
                    case '11'
                        app.Month='Noviembre';
                    case '12'
                        app.Month='Diciembre';
                    otherwise
                        app.Month='Error';
                end
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');
            end
            
        end
        
        function resetNewPatient(app)
            try
                app.SurnamesEditField.Value='';
                app.NameEditField.Value='';
                app.SpasticityLevelEditField.Value=0;
                app.AgeEditField.Value=0;
                app.HeightEditField.Value=0;
                app.WeightEditField.Value=0;
                app.LeftWristElbowEditField.Value=0;
                app.LeftElbowShoulderEditField.Value=0;
                app.LeftShoulderBreastboneEditField.Value=0;
                app.LeftWristHandleEditField.Value=0;
                app.RightWristElbowEditField.Value=0;
                app.RightElbowShoulderEditField.Value=0;
                app.RightShoulderBreastboneEditField.Value=0;
                app.RightWristHandleEditField.Value=0;
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');
            end
        end
        
        function EnableResultsButtons(app)
            try
                Trials=app.TrialsLoad;
                selectedNodes = app.MedicalHistoryTree.SelectedNodes;
                app.DisableResultsButtons();
                ChoosedArm=Trials(selectedNodes(1).NodeData).Conditions.Arm;
                ArmError=0;
                TrajectoryError=0;
                FTError=0;
                DelsysError=0;
                for j=1:size(selectedNodes,1)
                    if (Trials(selectedNodes(j).NodeData).Conditions.Arm~=ChoosedArm)
                        ArmError=1;
                    end
                    if (isempty(Trials(selectedNodes(j).NodeData).Trajectory))
                        TrajectoryError=1;
                    end
                    if (isempty(Trials(selectedNodes(j).NodeData).FTSensor))
                        FTError=1;
                    end
                    if (isempty(Trials(selectedNodes(j).NodeData).DelsysSensors))
                        DelsysError=1;
                    end
                end
                if ArmError==1
                    uialert(app.UIFigure,'Todas las pruebas seleccionadas deben haber sido realizadas con el mismo brazo','Error cargando los resultados');
                else
                    if (TrajectoryError==0)
                        app.qButton.Enable='on';
                        app.dqButton.Enable='on';
                        app.ddqButton.Enable='on';
                        app.EpqButton.Enable='on';
                        app.EpdqButton.Enable='on';
                        app.EpddqButton.Enable='on';
                        app.xButton.Enable='on';
                        app.dxButton.Enable='on';
                        app.ThreeDButton.Enable='on';
                        app.EpxButton.Enable='on';
                        app.EpdxButton.Enable='on';
                    end
                    if (FTError==0)
                        app.FTButton.Enable='on';
                    end
                    if (DelsysError==0)
                        app.EMGButton.Enable='on';
                        app.AngularVelocityButton.Enable='on';
                        app.AccelerationsButton.Enable='on';
                    end
                    if (TrajectoryError==0)&&(size(selectedNodes,1)>=2)
                        app.EqButton.Enable='on';
                        app.EdqButton.Enable='on';
                        app.EddqButton.Enable='on';
                        app.ExButton.Enable='on';
                        app.EdxButton.Enable='on';
                    end
                end
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end
        
        function DisableResultsButtons(app)
            try
                app.qButton.Enable='off';
                app.dqButton.Enable='off';
                app.ddqButton.Enable='off';
                app.EqButton.Enable='off';
                app.EdqButton.Enable='off';
                app.EddqButton.Enable='off';
                app.EpqButton.Enable='off';
                app.EpdqButton.Enable='off';
                app.EpddqButton.Enable='off';
                app.xButton.Enable='off';
                app.dxButton.Enable='off';
                app.ThreeDButton.Enable='off';
                app.ExButton.Enable='off';
                app.EdxButton.Enable='off';
                app.EpxButton.Enable='off';
                app.EpdxButton.Enable='off';
                app.FTButton.Enable='off';
                app.EMGButton.Enable='off';
                app.AngularVelocityButton.Enable='off';
                app.AccelerationsButton.Enable='off';
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error')
            end
        end
        
        function UpdateSummaryPanel(app)
            try
                if (app.IIWACheckBox.Value==1)
                    app.IIWASummaryLabel.Text=('IIWA: Conectado');
                else
                    app.IIWASummaryLabel.Text=('IIWA: Desconectado');    
                end
                if (app.FTSensorCheckBox.Value==1)
                    app.FTSensorSummaryLabel.Text=('Sensor Fuerza-Par: Conectado');
                else
                    app.FTSensorSummaryLabel.Text=('Sensor Fuerza-Par: Desconectado');    
                end
                if (app.DelsysSensorsCheckBox.Value==1)
                    app.DelsysSensorsSummaryLabel.Text=('Sensores Delsys: Conectados');
                else
                    app.DelsysSensorsSummaryLabel.Text=('Sensores Delsys: Desconectados');    
                end
                if (app.Arm=='R')
                    app.ArmSummaryLabel.Text=('Brazo Seleccionado: Derecho');
                else
                    app.ArmSummaryLabel.Text=('Brazo Seleccionado: Izquierdo');    
                end
                if (app.WorkingPlane=="XZ")
                    app.PlaneSummaryLabel.Text=('Posición del Paciente: Plano XZ');
                else
                    app.PlaneSummaryLabel.Text=('Posición del Paciente: Plano YZ');    
                end
                if (app.NameMovement=="flexext")
                    app.MovementSummaryLabel.Text=('Tipo de Movimiento Seleccionado: Flexión-Extensión Codo');
                    app.Sensor1SummaryLabel.Text=['Posición Sensor 1 (Biceps Brachii): ',app.Sensor1PosEditField.Value];
                    app.Sensor2SummaryLabel.Text=['Posición Sensor 2 (Triceps Lateral Head): ',app.Sensor2PosEditField.Value];
                    app.Sensor3SummaryLabel.Text=['Posición Sensor 3 (Brachio Radialis): ',app.Sensor3PosEditField.Value];
                    app.Sensor4SummaryLabel.Text=['Posición Sensor 4 (Flexor Carpi Radialis): ',app.Sensor4PosEditField.Value];
                else
                    app.MovementSummaryLabel.Text=('Tipo de Movimiento Seleccionado: Error');
                    app.Sensor1SummaryLabel.Text=('Posición Sensor 1: Error');
                    app.Sensor2SummaryLabel.Text=('Posición Sensor 2: Error');
                    app.Sensor3SummaryLabel.Text=('Posición Sensor 3: Error');
                    app.Sensor4SummaryLabel.Text=('Posición Sensor 4: Error');
                end
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error')
            end        
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            prg=uiprogressdlg(app.UIFigure, 'Message', 'Inicializando');
            try
                app.UIFigure.WindowState = 'maximized';
                prg.Value=0.5;
                
                drawnow;
                
                %Add folders to path
                addpath(genpath(fileparts(which(mfilename))));
                
                %Initialize IIWAControlObject
                app.IIWAControlObject = IIWAControl(0);
                
                %Initialize FTSensorObject
                app.FTSensorObject = FTSensor(0);
                
                %Initialize DelsysSensors
                app.DelsysSensorsObject = DelsysSensors(0);
                
                %Initialize dropdowns
                app.UpdateInputDropDown();
                app.UpdateAdjustedTrajectoryDropDown();
                app.UpdateMovementDropDown();
                
                %Initialize variables that need initialization
                app.VelocityLabel.Text = [num2str(app.DefaultVelocity), ' ', char(176), '/s'];
                app.VelocitySlider.Value = app.DefaultVelocity;
                app.RoboespasStartImage.ImageSource = [fileparts(which(mfilename)),'\Fotos\LogoRoboespas.png'];
                app.Uc3mMenuImage.ImageSource = [fileparts(which(mfilename)),'\Fotos\LogoUc3m.png'];
                app.RoboespasMenuImage.ImageSource = [fileparts(which(mfilename)),'\Fotos\LogoRoboespas.png'];
                app.Uc3mStartImage.ImageSource = [fileparts(which(mfilename)),'\Fotos\LogoUc3m.png'];
                app.Sensor1Image.ImageSource = [fileparts(which(mfilename)),'\Fotos\ElectrodePositioning1.png'];
                app.Sensor2Image.ImageSource = [fileparts(which(mfilename)),'\Fotos\ElectrodePositioning2.png'];
                app.Sensor3Image.ImageSource = [fileparts(which(mfilename)),'\Fotos\ElectrodePositioning3.png'];
                app.Sensor4Image.ImageSource = [fileparts(which(mfilename)),'\Fotos\ElectrodePositioning4.png'];
                app.CloseImage.ImageSource = [fileparts(which(mfilename)),'\Fotos\Close.png'];
                app.CloseImage.Tag='CloseImage';
                close(prg);
            catch ME
                close(prg);
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            try
                app.ResizeApp();
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error')
            end          
        end

        % Button pushed function: SavePatientButton
        function SavePatientButtonPushed(app, event)
            try
                namesidFile = matfile(app.NamesIDDatabasesFilePath, 'Writable',true);
                Person.Apellidos=app.SurnamesEditField.Value;
                Person.Nombre=app.NameEditField.Value;
                Person.ID=app.IDEditField.Value;
                app.IDPatient=app.IDEditField.Value;
                namesidFile.Person(app.IDEditField.Value,1) = Person; 
                patientsFile = matfile(app.PatientsDatabasesFilePath, 'Writable', true);
                Patient.ID=app.IDEditField.Value;
                Patient.Espasticidad=app.SpasticityLevelEditField.Value;
                Patient.Edad=app.AgeEditField.Value;
                Patient.Altura=app.HeightEditField.Value;
                Patient.Peso=app.WeightEditField.Value;
                Patient.Genero=app.GenderDropDown.Value;
                Patient.MedidasBrazoIzq.MunecaCodoIzq=app.LeftWristElbowEditField.Value;
                Patient.MedidasBrazoIzq.CodoHombroIzq=app.LeftElbowShoulderEditField.Value;
                Patient.MedidasBrazoIzq.HombroEsternonIzq=app.LeftShoulderBreastboneEditField.Value;
                Patient.MedidasBrazoIzq.MunecaAgarradorIzq=app.LeftWristHandleEditField.Value;
                Patient.MedidasBrazoDcha.MunecaCodoDcha=app.RightWristElbowEditField.Value;
                Patient.MedidasBrazoDcha.CodoHombroDcha=app.RightElbowShoulderEditField.Value;
                Patient.MedidasBrazoDcha.HombroEsternonDcha=app.RightShoulderBreastboneEditField.Value;
                Patient.MedidasBrazoDcha.MunecaAgarradorDcha=app.RightWristHandleEditField.Value;
                patientsFile.Patient(app.IDEditField.Value,1) = Patient;
                disp(['Salvado el paciente ', num2str(app.IDEditField.Value)]);
                uialert(app.UIFigure,'Paciente Guardado','','Icon','success');
                app.BackToStartPatientDataButton.Visible='on';
                app.BackToMenuPatientDataButton.Visible='off';
                app.PatientDataPanel.Visible='off';
                app.PatientMenuPanel.Visible='on';
                app.PatientMenuEditField.Value=app.IDPatient;
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end

        % Button pushed function: NewPatientButton
        function NewPatientButtonPushed(app, event)
            try
                app.resetNewPatient();
                patientsFile = matfile(app.PatientsDatabasesFilePath, 'Writable', true);
                if (~isempty(who(patientsFile)))
                    npatient=size(patientsFile.Patient,1);
                    app.IDEditField.Value=npatient+1;
                else
                    app.IDEditField.Value=1;
                end
                app.StartPanel.Visible='off';
                app.PatientDataPanel.Visible='on';
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end

        % Button pushed function: LoadPatientButton
        function LoadPatientButtonPushed(app, event)
            prg=uiprogressdlg(app.UIFigure, 'Message', 'Cargando lista de pacientes');
            try
                namesidFile = matfile(app.NamesIDDatabasesFilePath, 'Writable', true);
                if (~isempty(who(namesidFile)))
                    load(app.NamesIDDatabasesFilePath)
                    prg.Value=0.5;
                    if size(namesidFile.Person,1)==1
                        app.LoadPatientTable.Data = {Person(1).Apellidos,Person(1).Nombre,Person(1).ID}; %#ok<NODEF> 
    
                    else
                        Person=struct2table(Person); %#ok<NODEF> 
                        Person=sortrows(Person,1,'ascend');
                        app.LoadPatientTable.Data = Person;
                    end
                    close(prg);
                else
                    close(prg);
                    uialert(app.UIFigure,'No hay pacientes guardados','Error cargando pacientes');
                end
                s = uistyle('BackgroundColor','white');
                addStyle(app.LoadPatientTable,s);
                app.StartPanel.Visible='off';
                app.LoadPatientPanel.Visible='on';
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end

        % Cell selection callback: LoadPatientTable
        function LoadPatientTableCellSelection(app, event)
           try
               Indices=event.Indices;
               app.IDPatient=app.LoadPatientTable.Data{Indices(1),3};
               s = uistyle('BackgroundColor','white');
               addStyle(app.LoadPatientTable,s);
               s = uistyle('BackgroundColor',[0.6308 0.7991 0.9216]);
               addStyle(app.LoadPatientTable,s,'row',Indices(1));
           catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error')
           end
            
        end

        % Button pushed function: EditPatientButton
        function EditPatientButtonPushed(app, event)
            try
                load(app.PatientsDatabasesFilePath)
                load(app.NamesIDDatabasesFilePath)
                app.NameEditField.Value=Person(app.IDPatient).Nombre;
                app.SurnamesEditField.Value=Person(app.IDPatient).Apellidos;
                app.IDEditField.Value=Patient(app.IDPatient).ID;
                app.SpasticityLevelEditField.Value=Patient(app.IDPatient).Espasticidad;
                app.AgeEditField.Value=Patient(app.IDPatient).Edad;
                app.HeightEditField.Value=Patient(app.IDPatient).Altura;
                app.WeightEditField.Value=Patient(app.IDPatient).Peso;
                app.GenderDropDown.Value=Patient(app.IDPatient).Genero;
                app.LeftWristElbowEditField.Value=Patient(app.IDPatient).MedidasBrazoIzq.MunecaCodoIzq;
                app.LeftElbowShoulderEditField.Value=Patient(app.IDPatient).MedidasBrazoIzq.CodoHombroIzq;
                app.LeftShoulderBreastboneEditField.Value=Patient(app.IDPatient).MedidasBrazoIzq.HombroEsternonIzq;
                app.LeftWristHandleEditField.Value=Patient(app.IDPatient).MedidasBrazoIzq.MunecaAgarradorIzq;
                app.RightWristElbowEditField.Value=Patient(app.IDPatient).MedidasBrazoDcha.MunecaCodoDcha;
                app.RightElbowShoulderEditField.Value=Patient(app.IDPatient).MedidasBrazoDcha.CodoHombroDcha;
                app.RightShoulderBreastboneEditField.Value=Patient(app.IDPatient).MedidasBrazoDcha.HombroEsternonDcha;
                app.RightWristHandleEditField.Value=Patient(app.IDPatient).MedidasBrazoDcha.MunecaAgarradorDcha;
                app.BackToStartPatientDataButton.Visible='off';
                app.BackToMenuPatientDataButton.Visible='on';
                app.PatientMenuPanel.Visible='off';
                app.PatientDataPanel.Visible='on';
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end

        % Button pushed function: BackToStartPatientDataButton
        function BackToStartPatientDataButtonPushed(app, event)
            try
                app.PatientDataPanel.Visible='off';
                app.StartPanel.Visible='on';
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error')
            end
        end

        % Button pushed function: ChoosePatientButton
        function ChoosePatientButtonPushed(app, event)
            try
                app.PatientMenuEditField.Value=app.IDPatient;
                app.LoadPatientPanel.Visible='off';
                app.PatientMenuPanel.Visible='on';
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error')
            end
        end

        % Button pushed function: BackToStartLoadPatientButton
        function BackToStartLoadPatientButtonPushed(app, event)
            try
                app.LoadPatientPanel.Visible='off';
                app.StartPanel.Visible='on';
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error')
            end
        end

        % Button pushed function: BackToStartPatientMenuButton
        function BackToStartPatientMenuButtonPushed(app, event)
            try
                app.PatientMenuPanel.Visible='off';
                app.StartPanel.Visible='on';
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error')
            end
        end

        % Button pushed function: ShowMedicalHistoryButton
        function ShowMedicalHistoryButtonPushed(app, event)
            prg=uiprogressdlg(app.UIFigure, 'Message', 'Cargando historial médico del paciente');
            try    
                app.PersonalDataPanel.Visible='off';
                app.TrialDataPanel.Visible='off';
                app.ResultsButtonsPanel.Visible='off';
                delete(app.MedicalHistoryTree.Children)
                load(app.PatientsDatabasesFilePath)
                load(app.NamesIDDatabasesFilePath)
                prg.Value=0.25;
                trialsFile = matfile(app.TrialsDatabasesFilePath);
                app.TrialsLoad=trialsFile.Trials;
                prg.Value=0.75;
                NameNode = uitreenode(app.MedicalHistoryTree,'Text', [Person(app.IDPatient).Nombre ' ' Person(app.IDPatient).Apellidos],'NodeData',[]);
                uitreenode(NameNode,'Text','Datos Personales','NodeData',[]);
                TrialsNode = uitreenode(NameNode,'Text','Pruebas','NodeData',[]);
                n=[];
                for j=1:size(app.TrialsLoad,1)
                    if app.TrialsLoad(j).IDPatient==app.IDPatient
                        n(size(n,2)+1)=j; %#ok<AGROW> 
                    end
                end
                if size(n,2)~=0
                    dc=(app.TrialsLoad(n(1)).Conditions.DateCaptured(1:6));
                    for j=1:size(n,2)
                        updatedc=1;
                        for k=1:size(dc,1)
                            if (strcmp(app.TrialsLoad(n(j)).Conditions.DateCaptured(1:6),dc(k,:))==1)
                                updatedc=0;
                            end
                        end
                        if j~=1
                            dc(size(dc,1)+1,:)=app.TrialsLoad(n(j)).Conditions.DateCaptured(1:6);
                        end
                        if (j==1)||(updatedc==1)
                            app.getMonth(app.TrialsLoad(n(j)).Conditions.DateCaptured(3:4))
                            DateCaptured=['Trayectoria capturada el Día ' app.TrialsLoad(n(j)).Conditions.DateCaptured(5:6) ' de ' app.Month ' de 20' app.TrialsLoad(n(j)).Conditions.DateCaptured(1:2)];
                            CapturedTrayectoryNode=uitreenode(TrialsNode,'Text',DateCaptured,'NodeData',[]);
                            RightArmNode = uitreenode(CapturedTrayectoryNode,'Text','Brazo Derecho','NodeData',[]);
                            LeftArmNode = uitreenode(CapturedTrayectoryNode,'Text','Brazo Izquierdo','NodeData',[]);
                            del=[0]; %#ok<NBRAK> 
                            der=[0]; %#ok<NBRAK>
                            for l=1:size(n,2)
                                if (strcmp(dc(size(dc,1),:),app.TrialsLoad(n(l)).Conditions.DateCaptured(1:6))==1)
                                    if (app.TrialsLoad(n(l)).Conditions.Arm=="L")
                                        if del==0
                                            del=[app.TrialsLoad(n(l)).Conditions.DateExecuted(1:6)]; %#ok<NBRAK> 
                                            first=1;
                                        end
                                        updatedel=1;
                                        for m=1:size(del,1)
                                            if (strcmp(app.TrialsLoad(n(l)).Conditions.DateExecuted(1:6),del(m,:))==1)
                                                updatedel=0;
                                            end
                                        end
                                        if first==0
                                            del(size(del,1)+1,:)=app.TrialsLoad(n(l)).Conditions.DateExecuted(1:6);
                                        end
                                        if (first==1)||(updatedel==1)
                                            first=0;
                                            app.getMonth(app.TrialsLoad(n(l)).Conditions.DateExecuted(3:4))
                                            DateExecuted=['Trayectoria repetida el Día ' app.TrialsLoad(n(l)).Conditions.DateExecuted(5:6) ' de ' app.Month ' de 20' app.TrialsLoad(n(l)).Conditions.DateExecuted(1:2)];
                                            ExecutedTrayectoryNode = uitreenode(LeftArmNode,'Text',DateExecuted,'NodeData',[]);
                                            nTraj=1;
                                            for o=1:size(n,2)
                                                if (strcmp(app.TrialsLoad(n(o)).Conditions.DateExecuted(1:6),del(size(del,1),:))==1)&&(strcmp(app.TrialsLoad(n(o)).Conditions.DateCaptured(1:6),dc(size(dc,1),:))==1)&&(app.TrialsLoad(n(o)).Conditions.Arm=="L")
                                                    EndNode = uitreenode(ExecutedTrayectoryNode,'Text',['Prueba ' num2str(nTraj)] ,'NodeData',n(o)); %#ok<NASGU> 
                                                    nTraj=nTraj+1;
                                                end
                                            end
                                        end
                                   end
                                   if (app.TrialsLoad(n(l)).Conditions.Arm=="R") 
                                        if der==0
                                            der=[app.TrialsLoad(n(l)).Conditions.DateExecuted(1:6)]; %#ok<NBRAK> 
                                            first=1;
                                        end
                                        updateder=1;
                                        for m=1:size(der,1)
                                            if (strcmp(app.TrialsLoad(n(l)).Conditions.DateExecuted(1:6),der(size(der,1),:))==1)
                                                updateder=0;
                                            end
                                        end
                                        if first==0
                                            der(size(der,1)+1,:)=app.TrialsLoad(n(l)).Conditions.DateExecuted(1:6);
                                        end
                                        if (first==1)||(updateder==1)
                                            first=0;
                                            app.getMonth(app.TrialsLoad(n(l)).Conditions.DateExecuted(3:4))
                                            DateExecuted=['Trayectoria repetida el Día ' app.TrialsLoad(n(l)).Conditions.DateExecuted(5:6) ' de ' app.Month ' de 20' app.TrialsLoad(n(l)).Conditions.DateExecuted(1:2)];
                                            ExecutedTrayectoryNode = uitreenode(RightArmNode,'Text',DateExecuted,'NodeData',[]);
                                            nTraj=1;
                                            for o=1:size(n,2)
                                                if (strcmp(app.TrialsLoad(n(o)).Conditions.DateExecuted(1:6),der(size(der,1),:))==1)&&(strcmp(app.TrialsLoad(n(o)).Conditions.DateCaptured(1:6),dc(size(dc,1),:))==1)&&(app.TrialsLoad(n(o)).Conditions.Arm=="R")
                                                    EndNode = uitreenode(ExecutedTrayectoryNode,'Text',['Prueba ' num2str(nTraj)] ,'NodeData',n(o)); %#ok<NASGU> 
                                                    nTraj=nTraj+1;
                                                end
                                            end
                                        end  
                                    end
                                end
                            end
                        end    
                    end
                end
                close(prg);
                app.Return=1;
                app.PatientMenuPanel.Visible='off';
                app.MedicalHistoryPanel.Visible='on';
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end

        % Button pushed function: BackToMenuMedicalHistoryButton
        function BackToMenuMedicalHistoryButtonPushed(app, event)
            try
                app.MedicalHistoryPanel.Visible='off';
                app.PatientMenuPanel.Visible='on';
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error')
            end
        end

        % Button pushed function: qButton
        function qButtonPushed(app, event)
            try
                selectedNodes = app.MedicalHistoryTree.SelectedNodes;
                if size(selectedNodes,1)==1
                    arg=app.TrialsLoad(selectedNodes.NodeData).Trajectory;    
                else
                    for j=1:size(selectedNodes,1)
                        if (selectedNodes(j).Text(1:6)=="Prueba")&&(selectedNodes(j).Text(7)~="s")
                            arg{j,1}=app.TrialsLoad(selectedNodes(j).NodeData).Trajectory; %#ok<AGROW> 
                        end    
                    end    
                end
                app.MedicalHistoryPanel.Visible='off';
                app.PlotsPanel.Visible='on';
                drawnow;
                app.UIFigure.WindowState = 'maximized';
                app.IIWAControlObject.PlotJointPositionTrajectories('database', arg, app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end

        % Button pushed function: dqButton
        function dqButtonPushed(app, event)
            try
                selectedNodes = app.MedicalHistoryTree.SelectedNodes;
                if size(selectedNodes,1)==1
                    arg=app.TrialsLoad(selectedNodes.NodeData).Trajectory;    
                else
                    for j=1:size(selectedNodes,1)
                        if (selectedNodes(j).Text(1:6)=="Prueba")&&(selectedNodes(j).Text(7)~="s")
                            arg{j,1}=app.TrialsLoad(selectedNodes(j).NodeData).Trajectory; %#ok<AGROW> 
                        end    
                    end    
                end
                app.MedicalHistoryPanel.Visible='off';
                app.PlotsPanel.Visible='on';
                drawnow;
                app.UIFigure.WindowState = 'maximized';
                app.IIWAControlObject.PlotJointVelocityTrajectories('database', arg, app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end

        % Button pushed function: EqButton
        function EqButtonPushed(app, event)
            try
                selectedNodes = app.MedicalHistoryTree.SelectedNodes;
                if size(selectedNodes,1)==1
                    arg=app.Trials.Load(selectedNodes.NodeData).Trajectory;    
                else
                    for j=1:size(selectedNodes,1)
                        if (selectedNodes(j).Text(1:6)=="Prueba")&&(selectedNodes(j).Text(7)~="s")
                            arg{j,1}=app.TrialsLoad(selectedNodes(j).NodeData).Trajectory; %#ok<AGROW> 
                        end    
                    end    
                end
                app.MedicalHistoryPanel.Visible='off';
                app.PlotsPanel.Visible='on';
                drawnow;
                app.UIFigure.WindowState = 'maximized';
                app.IIWAControlObject.PlotJointPositionRepeatibilityError('database',app.PlotsPanel,arg);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end

        % Button pushed function: EdqButton
        function EdqButtonPushed(app, event)
            try
                selectedNodes = app.MedicalHistoryTree.SelectedNodes;
                if size(selectedNodes,1)==1
                    arg=app.TrialsLoad(selectedNodes.NodeData).Trajectory;    
                else
                    for j=1:size(selectedNodes,1)
                        if (selectedNodes(j).Text(1:6)=="Prueba")&&(selectedNodes(j).Text(7)~="s")
                            arg{j,1}=app.TrialsLoad(selectedNodes(j).NodeData).Trajectory; %#ok<AGROW> 
                        end    
                    end    
                end
                app.MedicalHistoryPanel.Visible='off';
                app.PlotsPanel.Visible='on';
                drawnow;
                app.UIFigure.WindowState = 'maximized';
                app.IIWAControlObject.PlotJointVelocityRepeatibilityError('database',app.PlotsPanel,arg);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end

        % Button pushed function: xButton
        function xButtonPushed(app, event)
            try
                selectedNodes = app.MedicalHistoryTree.SelectedNodes;
                if size(selectedNodes,1)==1
                    arg=app.TrialsLoad(selectedNodes.NodeData).Trajectory;    
                else
                    for j=1:size(selectedNodes,1)
                        if (selectedNodes(j).Text(1:6)=="Prueba")&&(selectedNodes(j).Text(7)~="s")
                            arg{j,1}=app.TrialsLoad(selectedNodes(j).NodeData).Trajectory; %#ok<AGROW> 
                        end    
                    end    
                end
                app.MedicalHistoryPanel.Visible='off';
                app.PlotsPanel.Visible='on';
                drawnow;
                app.UIFigure.WindowState = 'maximized';
                app.IIWAControlObject.PlotCartesianPositionTrajectories('database',arg, app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end

        % Button pushed function: ExButton
        function ExButtonPushed(app, event)
            try
                selectedNodes = app.MedicalHistoryTree.SelectedNodes;
                if size(selectedNodes,1)==1
                    arg=app.TrialsLoad(selectedNodes.NodeData).Trajectory;    
                else
                    for j=1:size(selectedNodes,1)
                        if (selectedNodes(j).Text(1:6)=="Prueba")&&(selectedNodes(j).Text(7)~="s")
                            arg{j,1}=app.TrialsLoad(selectedNodes(j).NodeData).Trajectory; %#ok<AGROW> 
                        end    
                    end    
                end
                app.MedicalHistoryPanel.Visible='off';
                app.PlotsPanel.Visible='on';
                drawnow;
                app.UIFigure.WindowState = 'maximized';
                app.IIWAControlObject.PlotCartesianPositionRepeatibilityError('database', app.PlotsPanel,arg);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end

        % Button pushed function: EdxButton
        function EdxButtonPushed(app, event)
            try
                selectedNodes = app.MedicalHistoryTree.SelectedNodes;
                if size(selectedNodes,1)==1
                    arg=app.TrialsLoad(selectedNodes.NodeData).Trajectory;    
                else
                    for j=1:size(selectedNodes,1)
                        if (selectedNodes(j).Text(1:6)=="Prueba")&&(selectedNodes(j).Text(7)~="s")
                            arg{j,1}=app.TrialsLoad(selectedNodes(j).NodeData).Trajectory; %#ok<AGROW> 
                        end    
                    end    
                end
                app.MedicalHistoryPanel.Visible='off';
                app.PlotsPanel.Visible='on';
                drawnow;
                app.UIFigure.WindowState = 'maximized';
                app.IIWAControlObject.PlotCartesianVelocityRepeatibilityError('database', app.PlotsPanel,arg);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end

        % Button pushed function: ThreeDButton
        function ThreeDButtonPushed(app, event)
            try
                selectedNodes = app.MedicalHistoryTree.SelectedNodes;
                if size(selectedNodes,1)==1
                    arg=app.TrialsLoad(selectedNodes.NodeData).Trajectory;    
                else
                    for j=1:size(selectedNodes,1)
                        if (selectedNodes(j).Text(1:6)=="Prueba")&&(selectedNodes(j).Text(7)~="s")
                            arg{j,1}=app.TrialsLoad(selectedNodes(j).NodeData).Trajectory; %#ok<AGROW> 
                        end    
                    end    
                end
                app.MedicalHistoryPanel.Visible='off';
                app.PlotsPanel.Visible='on';
                drawnow;
                app.UIFigure.WindowState = 'maximized';
                app.IIWAControlObject.Plot3DCartesianPositionTrajectories('database',arg, app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end

        % Button pushed function: dxButton
        function dxButtonPushed(app, event)
            try
                selectedNodes = app.MedicalHistoryTree.SelectedNodes;
                if size(selectedNodes,1)==1
                    arg=app.TrialsLoad(selectedNodes.NodeData).Trajectory;    
                else
                    for j=1:size(selectedNodes,1)
                        if (selectedNodes(j).Text(1:6)=="Prueba")&&(selectedNodes(j).Text(7)~="s")
                            arg{j,1}=app.TrialsLoad(selectedNodes(j).NodeData).Trajectory; %#ok<AGROW> 
                        end    
                    end    
                end
                app.MedicalHistoryPanel.Visible='off';
                app.PlotsPanel.Visible='on';
                drawnow;
                app.UIFigure.WindowState = 'maximized';
                app.IIWAControlObject.PlotCartesianVelocityTrajectories('database',arg, app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end

        % Button pushed function: FTButton
        function FTButtonPushed(app, event)
            try
                selectedNodes = app.MedicalHistoryTree.SelectedNodes;
                if size(selectedNodes,1)==1
                    arg=app.TrialsLoad(selectedNodes.NodeData).FTSensor.ForceTorqueTrial;    
                else
                    for j=1:size(selectedNodes,1)
                        if (selectedNodes(j).Text(1:6)=="Prueba")&&(selectedNodes(j).Text(7)~="s")
                            arg{j,1}=app.TrialsLoad(selectedNodes(j).NodeData).FTSensor.ForceTorqueTrial; %#ok<AGROW> 
                        end    
                    end    
                end
                app.MedicalHistoryPanel.Visible='off';
                app.PlotsPanel.Visible='on';
                drawnow;
                app.UIFigure.WindowState = 'maximized';
                app.FTSensorObject.PlotFT('database', arg, app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end

        % Button pushed function: EMGButton
        function EMGButtonPushed(app, event)
            try
                selectedNodes = app.MedicalHistoryTree.SelectedNodes;
                if size(selectedNodes,1)==1
                    arg=app.TrialsLoad(selectedNodes.NodeData).DelsysSensors.Trial;    
                else
                    for j=1:size(selectedNodes,1)
                        if (selectedNodes(j).Text(1:6)=="Prueba")&&(selectedNodes(j).Text(7)~="s")
                            arg{j,1}=app.TrialsLoad(selectedNodes(j).NodeData).DelsysSensors.Trial; %#ok<AGROW> 
                        end    
                    end    
                end
                app.MedicalHistoryPanel.Visible='off';
                app.PlotsPanel.Visible='on';
                drawnow;
                app.UIFigure.WindowState = 'maximized';
                app.DelsysSensorsObject.PlotEMG('database', arg, app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end

        % Button pushed function: AccelerationsButton
        function AccelerationsButtonPushed(app, event)
            try
                selectedNodes = app.MedicalHistoryTree.SelectedNodes;
                if size(selectedNodes,1)==1
                    arg=app.TrialsLoad(selectedNodes.NodeData).DelsysSensors.Trial;    
                else
                    for j=1:size(selectedNodes,1)
                        if (selectedNodes(j).Text(1:6)=="Prueba")&&(selectedNodes(j).Text(7)~="s")
                            arg{j,1}=app.TrialsLoad(selectedNodes(j).NodeData).DelsysSensors.Trial; %#ok<AGROW> 
                        end    
                    end    
                end
                app.MedicalHistoryPanel.Visible='off';
                app.PlotsPanel.Visible='on';
                drawnow;
                app.UIFigure.WindowState = 'maximized';
                app.DelsysSensorsObject.PlotACC('database', arg, app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end

        % Button pushed function: AngularVelocityButton
        function AngularVelocityButtonPushed(app, event)
            try
                selectedNodes = app.MedicalHistoryTree.SelectedNodes;
                if size(selectedNodes,1)==1
                    arg=app.TrialsLoad(selectedNodes.NodeData).DelsysSensors.Trial;    
                else
                    for j=1:size(selectedNodes,1)
                        if (selectedNodes(j).Text(1:6)=="Prueba")&&(selectedNodes(j).Text(7)~="s")
                            arg{j,1}=app.TrialsLoad(selectedNodes(j).NodeData).DelsysSensors.Trial; %#ok<AGROW> 
                        end    
                    end    
                end
                app.MedicalHistoryPanel.Visible='off';
                app.PlotsPanel.Visible='on';
                drawnow;
                app.UIFigure.WindowState = 'maximized';
                app.DelsysSensorsObject.PlotGYRO('database', arg, app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end

        % Button pushed function: ddqButton
        function ddqButtonPushed(app, event)
            try
                selectedNodes = app.MedicalHistoryTree.SelectedNodes;
                if size(selectedNodes,1)==1
                    arg=app.TrialsLoad(selectedNodes.NodeData).Trajectory;    
                else
                    for j=1:size(selectedNodes,1)
                        if (selectedNodes(j).Text(1:6)=="Prueba")&&(selectedNodes(j).Text(7)~="s")
                            arg{j,1}=app.TrialsLoad(selectedNodes(j).NodeData).Trajectory; %#ok<AGROW> 
                        end    
                    end    
                end
                app.MedicalHistoryPanel.Visible='off';
                app.PlotsPanel.Visible='on';
                drawnow;
                app.UIFigure.WindowState = 'maximized';
                app.IIWAControlObject.PlotJointAccelerationTrajectories('database', arg, app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end

        % Button pushed function: EddqButton
        function EddqButtonPushed(app, event)
            try
                selectedNodes = app.MedicalHistoryTree.SelectedNodes;
                if size(selectedNodes,1)==1
                    arg=app.TrialsLoad(selectedNodes.NodeData).Trajectory;    
                else
                    for j=1:size(selectedNodes,1)
                        if (selectedNodes(j).Text(1:6)=="Prueba")&&(selectedNodes(j).Text(7)~="s")
                            arg{j,1}=app.TrialsLoad(selectedNodes(j).NodeData).Trajectory; %#ok<AGROW> 
                        end    
                    end    
                end
                app.MedicalHistoryPanel.Visible='off';
                app.PlotsPanel.Visible='on';
                drawnow;
                app.UIFigure.WindowState = 'maximized';
                app.IIWAControlObject.PlotJointVelocityRepeatibilityError('database', app.PlotsPanel,arg);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end 
        end

        % Button pushed function: EpqButton
        function EpqButtonPushed(app, event)
            try
                selectedNodes = app.MedicalHistoryTree.SelectedNodes;
                if size(selectedNodes,1)==1
                    arg=app.TrialsLoad(selectedNodes.NodeData).Trajectory;    
                else
                    for j=1:size(selectedNodes,1)
                        if (selectedNodes(j).Text(1:6)=="Prueba")&&(selectedNodes(j).Text(7)~="s")
                            arg{j,1}=app.TrialsLoad(selectedNodes(j).NodeData).Trajectory; %#ok<AGROW> 
                        end    
                    end    
                end
                app.MedicalHistoryPanel.Visible='off';
                app.PlotsPanel.Visible='on';
                drawnow;
                app.UIFigure.WindowState = 'maximized';
                app.IIWAControlObject.PlotJointPositionError('database',arg, app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end

        % Button pushed function: EpdqButton
        function EpdqButtonPushed(app, event)
            try
                selectedNodes = app.MedicalHistoryTree.SelectedNodes;
                if size(selectedNodes,1)==1
                    arg=app.TrialsLoad(selectedNodes.NodeData).Trajectory;    
                else
                    for j=1:size(selectedNodes,1)
                        if (selectedNodes(j).Text(1:6)=="Prueba")&&(selectedNodes(j).Text(7)~="s")
                            arg{j,1}=app.TrialsLoad(selectedNodes(j).NodeData).Trajectory; %#ok<AGROW> 
                        end    
                    end    
                end
                app.MedicalHistoryPanel.Visible='off';
                app.PlotsPanel.Visible='on';
                drawnow;
                app.UIFigure.WindowState = 'maximized';
                app.IIWAControlObject.PlotJointVelocityError('database',arg, app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end

        % Button pushed function: EpddqButton
        function EpddqButtonPushed(app, event)
            try
                selectedNodes = app.MedicalHistoryTree.SelectedNodes;
                if size(selectedNodes,1)==1
                    arg=app.TrialsLoad(selectedNodes.NodeData).Trajectory;    
                else
                    for j=1:size(selectedNodes,1)
                        if (selectedNodes(j).Text(1:6)=="Prueba")&&(selectedNodes(j).Text(7)~="s")
                            arg{j,1}=app.TrialsLoad(selectedNodes(j).NodeData).Trajectory; %#ok<AGROW> 
                        end    
                    end    
                end
                app.MedicalHistoryPanel.Visible='off';
                app.PlotsPanel.Visible='on';
                drawnow;
                app.UIFigure.WindowState = 'maximized';
                app.IIWAControlObject.PlotJointAccelerationError('database', arg, app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end

        % Button pushed function: EpxButton
        function EpxButtonPushed(app, event)
            try
                selectedNodes = app.MedicalHistoryTree.SelectedNodes;
                if size(selectedNodes,1)==1
                    arg=app.TrialsLoad(selectedNodes.NodeData).Trajectory;    
                else
                    for j=1:size(selectedNodes,1)
                        if (selectedNodes(j).Text(1:6)=="Prueba")&&(selectedNodes(j).Text(7)~="s")
                            arg{j,1}=app.TrialsLoad(selectedNodes(j).NodeData).Trajectory; %#ok<AGROW> 
                        end    
                    end    
                end
                app.MedicalHistoryPanel.Visible='off';
                app.PlotsPanel.Visible='on';
                drawnow;
                app.UIFigure.WindowState = 'maximized';
                app.IIWAControlObject.PlotCartesianPositionError('database',arg, app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end

        % Button pushed function: EpdxButton
        function EpdxButtonPushed(app, event)
            try
                selectedNodes = app.MedicalHistoryTree.SelectedNodes;
                if size(selectedNodes,1)==1
                    arg=app.TrialsLoad(selectedNodes.NodeData).Trajectory;    
                else
                    for j=1:size(selectedNodes,1)
                        if (selectedNodes(j).Text(1:6)=="Prueba")&&(selectedNodes(j).Text(7)~="s")
                            arg{j,1}=app.TrialsLoad(selectedNodes(j).NodeData).Trajectory; %#ok<AGROW> 
                        end    
                    end    
                end
                app.MedicalHistoryPanel.Visible='off';
                app.PlotsPanel.Visible='on';
                drawnow;
                app.UIFigure.WindowState = 'maximized';
                app.IIWAControlObject.PlotCartesianVelocityError('database',arg, app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end

        % Image clicked function: CloseImage
        function CloseImageClicked(app, event)
            try    
                j = 1;
                for i = 1:max(size(app.PlotsPanel.Children))
                    if(~strcmp(app.PlotsPanel.Children(j).Tag, 'CloseImage'))
                        delete(app.PlotsPanel.Children(j));
                    else
                        j = j+1;
                    end
                end
                if (app.Return==1)
                    app.PlotsPanel.Visible='off';
                    app.MedicalHistoryPanel.Visible='on';
                else
                    app.PlotsPanel.Visible='off';
                    app.ResultsPanel.Visible='on';       
                end
            catch ME
                uialert(app.UIFigure, [ME.identifier, ':', ME.message] , 'Error');    
            end
        end

        % Selection changed function: MedicalHistoryTree
        function MedicalHistoryTreeSelectionChanged(app, event)
            try
                selectedNodes = app.MedicalHistoryTree.SelectedNodes;
                if size(selectedNodes,1)==1
                    if selectedNodes.Text=="Datos Personales"
                        app.PersonalDataPanel.Visible='off';
                        app.ResultsButtonsPanel.Visible='off';
                        app.TrialDataPanel.Visible='off';
                        app.IDEditField2.Value=app.IDPatient;
                        load(app.PatientsDatabasesFilePath)
                        load(app.NamesIDDatabasesFilePath)
                        app.NameEditField2.Value=Person(app.IDPatient).Nombre;
                        app.SurnamesEditField2.Value=Person(app.IDPatient).Apellidos;
                        app.IDEditField2.Value=Patient(app.IDPatient).ID;
                        app.SpasticityLevelEditField2.Value=Patient(app.IDPatient).Espasticidad;
                        app.AgeEditField2.Value=Patient(app.IDPatient).Edad;
                        app.HeightEditField2.Value=Patient(app.IDPatient).Altura;
                        app.WeightEditField2.Value=Patient(app.IDPatient).Peso;
                        app.GenderEditField2.Value=Patient(app.IDPatient).Genero;
                        app.LeftWristElbowEditField2.Value=Patient(app.IDPatient).MedidasBrazoIzq.MunecaCodoIzq;
                        app.LeftElbowShoulderEditField2.Value=Patient(app.IDPatient).MedidasBrazoIzq.CodoHombroIzq;
                        app.LeftShoulderBreastboneEditField2.Value=Patient(app.IDPatient).MedidasBrazoIzq.HombroEsternonIzq;
                        app.LeftWristHandleEditField2.Value=Patient(app.IDPatient).MedidasBrazoIzq.MunecaAgarradorIzq;
                        app.RightWristElbowEditField2.Value=Patient(app.IDPatient).MedidasBrazoDcha.MunecaCodoDcha;
                        app.RightElbowShoulderEditField2.Value=Patient(app.IDPatient).MedidasBrazoDcha.CodoHombroDcha;
                        app.RightShoulderBreastboneEditField2.Value=Patient(app.IDPatient).MedidasBrazoDcha.HombroEsternonDcha;
                        app.RightWristHandleEditField2.Value=Patient(app.IDPatient).MedidasBrazoDcha.MunecaAgarradorDcha;
                        app.PersonalDataPanel.Visible='on';
                    elseif (selectedNodes.Text(1:6)=="Prueba")&&(selectedNodes.Text(7)~="s")
                        app.PersonalDataPanel.Visible='off';
                        load(app.NamesIDDatabasesFilePath)
                        app.TrialDataPatientEditField.Value=['Paciente: ' Person(app.TrialsLoad(selectedNodes.NodeData).IDPatient).Nombre ' ' Person(app.TrialsLoad(selectedNodes.NodeData).IDPatient).Apellidos];
                        if app.TrialsLoad(selectedNodes.NodeData).Conditions.Arm=='L'
                            app.TrialDataChoosedArmEditField.Value='Brazo Utilizado: Brazo Izquierdo';    
                        else
                            app.TrialDataChoosedArmEditField.Value='Brazo Utilizado: Brazo Derecho';
                        end
                        if app.TrialsLoad(selectedNodes.NodeData).Conditions.WorkingPlane=="XZ"
                            app.TrialDataChoosedWorkingPlaneEditField.Value='Posición del Paciente: Plano XZ';    
                        else
                            app.TrialDataChoosedWorkingPlaneEditField.Value='Posición del Paciente: Plano YZ';
                        end
                        app.getMonth(app.TrialsLoad(selectedNodes.NodeData).Conditions.DateExecuted(3:4))
                        DateExecuted=['Trayectoria repetida el Día ' app.TrialsLoad(selectedNodes.NodeData).Conditions.DateExecuted(5:6) ' de ' app.Month ' de 20' app.TrialsLoad(selectedNodes.NodeData).Conditions.DateExecuted(1:2) ' a las ' app.TrialsLoad(selectedNodes.NodeData).Conditions.DateExecuted(8:9) ':' app.TrialsLoad(selectedNodes.NodeData).Conditions.DateExecuted(10:11) ':' app.TrialsLoad(selectedNodes.NodeData).Conditions.DateExecuted(12:13) ''];
                        app.TrialDataTrajectoryDateExecutedEditField.Value=DateExecuted;
                        app.getMonth(app.TrialsLoad(selectedNodes.NodeData).Conditions.DateCaptured(3:4))
                        DateCaptured=['Trayectoria capturada el Día ' app.TrialsLoad(selectedNodes.NodeData).Conditions.DateCaptured(5:6) ' de ' app.Month ' de 20' app.TrialsLoad(selectedNodes.NodeData).Conditions.DateCaptured(1:2)];
                        app.TrialDataTrajectoryDateCapturedEditField.Value=DateCaptured;
                        app.EnableResultsButtons();
                        app.TrialDataPanel.Visible='on';
                        app.ResultsButtonsPanel.Visible='on';
                    end
                else
                    app.EnableResultsButtons();
                    app.ResultsButtonsPanel.Visible='on';    
                end
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error')
            end
            
        end

        % Button pushed function: BackToMenuPatientDataButton
        function BackToMenuPatientDataButtonPushed(app, event)
            try
                app.PatientDataPanel.Visible='off';
                app.PatientMenuPanel.Visible='on';
                app.BackToStartPatientDataButton.Visible='on';
                app.BackToMenuPatientDataButton.Visible='off';
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error')
            end
        end

        % Button pushed function: GoToSensorsPanelButton
        function GoToSensorsPanelButtonPushed(app, event)
            try
                app.SessionInfoPanel.Visible='off';
                app.SensorsPanel.Visible='on';
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error')
            end
        end

        % Button pushed function: BackToMenuSessionInfoButton
        function BackToMenuSessionInfoButtonPushed(app, event)
            try
                app.SessionInfoPanel.Visible='off';
                app.PatientMenuPanel.Visible='on';
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error')
            end
        end

        % Button pushed function: GoToTrialMenuButton
        function GoToTrialMenuButtonPushed(app, event)
            try
                if (strcmp(app.MovementDropDown.Value, app.MovementSelectStr))
                    uialert(app.UIFigure, 'Seleccione un movimiento primero', 'Error');
                else
                    if (app.ArmSwitch.Value=="Brazo Izquierdo")
                        app.Arm='L';
                    else
                        app.Arm='R';    
                    end
                    if (app.PlaneSwitch.Value=="Plano YZ")
                        app.WorkingPlane='YZ';
                    else
                        app.WorkingPlane='XZ';    
                    end
                    app.NameMovement=app.MovementMap(app.MovementDropDown.Value);
                    app.IIWAControlObject.SetMovement(app.NameMovement);
                    app.DelsysSensorsObject.SetMovement(app.NameMovement);
                    app.UpdateSummaryPanel();
                    app.SessionInfoPanel.Visible='off';
                    app.TrialMenuPanel.Visible='on';    
                end
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error')
            end
        end

        % Button pushed function: StartTrialButton
        function StartTrialButtonPushed(app, event)
            try
                app.Return=2;
                app.PatientMenuPanel.Visible='off';
                app.SessionInfoPanel.Visible='on';
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error')
            end
        end

        % Button pushed function: BackToSessionInfoSensorsButton
        function BackToSessionInfoSensorsButtonPushed(app, event)
            try
                app.SaveSensorsPosition();
                app.SensorsPanel.Visible='off';
                app.SessionInfoPanel.Visible='on';
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error')
            end
        end

        % Button pushed function: GoToCaptureButton
        function GoToCaptureButtonPushed(app, event)
            try
                app.InputSmoothingEditField.Value = app.IIWAControlObject.InputSmoothing;
                app.CommandedSmoothingEditField.Value = app.IIWAControlObject.CommandedSmoothing;
                app.OutputSmoothingEditField.Value = app.IIWAControlObject.OutputSmoothing;
                app.GoToCapture2Button.Enable='off';
                if (app.Arm=='R')
                    app.LeftReferencePosButton.Enable='off';
                    app.RightReferencePosButton.Enable='on';
                else
                    app.LeftReferencePosButton.Enable='on';
                    app.RightReferencePosButton.Enable='off';    
                end
                app.TrialMenuPanel.Visible='off';
                app.CaptureTrajectory1Panel.Visible='on';
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error')
            end
        end

        % Button pushed function: GoToRepeatButton
        function GoToRepeatButtonPushed(app, event)
            prg=uiprogressdlg(app.UIFigure, 'Message', 'Cargando lista de trayectorias ajustadas');
            try
                app.UpdateAdjustedTrajectoryDropDown();
                %app.AdjustedTrajectoryDropDown.Value=app.IIWAControlObject.NameDataCommanded;
                app.VelocitySlider.Value = app.DefaultVelocity;
                app.VelocityLabel.Text = [num2str(app.VelocitySlider.Value), ' ', char(176), '/s'];
                app.IIWAControlObject.ChangeVelocity(round(app.DefaultVelocity/app.IIWAControlObject.InputAngularVelocity, 2));
                close(prg);
                app.AdjustedTrajectoryDropDown.Enable='on';
                app.VelocitySlider.Enable='on';
                app.InitialPositionEmptyButton.Enable='on';
                app.RelaxMusclesEmptyButton.Enable='off';
                app.RunTrajectoryEmptyButton.Enable='off';
                app.InitialPositionRunButton.Enable='off';
                app.RelaxMusclesRunButton.Enable='off';
                app.RunTrajectoryButton.Enable='off';
                app.RepetitionsSpinner.Enable='off';
                app.GoToResultsButton.Enable='off';
                app.BackToTrialMenuRepeatButton.Enable='on';
                app.TrialMenuPanel.Visible='off';
                app.RepeatTrajectoryPanel.Visible='on';
            catch ME
                close(prg);
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error')
            end
        end

        % Button pushed function: BackToSessionInfoTrialMenuButton
        function BackToSessionInfoTrialMenuButtonPushed(app, event)
            try
                app.TrialMenuPanel.Visible='off';
                app.SessionInfoPanel.Visible='on';
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error')
            end
        end

        % Button pushed function: BackToMenuTrialMenuButton
        function BackToMenuTrialMenuButtonPushed(app, event)
            try
                app.TrialMenuPanel.Visible='off';
                app.PatientMenuPanel.Visible='on';
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error')
            end
        end

        % Button pushed function: GoToCapture2Button
        function GoToCapture2ButtonPushed(app, event)
            prg=uiprogressdlg(app.UIFigure, 'Message', 'Cargando lista de trayectorias de entrada');
            try
                UpdateInputDropDown(app);
                close(prg);
                app.IIWAControlObject.SetSmoothingParameters(app.InputSmoothingEditField.Value, app.CommandedSmoothingEditField.Value, app.OutputSmoothingEditField.Value);
                app.FreeBlockRobotButton.Enable='off';
                app.EndCaptureButton.Enable='off';
                app.RepeatCaptureButton.Enable='off';
                app.InputDropDown.Enable='off';
                app.SaveTrajectoryButton.Enable='off';
                app.FreeXButton.Enable='on';
                app.FreeYButton.Enable='on';
                app.FreeZButton.Enable='on';
                app.StartCaptureButton.Enable='on';
                app.CaptureTrajectory1Panel.Visible='off';
                app.CaptureTrajectory2Panel.Visible='on';
            catch ME
                close(prg);
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error')
            end
        end

        % Button pushed function: SaveTrajectoryButton
        function SaveTrajectoryButtonPushed(app, event)
            if (~strcmp(app.InputDropDown.Value, app.TrajectorySelectStr))
                prg=uiprogressdlg(app.UIFigure, 'Message', 'Ajustando trayectoria');
                try
%                   [app.IDPatient, app.Arm, app.WorkingPlane]=app.IIWAControlObject.BuildDataAdjusted(app.InputDropDown.Value, app.PlotAjusteCheckBox.Value, app.VelocitySlider.Value);
                    [app.IDPatient, app.Arm]=app.IIWAControlObject.BuildDataAdjusted(app.InputDropDown.Value, app.PlotAdjustmentCheckBox.Value, app.VelocitySlider.Value);
                    app.IIWAControlObject.ChangeVelocity(round(app.DefaultVelocity/app.IIWAControlObject.InputAngularVelocity, 2));
                    app.ShowCheckedPlots();
                    close(prg);
                    uialert(app.UIFigure, ['Trayectoria ajustada guardada en carpeta "entrada" de ', app.IIWAControlObject.NameDataCommanded], '', 'Icon', 'success');
                    app.CaptureTrajectory2Panel.Visible='off';
                    app.TrialMenuPanel.Visible='on';
                catch ME
                    close(prg);
                    uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
                end
            else
                uialert(app.UIFigure, 'Seleccione una trayectoria primero', 'Error');
            end
        end

        % Button pushed function: RepeatCaptureButton
        function RepeatCaptureButtonPushed(app, event)
            try
                app.GoToCapture2Button.Enable='off';
                app.CaptureTrajectory2Panel.Visible='off';
                app.CaptureTrajectory1Panel.Visible='on';
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error')
            end
        end

        % Button pushed function: GoToResultsButton
        function GoToResultsButtonPushed(app, event)
            try
                app.EnablePlotButtons('iiwa', app.IIWACheckBox.Value);
                app.EnablePlotButtons('ft', app.FTSensorCheckBox.Value);
                app.EnablePlotButtons('delsys', app.DelsysSensorsCheckBox.Value);
                if (app.RepetitionPlotSpinner.Value == 0) && (app.IIWACheckBox.Value == 1)
                    app.Eq2Button.Enable = 'on';
                    app.Edq2Button.Enable = 'on';
                    app.Eddq2Button.Enable = 'on';
                    app.Ex2Button.Enable = 'on';
                    app.Edx2Button.Enable = 'on';
                else
                    app.Eq2Button.Enable = 'off';
                    app.Edq2Button.Enable = 'off';
                    app.Eddq2Button.Enable = 'off';
                    app.Ex2Button.Enable = 'off';
                    app.Edx2Button.Enable = 'off';
                end
                app.RepeatTrajectoryPanel.Visible='off';
                app.ResultsPanel.Visible='on';
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error')
            end
        end

        % Button pushed function: BackToTrialMenuRepeatButton
        function BackToTrialMenuRepeatButtonPushed(app, event)
            try
                app.RepeatTrajectoryPanel.Visible='off';
                app.TrialMenuPanel.Visible='on';
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error')
            end
        end

        % Button pushed function: BackToTrialMenuResultsButton
        function BackToTrialMenuResultsButtonPushed(app, event)
            try
                app.ResultsPanel.Visible='off';
                app.TrialMenuPanel.Visible='on';
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error')
            end
        end

        % Value changed function: VelocitySlider
        function VelocitySliderValueChanged(app, event)
            try
                app.SetCurrentSliderVelocity();
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error')
            end
        end

        % Callback function
        function RelaxMusclesEmptyButtonPushed(app, event)
            try
                value = app.RelaxMusclesEmptyButton.Value;
                if(app.DelsysCheckBox.Value)
                    app.EMGrealTimePlot(value);
                else
                    app.RelaxMusclesEmptyButton.Value = 0;
                end
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error')
            end
        end

        % Callback function
        function RelaxMusclesRunButtonPushed(app, event)
            try
                value = app.RelaxMusclesRunButton.Value;
                if(app.DelsysCheckBox.Value)
                    app.EMGrealTimePlot(value);
                else
                    app.RelaxMusclesRunButton.Value = 0;
                end
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error')
            end
        end

        % Button pushed function: LeftReferencePosButton
        function LeftReferencePosButtonPushed(app, event)
            prg = uiprogressdlg(app.UIFigure, 'Message', 'Moviendose a posición de referencia izquierda');
            try
%               app.IIWAControlObject.SendReferencePositionLeft(app.WorkingPlane);
                app.IIWAControlObject.SendReferencePositionLeft();
                close(prg);
                app.GoToCapture2Button.Enable='on';
                uialert(app.UIFigure, 'Robot en posición de referencia izquierda', '', 'Icon', 'success');
            catch ME
                close(prg);
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: RightReferencePosButton
        function RightReferencePosButtonPushed(app, event)
            prg = uiprogressdlg(app.UIFigure, 'Message', 'Moviendose a posición de referencia derecha');
            try
%               app.IIWAControlObject.SendReferencePositionRight(app.WorkingPlane);
                app.IIWAControlObject.SendReferencePositionRight();
                close(prg);
                app.GoToCapture2Button.Enable='on';
                uialert(app.UIFigure, 'Robot en posición de referencia derecha', '', 'Icon', 'success');
            catch ME
                close(prg);
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: StartCaptureButton
        function StartCaptureButtonPushed(app, event)
            prg=uiprogressdlg(app.UIFigure, 'Message', 'Iniciando captura');
            try
                app.IIWAControlObject.CaptureStart( );
                app.StartCaptureButton.Enable = 'off';
                app.EndCaptureButton.Enable = 'on';
                app.FreeXButton.Enable = 'off';
                app.FreeYButton.Enable = 'off';
                app.FreeZButton.Enable = 'off';
                app.FreeBlockRobotButton.Enable = 'off';
                close(prg);
            catch ME
                close(prg);
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: EndCaptureButton
        function EndCaptureButtonPushed(app, event)
            prg=uiprogressdlg(app.UIFigure, 'Message', 'Finalizando captura');
            try
%               app.IIWAControlObject.SaveDataInput(app.IDPatient, app.Arm, app.WorkingPlane);
                app.IIWAControlObject.SaveDataInput(app.IDPatient, app.Arm);
                app.IIWAControlObject.BlockRobot();
                app.UpdateInputDropDown();
                app.InputDropDown.Value=app.IIWAControlObject.NameDataInput;
                app.EndCaptureButton.Enable='off';
                app.InputDropDown.Enable='on';
                app.SaveTrajectoryButton.Enable='on';
                app.RepeatCaptureButton.Enable='on';
                close(prg);
                uialert(app.UIFigure, 'Robot bloqueado', '', 'Icon', 'success');
                uialert(app.UIFigure, ['Trayectoria grabada guardada en carpeta ', app.IIWAControlObject.NameDataInput], '', 'Icon', "success");        
            catch ME
                close(prg);
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: FreeBlockRobotButton
        function FreeBlockRobotButtonPushed(app, event)
            prg=uiprogressdlg(app.UIFigure, 'Message', 'Bloqueando robot');
            try
                app.IIWAControlObject.BlockRobot();
                app.FreeBlockRobotButton.Enable='off';
                app.FreeXButton.Enable='on';
                app.FreeYButton.Enable='on';
                app.FreeZButton.Enable='on';
                app.StartCaptureButton.Enable='on';
                close(prg);
                uialert(app.UIFigure, 'Robot bloqueado', '', 'Icon', 'success');
            catch ME
                close(prg);
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: FreeXButton
        function FreeXButtonPushed(app, event)
            prg=uiprogressdlg(app.UIFigure, 'Message', 'Liberando robot');
            try
                app.IIWAControlObject.FreeCartesianCoordinate('X');
                app.FreeXButton.Enable='off';
                app.FreeYButton.Enable='off';
                app.FreeZButton.Enable='off';
                app.FreeBlockRobotButton.Enable='on';
                app.StartCaptureButton.Enable='off';
                close(prg);
            catch ME
                close(prg);
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: FreeYButton
        function FreeYButtonPushed(app, event)
           prg=uiprogressdlg(app.UIFigure, 'Message', 'Liberando robot');
            try
                app.IIWAControlObject.FreeCartesianCoordinate('Y');
                app.FreeXButton.Enable='off';
                app.FreeYButton.Enable='off';
                app.FreeZButton.Enable='off';
                app.FreeBlockRobotButton.Enable='on';
                app.StartCaptureButton.Enable='off';
                close(prg);
            catch ME
                close(prg);
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: FreeZButton
        function FreeZButtonPushed(app, event)
            prg=uiprogressdlg(app.UIFigure, 'Message', 'Liberando robot');
            try
                app.IIWAControlObject.FreeCartesianCoordinate('Z');
                app.FreeXButton.Enable='off';
                app.FreeYButton.Enable='off';
                app.FreeZButton.Enable='off';
                app.FreeBlockRobotButton.Enable='on';
                app.StartCaptureButton.Enable='off';
                close(prg);
            catch ME
                close(prg);
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Value changed function: InputDropDown
        function InputDropDownValueChanged(app, event)
            if (~strcmp(app.InputDropDown.Value, app.TrajectorySelectStr))
                prg=uiprogressdlg(app.UIFigure, 'Message', 'Cargando trayectoria de entrada');
                try
%                   [app.IDPatient, app.Arm, app.WorkingPlane] = app.IIWAControlObject.LoadInput(app.InputDropDown.Value);
                    [app.IDPatient, app.Arm] = app.IIWAControlObject.LoadInput(app.InputDropDown.Value);
                    app.SaveTrajectoryButton.Enable='on';
                    close(prg);
                    uialert(app.UIFigure, ['Cargada trayectoria capturada ', app.InputDropDown.Value], '', 'Icon', 'success'); 
                catch ME
                    close(prg);
                    uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
                end
            else
                app.SaveTrajectoryButton.Enable='off';
            end    
        end

        % Value changed function: AdjustedTrajectoryDropDown
        function AdjustedTrajectoryDropDownValueChanged(app, event)
            if (~strcmp(app.AdjustedTrajectoryDropDown.Value, app.TrajectorySelectStr))
                prg=uiprogressdlg(app.UIFigure, 'Message', 'Cargando trayectoria ajustada');
                try
%                   [app.IDPatient, app.Arm, app.WorkingPlane] = app.IIWAControlObject.LoadCommanded(app.AdjustedTrajectoryDropDown.Value);
                    [app.IDPatient, app.Arm] = app.IIWAControlObject.LoadCommanded(app.AdjustedTrajectoryDropDown.Value);
                    if (~strcmp(app.InputDropDown.Value,app.IIWAControlObject.NameDataInput))
                        prg=uiprogressdlg(app.UIFigure, 'Message', 'Cargando trayectoria de entrada');
                        app.InputDropDown.Value=app.IIWAControlObject.NameDataInput;
%                       [app.IDPatient, app.Arm, app.WorkingPlane]=app.IIWAControlObject.LoadInput(app.InputDropDown.Value);
                        [app.IDPatient, app.Arm]=app.IIWAControlObject.LoadInput(app.InputDropDown.Value);
                        close(prg);
                    end
                    app.SetCurrentSliderVelocity();
                    app.VelocitySlider.Enable='on';
                    app.VelocityLabel.Enable='on';
                    close(prg);
                catch ME
                    close(prg);
                    uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
                end
            end
        end

        % Button pushed function: InitialPositionEmptyButton
        function InitialPositionEmptyButtonPushed(app, event)
            if (strcmp(app.AdjustedTrajectoryDropDown.Value, app.TrajectorySelectStr))
                uialert(app.UIFigure, 'Seleccione una trayectoria primero', 'Error');                
            else
                prg=uiprogressdlg(app.UIFigure, 'Message', 'Moviendo robot a la posicion inicial de la trayectoria');
                try
                    app.IIWAControlObject.SendInitialPosition();
                    app.RunTrajectoryEmptyButton.Enable='on';
                    app.RelaxMusclesEmptyButton.Enable = 'on';
                    app.InitialPositionEmptyButton.Enable='off';
                    app.AdjustedTrajectoryDropDown.Enable='off';
                    app.VelocitySlider.Enable='off';
                    close(prg);
                    uialert(app.UIFigure, 'Robot en posición inicial', '', 'Icon', 'success');
                catch ME
                    close(prg);
                    uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
                end
            end
            
        end

        % Button pushed function: InitialPositionRunButton
        function InitialPositionRunButtonPushed(app, event)
            prg=uiprogressdlg(app.UIFigure, 'Message', 'Moviendo robot a la posicion inicial de la trayectoria');
            try
                app.IIWAControlObject.SendInitialPosition();
                app.RunTrajectoryButton.Enable='on';
                app.RelaxMusclesRunButton.Enable = 'on';
                app.RepetitionsSpinner.Enable='on';
                app.InitialPositionRunButton.Enable='off';
                close(prg);
                uialert(app.UIFigure, 'Robot en posición inicial', '', 'Icon', 'success');
            catch ME
                close(prg);
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: RunTrajectoryEmptyButton
        function RunTrajectoryEmptyButtonPushed(app, event)
            if(app.RelaxMusclesEmptyButton.Value == 1)
                app.RelaxMusclesEmptyButton.Value = 0;
                app.EMGrealTimePlot(app.RelaxMusclesEmptyButton.Value);
            end
            prg=uiprogressdlg(app.UIFigure, 'Message', 'Ejecutando trayectoria en vacio. El paciente debe relajar el brazo, y el robot no debe tocarse mientras se mueve');
            try
                %Inicializar
                app.DelsysSensorsObject.StartSensors();
                app.FTSensorObject.StartSensor();
                
                %Iniciar captura de datos
                prg.Message = 'Ejecutando trayectoria en vacio. El paciente debe relajar el brazo, y el robot no debe tocarse mientras se mueve. Iniciando captura sensor FT.';
                app.FTSensorObject.StartReference();
                prg.Message = 'Ejecutando trayectoria en vacio. El paciente debe relajar el brazo, y el robot no debe tocarse mientras se mueve. Iniciando captura sensores Delsys.';
                app.DelsysSensorsObject.StartCapture();
                prg.Message = 'Ejecutando trayectoria en vacio. El paciente debe relajar el brazo, y el robot no debe tocarse mientras se mueve. Ejecutando trayectoria!';
                prg.Value=0.33;
                %Ejecutar
%                 [app.IDPatient, app.Arm, app.WorkingPlane] = app.IIWAControlObject.SendTrajectory();
                [app.IDPatient, app.Arm] = app.IIWAControlObject.SendTrajectory();
                %Parar captura de datos
                prg.Message = 'Ejecutando trayectoria en vacio. El paciente debe relajar el brazo, y el robot no debe tocarse mientras se mueve. Parando captura sensores Delsys.';
                app.DelsysSensorsObject.EndReference();
                prg.Message = 'Ejecutando trayectoria en vacio. El paciente debe relajar el brazo, y el robot no debe tocarse mientras se mueve. Parando captura sensor FT.';
                app.FTSensorObject.EndReference();
                %Limpiar captura y parar sensores
                prg.Value=0.66;
                prg.Message = 'Ejecutando trayectoria en vacio. El paciente debe relajar el brazo, y el robot no debe tocarse mientras se mueve. Finalizando...';
                app.IIWAControlObject.EndReference();
                app.FTSensorObject.StopSensor();
                app.DelsysSensorsObject.StopSensors();

                app.FTSensorObject.GetReference();
                
                %GUI update
                app.RunTrajectoryEmptyButton.Enable='off';
                app.InitialPositionRunButton.Enable='on';
                app.RelaxMusclesEmptyButton.Enable = 'off';
                close(prg);
                uialert(app.UIFigure, 'Trayectoria ejecutada en vacío el robot', 'Title', 'Icon', 'success');
            catch ME
               uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error'); 
            end
        end

        % Button pushed function: RunTrajectoryButton
        function RunTrajectoryButtonPushed(app, event)
            if(app.RelaxMusclesRunButton.Value == 1)
                app.RelaxMusclesRunButton.Value = 0;
                app.EMGrealTimePlot(app.RelaxMusclesRunButton.Value);
            end
            prg=uiprogressdlg(app.UIFigure, 'Title', ['Ejecutando trayectoria ', app.IIWAControlObject.NameData]);
            try
                app.FTSensorObject.StartSensor(); %Normally lasts for 4-5 seconds
                app.DelsysSensorsObject.StartSensors();
                for i_trial=1:app.RepetitionsSpinner.Value
                    %Inicializar  
                    str_repeticion=['Repetición ', num2str(i_trial), ' de ', num2str(app.RepetitionsSpinner.Value)];
                    if (i_trial~=1)
                        t_wait = app.TimeWaitBetweenRepetitions-toc;
                        prg.Message=['Esperando ', num2str(app.TimeWaitBetweenRepetitions), ' segundos de descanso, restante: ', num2str(t_wait)];
                        pause(t_wait); %Pause for N seconds discounting the calculations time
                    end
                    prg.Message=[str_repeticion, ': Ejecutando trayectoria'];
                    prg.Value=((i_trial-1)/(app.RepetitionsSpinner.Value*2)); 
                    
                    %Iniciar captura de datos
                    prg.Message=[str_repeticion, ': Ejecutando trayectoria. Iniciando sensor FT.'];
                    app.FTSensorObject.StartTrial(i_trial);
                    app.ExecutionTimes{i_trial}.FT.Init=toc;
                    prg.Message=[str_repeticion, ': Ejecutando trayectoria. Iniciando sensores Delsys.'];
                    app.DelsysSensorsObject.StartCapture();
                    %Ejecutar
                    app.ExecutionTimes{i_trial}.Robot.Call=toc;
                    prg.Message=[str_repeticion, ': Ejecutando trayectoria. Moviendo robot.'];
%                     [app.IDPatient, app.Arm, app.WorkingPlane]= app.IIWAControlObject.SendTrajectory();
                    [app.IDPatient, app.Arm]= app.IIWAControlObject.SendTrajectory();
                    app.ExecutionTimes{i_trial}.Robot.End=toc;
                    
                    %Parar captura de datos
                    prg.Message=[str_repeticion, ': Ejecutando trayectoria. Parando sensores Delsys.'];
                    app.DelsysSensorsObject.EndTrial(i_trial);
                    prg.Message=[str_repeticion, ': Ejecutando trayectoria. Parando sensor FT.'];
                    app.FTSensorObject.EndTrial(i_trial);
                    app.ExecutionTimes{i_trial}.FT.End=toc;

                    %Save the capture but not interpolate the data yet 
                    tic;
                    if (i_trial~=app.RepetitionsSpinner.Value)
                        %Move to initial position
                        prg.Message =[str_repeticion ': Moviendo a posición inicial']; 
                        app.IIWAControlObject.SendInitialPosition();
                        prg.Message=['Esperando ', num2str(app.TimeWaitBetweenRepetitions), ' segundos de descanso'];
                    end
                    app.IIWAControlObject.SaveDataOutput(i_trial);
                end
                                    
                %Parar captura FT/DelsysSensors
                app.DelsysSensorsObject.StopSensors();
                app.FTSensorObject.StopSensor();

                
                % Salvar resultados
                for i_trial=1:app.RepetitionsSpinner.Value
                    prg.Value=(((i_trial-1)+app.RepetitionsSpinner.Value)/(app.RepetitionsSpinner.Value*2)); 
                    prg.Message=['Interpolando y salvando repetición ', num2str(i_trial), ' de ', num2str(app.RepetitionsSpinner.Value)];
                    app.SaveTrial(i_trial);
                end
                %Actualizar GUI
                if (app.RepetitionsSpinner.Value==1)
                    app.RepetitionPlotSpinner.Limits=[1 2];
                    app.RepetitionPlotSpinner.Value=1;
                    app.RepetitionPlotSpinner.Enable='off';
                    app.RepetitionPlotLabel.Visible='off';
                else
                    app.RepetitionPlotSpinner.Enable='on';
                    app.RepetitionPlotLabel.Visible='on';
                    app.RepetitionPlotSpinner.Value=1;
                    app.RepetitionPlotSpinner.Limits=[0, app.RepetitionsSpinner.Value];
                end
                app.RunTrajectoryButton.Enable='off';
                app.RepetitionsSpinner.Enable='off';
                app.RelaxMusclesRunButton.Enable = 'off';
                app.GoToResultsButton.Enable='on';
                app.BackToTrialMenuRepeatButton.Enable='on';
                close(prg); 
                uialert(app.UIFigure, 'Trayectoria ejecutada en el robot', 'Title', 'Icon', 'success', 'CloseFcn', 'uiresume(gcbf)');
                uiwait(gcbf);
            catch ME
                close(prg);
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: q2Button
        function q2ButtonPushed(app, event)
            try
                app.PlotsPanel.Visible='on';
                app.ResultsPanel.Visible='off';
                app.UIFigure.WindowState = 'maximized';
                drawnow;
                app.IIWAControlObject.PlotJointPositionTrajectories('results', app.RepetitionPlotSpinner.Value, app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: dq2Button
        function dq2ButtonPushed(app, event)
            try
                app.PlotsPanel.Visible='on';
                app.ResultsPanel.Visible='off';
                app.UIFigure.WindowState = 'maximized';
                drawnow;
                app.IIWAControlObject.PlotJointVelocityTrajectories('results', app.RepetitionPlotSpinner.Value, app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: ddq2Button
        function ddq2ButtonPushed(app, event)
            try
                app.PlotsPanel.Visible='on';
                app.ResultsPanel.Visible='off';
                app.UIFigure.WindowState = 'maximized';
                drawnow;
                app.IIWAControlObject.PlotJointAccelerationTrajectories('results', app.RepetitionPlotSpinner.Value, app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: Eq2Button
        function Eq2ButtonPushed(app, event)
            try
                app.PlotsPanel.Visible='on';
                app.ResultsPanel.Visible='off';
                app.UIFigure.WindowState = 'maximized';
                drawnow;
                app.IIWAControlObject.PlotJointPositionRepeatibilityError('results', app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: Edq2Button
        function Edq2ButtonPushed(app, event)
            try
                app.PlotsPanel.Visible='on';
                app.ResultsPanel.Visible='off';
                app.UIFigure.WindowState = 'maximized';
                drawnow;
                app.IIWAControlObject.PlotJointVelocityRepeatibilityError('results', app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: Eddq2Button
        function Eddq2ButtonPushed(app, event)
            try
                app.PlotsPanel.Visible='on';
                app.ResultsPanel.Visible='off';
                app.UIFigure.WindowState = 'maximized';
                drawnow;
                app.IIWAControlObject.PlotJointVelocityRepeatibilityError('results', app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: Epq2Button
        function Epq2ButtonPushed(app, event)
            try
                app.PlotsPanel.Visible='on';
                app.ResultsPanel.Visible='off';
                app.UIFigure.WindowState = 'maximized';
                drawnow;
                app.IIWAControlObject.PlotJointPositionError('results', app.RepetitionPlotSpinner.Value, app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: Epdq2Button
        function Epdq2ButtonPushed(app, event)
            try
                app.PlotsPanel.Visible='on';
                app.ResultsPanel.Visible='off';
                app.UIFigure.WindowState = 'maximized';
                drawnow;
                app.IIWAControlObject.PlotJointVelocityError('results', app.RepetitionPlotSpinner.Value, app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: Epddq2Button
        function Epddq2ButtonPushed(app, event)
            try
                app.PlotsPanel.Visible='on';
                app.ResultsPanel.Visible='off';
                app.UIFigure.WindowState = 'maximized';
                drawnow;
                app.IIWAControlObject.PlotJointAccelerationError('results', app.RepetitionPlotSpinner.Value, app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: x2Button
        function x2ButtonPushed(app, event)
            try
                app.PlotsPanel.Visible='on';
                app.ResultsPanel.Visible='off';
                app.UIFigure.WindowState = 'maximized';
                drawnow;
                app.IIWAControlObject.PlotCartesianPositionTrajectories('results', app.RepetitionPlotSpinner.Value, app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: dx2Button
        function dx2ButtonPushed(app, event)
            try
                app.PlotsPanel.Visible='on';
                app.ResultsPanel.Visible='off';
                app.UIFigure.WindowState = 'maximized';
                drawnow;
                app.IIWAControlObject.PlotCartesianVelocityTrajectories('results', app.RepetitionPlotSpinner.Value, app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: Ex2Button
        function Ex2ButtonPushed(app, event)
            try
                app.PlotsPanel.Visible='on';
                app.ResultsPanel.Visible='off';
                app.UIFigure.WindowState = 'maximized';
                drawnow;
                app.IIWAControlObject.PlotCartesianPositionRepeatibilityError('results', app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: Edx2Button
        function Edx2ButtonPushed(app, event)
            try
                app.PlotsPanel.Visible='on';
                app.ResultsPanel.Visible='off';
                app.UIFigure.WindowState = 'maximized';
                drawnow;
                app.IIWAControlObject.PlotCartesianVelocityRepeatibilityError('results', app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: Epx2Button
        function Epx2ButtonPushed(app, event)
            try
                app.PlotsPanel.Visible='on';
                app.ResultsPanel.Visible='off';
                app.UIFigure.WindowState = 'maximized';
                drawnow;
                app.IIWAControlObject.PlotCartesianPositionError('results', app.RepetitionPlotSpinner.Value, app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: Epdx2Button
        function Epdx2ButtonPushed(app, event)
            try
                app.PlotsPanel.Visible='on';
                app.ResultsPanel.Visible='off';
                app.UIFigure.WindowState = 'maximized';
                drawnow;
                app.IIWAControlObject.PlotCartesianVelocityError('results', app.RepetitionPlotSpinner.Value, app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: ThreeD2Button
        function ThreeD2ButtonPushed(app, event)
            try
                app.PlotsPanel.Visible='on';
                app.ResultsPanel.Visible='off';
                app.UIFigure.WindowState = 'maximized';
                drawnow;
                app.IIWAControlObject.Plot3DCartesianPositionTrajectories('results', app.RepetitionPlotSpinner.Value, app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: FT2Button
        function FT2ButtonPushed(app, event)
            try
                app.PlotsPanel.Visible='on';
                app.ResultsPanel.Visible='off';
                app.UIFigure.WindowState = 'maximized';
                drawnow;
                app.FTSensorObject.PlotFT('results', app.RepetitionPlotSpinner.Value, app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: Accelerations2Button
        function Accelerations2ButtonPushed(app, event)
            try
                app.PlotsPanel.Visible='on';
                app.ResultsPanel.Visible='off';
                app.UIFigure.WindowState = 'maximized';
                drawnow;
                app.DelsysSensorsObject.PlotACC('results', app.RepetitionPlotSpinner.Value, app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: EMG2Button
        function EMG2ButtonPushed(app, event)
            try
                app.PlotsPanel.Visible='on';
                app.ResultsPanel.Visible='off';
                app.UIFigure.WindowState = 'maximized';
                drawnow;
                app.DelsysSensorsObject.PlotEMG('results', app.RepetitionPlotSpinner.Value, app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Button pushed function: AngularVelocity2Button
        function AngularVelocity2ButtonPushed(app, event)
            try
                app.PlotsPanel.Visible='on';
                app.ResultsPanel.Visible='off';
                app.UIFigure.WindowState = 'maximized';
                drawnow;
                app.DelsysSensorsObject.PlotGYRO('results', app.RepetitionPlotSpinner.Value, app.PlotsPanel);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                if (~isempty(app.DelsysSensorsObject))
                    app.DelsysSensorsObject.CloseSensors();
                end
                delete(app);
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error');
            end
        end

        % Value changed function: IIWACheckBox
        function IIWACheckBoxValueChanged(app, event)
            prg=uiprogressdlg(app.UIFigure, 'Message', 'Inicializando IIWA');
            try
                app.IIWAControlObject = IIWAControl(app.IIWACheckBox.Value);
                app.UpdateInputDropDown();
                app.UpdateAdjustedTrajectoryDropDown();
                prg.close();
            catch ME
                app.IIWACheckBox.Value = 0;
                prg.close();
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error'); 
            end
        end

        % Value changed function: DelsysSensorsCheckBox
        function DelsysSensorsCheckBoxValueChanged(app, event)
            prg=uiprogressdlg(app.UIFigure, 'Message', 'Inicializando sensores Delsys');
            try
                if (~isempty(app.DelsysSensorsObject))
                    app.DelsysSensorsObject.CloseSensors();
                end
                app.DelsysSensorsObject = DelsysSensors(app.DelsysSensorsCheckBox.Value);
                prg.close();
            catch ME
                app.DelsysSensorsCheckBox.Value = 0;
                prg.close();
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error'); 
            end
        end

        % Value changed function: FTSensorCheckBox
        function FTSensorCheckBoxValueChanged(app, event)
            prg=uiprogressdlg(app.UIFigure, 'Message', 'Inicializando sensor F/T');
            try
                app.FTSensorObject = FTSensor(app.FTSensorCheckBox.Value);
                prg.close();
            catch ME
                app.FTSensorCheckBox.Value = 0;
                prg.close();
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error'); 
            end
        end

        % Value changed function: RepetitionPlotSpinner
        function RepetitionPlotSpinnerValueChanged(app, event)
            try
                value = app.RepetitionPlotSpinner.Value;
                if (value == 0) && (app.IIWACheckBox.Value == 1)
                    app.Eq2Button.Enable = 'on';
                    app.Edq2Button.Enable = 'on';
                    app.Eddq2Button.Enable = 'on';
                    app.Ex2Button.Enable = 'on';
                    app.Edx2Button.Enable = 'on';
                else
                    app.Eq2Button.Enable = 'off';
                    app.Edq2Button.Enable = 'off';
                    app.Eddq2Button.Enable = 'off';
                    app.Ex2Button.Enable = 'off';
                    app.Edx2Button.Enable = 'off';
                end
            catch ME
                uialert(app.UIFigure, [ME.identifier, ': ', ME.message], 'Error')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.StartPanel = uipanel(app.UIFigure);
            app.NewPatientButton = uibutton(app.StartPanel, 'push');
            app.NewPatientButton.ButtonPushedFcn = createCallbackFcn(app, @NewPatientButtonPushed, true);
            app.NewPatientButton.Text = 'NUEVO PACIENTE';
            app.LoadPatientButton = uibutton(app.StartPanel, 'push');
            app.LoadPatientButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPatientButtonPushed, true);
            app.LoadPatientButton.Text = 'CARGAR PACIENTE';
            app.RoboespasStartImage = uiimage(app.StartPanel);
            app.Uc3mStartImage = uiimage(app.StartPanel);
            app.DPILabel = uilabel(app.StartPanel);
            app.DPILabel.Text = 'ROBOESPAS (DPI2017-87562-C2-1-R)';
            app.TitleLabel = uilabel(app.StartPanel);
            app.TitleLabel.Text = 'BIENVENIDO A LA APLICACIÓN DE';
            app.RoboespasLabel = uilabel(app.StartPanel);
            app.RoboespasLabel.Text = 'ROBOESPAS';
            app.PatientDataPanel = uipanel(app.UIFigure);
            app.PatientDataPanel.Visible = 'off';
            app.PatientDataLabel = uilabel(app.PatientDataPanel);
            app.PatientDataLabel.Text = 'Introducir Datos del Paciente';
            app.LeftArmLabel = uilabel(app.PatientDataPanel);
            app.LeftArmLabel.Text = 'Medidas Brazo Izquierdo';
            app.RightArmLabel = uilabel(app.PatientDataPanel);
            app.RightArmLabel.Text = 'Medidas Brazo Derecho';
            app.SavePatientButton = uibutton(app.PatientDataPanel, 'push');
            app.SavePatientButton.ButtonPushedFcn = createCallbackFcn(app, @SavePatientButtonPushed, true);
            app.SavePatientButton.Text = 'Guardar Paciente';
            app.IDEditFieldLabel = uilabel(app.PatientDataPanel);
            app.IDEditFieldLabel.Enable = 'off';
            app.IDEditFieldLabel.Text = 'ID';
            app.IDEditField = uieditfield(app.PatientDataPanel, 'numeric');
            app.IDEditField.Enable = 'off';
            app.HeightEditFieldLabel = uilabel(app.PatientDataPanel);
            app.HeightEditFieldLabel.Text = 'Altura';
            app.HeightEditField = uieditfield(app.PatientDataPanel, 'numeric');
            app.WeightEditFieldLabel = uilabel(app.PatientDataPanel);
            app.WeightEditFieldLabel.Text = 'Peso';
            app.WeightEditField = uieditfield(app.PatientDataPanel, 'numeric');
            app.AgeEditFieldLabel = uilabel(app.PatientDataPanel);
            app.AgeEditFieldLabel.Text = 'Edad';
            app.AgeEditField = uieditfield(app.PatientDataPanel, 'numeric');
            app.LeftWristElbowEditFieldLabel = uilabel(app.PatientDataPanel);
            app.LeftWristElbowEditFieldLabel.Text = 'Muñeca-Codo';
            app.LeftWristElbowEditField = uieditfield(app.PatientDataPanel, 'numeric');
            app.LeftElbowShoulderEditFieldLabel = uilabel(app.PatientDataPanel);
            app.LeftElbowShoulderEditFieldLabel.Text = 'Codo-Hombro';
            app.LeftElbowShoulderEditField = uieditfield(app.PatientDataPanel, 'numeric');
            app.LeftShoulderBreastboneEditFieldLabel = uilabel(app.PatientDataPanel);
            app.LeftShoulderBreastboneEditFieldLabel.Text = 'Hombro-Esternón';
            app.LeftShoulderBreastboneEditField = uieditfield(app.PatientDataPanel, 'numeric');
            app.LeftWristHandleEditFieldLabel = uilabel(app.PatientDataPanel);
            app.LeftWristHandleEditFieldLabel.Text = 'Muñeca-Agarrador';
            app.LeftWristHandleEditField = uieditfield(app.PatientDataPanel, 'numeric');
            app.RightWristHandleEditFieldLabel = uilabel(app.PatientDataPanel);
            app.RightWristHandleEditFieldLabel.Text = 'Muñeca-Agarrador';
            app.RightWristHandleEditField = uieditfield(app.PatientDataPanel, 'numeric');
            app.RightWristElbowEditFieldLabel = uilabel(app.PatientDataPanel);
            app.RightWristElbowEditFieldLabel.Text = 'Muñeca-Codo';
            app.RightWristElbowEditField = uieditfield(app.PatientDataPanel, 'numeric');
            app.RightElbowShoulderEditFieldLabel = uilabel(app.PatientDataPanel);
            app.RightElbowShoulderEditFieldLabel.Text = 'Codo-Hombro';
            app.RightElbowShoulderEditField = uieditfield(app.PatientDataPanel, 'numeric');
            app.RightShoulderBreastboneEditFieldLabel = uilabel(app.PatientDataPanel);
            app.RightShoulderBreastboneEditFieldLabel.Text = 'Hombro-Esternón';
            app.RightShoulderBreastboneEditField = uieditfield(app.PatientDataPanel, 'numeric');
            app.GenderDropDownLabel = uilabel(app.PatientDataPanel);
            app.GenderDropDownLabel.Text = 'Género';
            app.GenderDropDown = uidropdown(app.PatientDataPanel);
            app.GenderDropDown.Items = {'Masculino', 'Femenino', ''};
            app.GenderDropDown.Value = 'Masculino';
            app.NameEditFieldLabel = uilabel(app.PatientDataPanel);
            app.NameEditFieldLabel.Text = 'Nombre';
            app.NameEditField = uieditfield(app.PatientDataPanel, 'text');
            app.BackToStartPatientDataButton = uibutton(app.PatientDataPanel, 'push');
            app.BackToStartPatientDataButton.ButtonPushedFcn = createCallbackFcn(app, @BackToStartPatientDataButtonPushed, true);
            app.BackToStartPatientDataButton.Text = 'Volver al Inicio';
            app.SurnamesEditFieldLabel = uilabel(app.PatientDataPanel);
            app.SurnamesEditFieldLabel.Text = 'Apellidos';
            app.SurnamesEditField = uieditfield(app.PatientDataPanel, 'text');
            app.SpasticityLevelEditFieldLabel = uilabel(app.PatientDataPanel);
            app.SpasticityLevelEditFieldLabel.Text = 'Nivel Diagnosticado Espasticidad';
            app.SpasticityLevelEditField = uieditfield(app.PatientDataPanel, 'numeric');
            app.BackToMenuPatientDataButton = uibutton(app.PatientDataPanel, 'push');
            app.BackToMenuPatientDataButton.ButtonPushedFcn = createCallbackFcn(app, @BackToMenuPatientDataButtonPushed, true);
            app.BackToMenuPatientDataButton.Visible = 'off';
            app.BackToMenuPatientDataButton.Text = 'Volver al Menú';
            app.LoadPatientPanel = uipanel(app.UIFigure);
            app.LoadPatientPanel.Visible = 'off';
            app.LoadPatientTable = uitable(app.LoadPatientPanel);
            app.LoadPatientTable.ColumnName = {'Apellidos'; 'Nombre'; 'ID'};
            app.LoadPatientTable.RowName = {};
            app.LoadPatientTable.ColumnSortable = [true false true];
            app.LoadPatientTable.CellSelectionCallback = createCallbackFcn(app, @LoadPatientTableCellSelection, true);
            app.BackToStartLoadPatientButton = uibutton(app.LoadPatientPanel, 'push');
            app.BackToStartLoadPatientButton.ButtonPushedFcn = createCallbackFcn(app, @BackToStartLoadPatientButtonPushed, true);
            app.BackToStartLoadPatientButton.Text = 'Volver al Inicio';
            app.ChoosePatientButton = uibutton(app.LoadPatientPanel, 'push');
            app.ChoosePatientButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosePatientButtonPushed, true);
            app.ChoosePatientButton.Text = 'Seleccionar Paciente';
            app.PatientMenuPanel = uipanel(app.UIFigure);
            app.PatientMenuPanel.Visible = 'off';
            app.EditPatientButton = uibutton(app.PatientMenuPanel, 'push');
            app.EditPatientButton.ButtonPushedFcn = createCallbackFcn(app, @EditPatientButtonPushed, true);
            app.EditPatientButton.Text = 'EDITAR DATOS DEL PACIENTE';
            app.StartTrialButton = uibutton(app.PatientMenuPanel, 'push');
            app.StartTrialButton.ButtonPushedFcn = createCallbackFcn(app, @StartTrialButtonPushed, true);
            app.StartTrialButton.Text = 'COMENZAR PRUEBA';
            app.ShowMedicalHistoryButton = uibutton(app.PatientMenuPanel, 'push');
            app.ShowMedicalHistoryButton.ButtonPushedFcn = createCallbackFcn(app, @ShowMedicalHistoryButtonPushed, true);
            app.ShowMedicalHistoryButton.Text = 'VER HISTORIAL DEL PACIENTE';
            app.PatientMenuEditFieldLabel = uilabel(app.PatientMenuPanel);
            app.PatientMenuEditFieldLabel.Text = 'PACIENTE';
            app.PatientMenuEditField = uieditfield(app.PatientMenuPanel, 'numeric');
            app.PatientMenuEditField.Editable = 'off';
            app.BackToStartPatientMenuButton = uibutton(app.PatientMenuPanel, 'push');
            app.BackToStartPatientMenuButton.ButtonPushedFcn = createCallbackFcn(app, @BackToStartPatientMenuButtonPushed, true);
            app.BackToStartPatientMenuButton.Text = 'VOLVER AL INICIO';
            app.RoboespasMenuImage = uiimage(app.PatientMenuPanel);
            app.Uc3mMenuImage = uiimage(app.PatientMenuPanel);
            app.MedicalHistoryPanel = uipanel(app.UIFigure);
            app.MedicalHistoryPanel.Visible = 'off';
            app.MedicalHistoryTree = uitree(app.MedicalHistoryPanel);
            app.MedicalHistoryTree.Multiselect = 'on';
            app.MedicalHistoryTree.SelectionChangedFcn = createCallbackFcn(app, @MedicalHistoryTreeSelectionChanged, true);
            app.PersonalDataPanel = uipanel(app.MedicalHistoryPanel);
            app.PersonalDataPanel.Title = 'Datos Personales';
            app.PersonalDataPanel.Visible = 'off';
            app.LeftArmLabel2 = uilabel(app.PersonalDataPanel);
            app.LeftArmLabel2.Text = 'Medidas Brazo Izquierdo';
            app.RightArmLabel2 = uilabel(app.PersonalDataPanel);
            app.RightArmLabel2.Text = 'Medidas Brazo Derecho';
            app.IDEditFieldLabel2 = uilabel(app.PersonalDataPanel);
            app.IDEditFieldLabel2.Text = 'ID';
            app.IDEditField2 = uieditfield(app.PersonalDataPanel, 'numeric');
            app.IDEditField2.Editable = 'off';
            app.SpasticityLevelEditFieldLabel2 = uilabel(app.PersonalDataPanel);
            app.SpasticityLevelEditFieldLabel2.Text = 'Nivel Diagnosticado Espasticidad';
            app.SpasticityLevelEditField2 = uieditfield(app.PersonalDataPanel, 'numeric');
            app.SpasticityLevelEditField2.Editable = 'off';
            app.HeightEditFieldLabel2 = uilabel(app.PersonalDataPanel);
            app.HeightEditFieldLabel2.Text = 'Altura';
            app.HeightEditField2 = uieditfield(app.PersonalDataPanel, 'numeric');
            app.HeightEditField2.Editable = 'off';
            app.WeightEditFieldLabel2 = uilabel(app.PersonalDataPanel);
            app.WeightEditFieldLabel2.Text = 'Peso';
            app.WeightEditField2 = uieditfield(app.PersonalDataPanel, 'numeric');
            app.WeightEditField2.Editable = 'off';
            app.AgeEditFieldLabel2 = uilabel(app.PersonalDataPanel);
            app.AgeEditFieldLabel2.Text = 'Edad';
            app.AgeEditField2 = uieditfield(app.PersonalDataPanel, 'numeric');
            app.AgeEditField2.Editable = 'off';
            app.LeftWristElbowEditFieldLabel2 = uilabel(app.PersonalDataPanel);
            app.LeftWristElbowEditFieldLabel2.Text = 'Muñeca-Codo';
            app.LeftWristElbowEditField2 = uieditfield(app.PersonalDataPanel, 'numeric');
            app.LeftWristElbowEditField2.Editable = 'off';
            app.LeftElbowShoulderEditFieldLabel2 = uilabel(app.PersonalDataPanel);
            app.LeftElbowShoulderEditFieldLabel2.Text = 'Codo-Hombro';
            app.LeftElbowShoulderEditField2 = uieditfield(app.PersonalDataPanel, 'numeric');
            app.LeftElbowShoulderEditField2.Editable = 'off';
            app.LeftShoulderBreastboneEditFieldLabel2 = uilabel(app.PersonalDataPanel);
            app.LeftShoulderBreastboneEditFieldLabel2.Text = 'Hombro-Esternón';
            app.LeftShoulderBreastboneEditField2 = uieditfield(app.PersonalDataPanel, 'numeric');
            app.LeftShoulderBreastboneEditField2.Editable = 'off';
            app.LeftWristHandleEditFieldLabel2 = uilabel(app.PersonalDataPanel);
            app.LeftWristHandleEditFieldLabel2.Text = 'Muñeca-Agarrador';
            app.LeftWristHandleEditField2 = uieditfield(app.PersonalDataPanel, 'numeric');
            app.LeftWristHandleEditField2.Editable = 'off';
            app.RightWristHandleEditFieldLabel2 = uilabel(app.PersonalDataPanel);
            app.RightWristHandleEditFieldLabel2.Text = 'Muñeca-Agarrador';
            app.RightWristHandleEditField2 = uieditfield(app.PersonalDataPanel, 'numeric');
            app.RightWristHandleEditField2.Editable = 'off';
            app.RightWristElbowEditFieldLabel2 = uilabel(app.PersonalDataPanel);
            app.RightWristElbowEditFieldLabel2.Text = 'Muñeca-Codo';
            app.RightWristElbowEditField2 = uieditfield(app.PersonalDataPanel, 'numeric');
            app.RightWristElbowEditField2.Editable = 'off';
            app.RightElbowShoulderEditFieldLabel2 = uilabel(app.PersonalDataPanel);
            app.RightElbowShoulderEditFieldLabel2.Text = 'Codo-Hombro';
            app.RightElbowShoulderEditField2 = uieditfield(app.PersonalDataPanel, 'numeric');
            app.RightElbowShoulderEditField2.Editable = 'off';
            app.RightShoulderBreastboneEditFieldLabel2 = uilabel(app.PersonalDataPanel);
            app.RightShoulderBreastboneEditFieldLabel2.Text = 'Hombro-Esternón';
            app.RightShoulderBreastboneEditField2 = uieditfield(app.PersonalDataPanel, 'numeric');
            app.RightShoulderBreastboneEditField2.Editable = 'off';
            app.NameEditFieldLabel2 = uilabel(app.PersonalDataPanel);
            app.NameEditFieldLabel2.Text = 'Nombre';
            app.NameEditField2 = uieditfield(app.PersonalDataPanel, 'text');
            app.NameEditField2.Editable = 'off';
            app.SurnamesEditFieldLabel2 = uilabel(app.PersonalDataPanel);
            app.SurnamesEditFieldLabel2.Text = 'Apellidos';
            app.SurnamesEditField2 = uieditfield(app.PersonalDataPanel, 'text');
            app.SurnamesEditField2.Editable = 'off';
            app.GenderEditFieldLabel2 = uilabel(app.PersonalDataPanel);
            app.GenderEditFieldLabel2.Text = 'Género';
            app.GenderEditField2 = uieditfield(app.PersonalDataPanel, 'text');
            app.GenderEditField2.Editable = 'off';
            app.BackToMenuMedicalHistoryButton = uibutton(app.MedicalHistoryPanel, 'push');
            app.BackToMenuMedicalHistoryButton.ButtonPushedFcn = createCallbackFcn(app, @BackToMenuMedicalHistoryButtonPushed, true);
            app.BackToMenuMedicalHistoryButton.Text = 'Volver al Menú';
            app.ResultsButtonsPanel = uipanel(app.MedicalHistoryPanel);
            app.TrajectoryLabel = uilabel(app.ResultsButtonsPanel);
            app.TrajectoryLabel.Text = 'Trayectoria';
            app.JointLabel = uilabel(app.ResultsButtonsPanel);
            app.JointLabel.Text = 'Articular';
            app.qButton = uibutton(app.ResultsButtonsPanel, 'push');
            app.qButton.ButtonPushedFcn = createCallbackFcn(app, @qButtonPushed, true);
            app.qButton.Text = 'q';
            app.dqButton = uibutton(app.ResultsButtonsPanel, 'push');
            app.dqButton.ButtonPushedFcn = createCallbackFcn(app, @dqButtonPushed, true);
            app.dqButton.Text = 'dq';
            app.EqButton = uibutton(app.ResultsButtonsPanel, 'push');
            app.EqButton.ButtonPushedFcn = createCallbackFcn(app, @EqButtonPushed, true);
            app.EqButton.Text = 'E(q)';
            app.EdqButton = uibutton(app.ResultsButtonsPanel, 'push');
            app.EdqButton.ButtonPushedFcn = createCallbackFcn(app, @EdqButtonPushed, true);
            app.EdqButton.Text = 'E(dq)';
            app.CartesianLabel = uilabel(app.ResultsButtonsPanel);
            app.CartesianLabel.Text = 'Cartesiana';
            app.xButton = uibutton(app.ResultsButtonsPanel, 'push');
            app.xButton.ButtonPushedFcn = createCallbackFcn(app, @xButtonPushed, true);
            app.xButton.Text = 'x';
            app.ExButton = uibutton(app.ResultsButtonsPanel, 'push');
            app.ExButton.ButtonPushedFcn = createCallbackFcn(app, @ExButtonPushed, true);
            app.ExButton.Text = 'E(x)';
            app.EdxButton = uibutton(app.ResultsButtonsPanel, 'push');
            app.EdxButton.ButtonPushedFcn = createCallbackFcn(app, @EdxButtonPushed, true);
            app.EdxButton.Text = 'E(dx)';
            app.ThreeDButton = uibutton(app.ResultsButtonsPanel, 'push');
            app.ThreeDButton.ButtonPushedFcn = createCallbackFcn(app, @ThreeDButtonPushed, true);
            app.ThreeDButton.Text = '3D';
            app.dxButton = uibutton(app.ResultsButtonsPanel, 'push');
            app.dxButton.ButtonPushedFcn = createCallbackFcn(app, @dxButtonPushed, true);
            app.dxButton.Text = 'dx';
            app.ForceSensorLabel = uilabel(app.ResultsButtonsPanel);
            app.ForceSensorLabel.Text = 'Sensor Fuerza Par';
            app.FTButton = uibutton(app.ResultsButtonsPanel, 'push');
            app.FTButton.ButtonPushedFcn = createCallbackFcn(app, @FTButtonPushed, true);
            app.FTButton.Text = 'FT';
            app.DelsysSensorsLabel = uilabel(app.ResultsButtonsPanel);
            app.DelsysSensorsLabel.Text = 'Sensores Delsys';
            app.EMGButton = uibutton(app.ResultsButtonsPanel, 'push');
            app.EMGButton.ButtonPushedFcn = createCallbackFcn(app, @EMGButtonPushed, true);
            app.EMGButton.Text = 'EMG';
            app.AccelerationsButton = uibutton(app.ResultsButtonsPanel, 'push');
            app.AccelerationsButton.ButtonPushedFcn = createCallbackFcn(app, @AccelerationsButtonPushed, true);
            app.AccelerationsButton.Text = 'Aceleraciones';
            app.AngularVelocityButton = uibutton(app.ResultsButtonsPanel, 'push');
            app.AngularVelocityButton.ButtonPushedFcn = createCallbackFcn(app, @AngularVelocityButtonPushed, true);
            app.AngularVelocityButton.Text = 'Velocidad Angular';
            app.ddqButton = uibutton(app.ResultsButtonsPanel, 'push');
            app.ddqButton.ButtonPushedFcn = createCallbackFcn(app, @ddqButtonPushed, true);
            app.ddqButton.Text = 'ddq';
            app.EddqButton = uibutton(app.ResultsButtonsPanel, 'push');
            app.EddqButton.ButtonPushedFcn = createCallbackFcn(app, @EddqButtonPushed, true);
            app.EddqButton.Text = 'E(ddq)';
            app.EpqButton = uibutton(app.ResultsButtonsPanel, 'push');
            app.EpqButton.ButtonPushedFcn = createCallbackFcn(app, @EpqButtonPushed, true);
            app.EpqButton.Text = 'Ep(q)';
            app.EpdqButton = uibutton(app.ResultsButtonsPanel, 'push');
            app.EpdqButton.ButtonPushedFcn = createCallbackFcn(app, @EpdqButtonPushed, true);
            app.EpdqButton.Text = 'Ep(dq)';
            app.EpddqButton = uibutton(app.ResultsButtonsPanel, 'push');
            app.EpddqButton.ButtonPushedFcn = createCallbackFcn(app, @EpddqButtonPushed, true);
            app.EpddqButton.Text = 'Ep(ddq)';
            app.EpxButton = uibutton(app.ResultsButtonsPanel, 'push');
            app.EpxButton.ButtonPushedFcn = createCallbackFcn(app, @EpxButtonPushed, true);
            app.EpxButton.Text = 'Ep(x)';
            app.EpdxButton = uibutton(app.ResultsButtonsPanel, 'push');
            app.EpdxButton.ButtonPushedFcn = createCallbackFcn(app, @EpdxButtonPushed, true);
            app.EpdxButton.Text = 'Ep(dx)';
            app.JointPrecisionLabel = uilabel(app.ResultsButtonsPanel);
            app.JointPrecisionLabel.Text = 'Precisión:';
            app.JointAccuracyLabel = uilabel(app.ResultsButtonsPanel);
            app.JointAccuracyLabel.Text = 'Exactitud:';
            app.CartesianPrecisionLabel = uilabel(app.ResultsButtonsPanel);
            app.CartesianPrecisionLabel.Text = 'Precisión:';
            app.CartesianAccuracyLabel = uilabel(app.ResultsButtonsPanel);
            app.CartesianAccuracyLabel.Text = 'Exactitud:';
            app.TrialDataPanel = uipanel(app.MedicalHistoryPanel);
            app.TrialDataPatientEditField = uieditfield(app.TrialDataPanel, 'text');
            app.TrialDataPatientEditField.Editable = 'off';
            app.TrialDataChoosedArmEditField = uieditfield(app.TrialDataPanel, 'text');
            app.TrialDataChoosedArmEditField.Editable = 'off';
            app.TrialDataTrajectoryDateCapturedEditField = uieditfield(app.TrialDataPanel, 'text');
            app.TrialDataTrajectoryDateCapturedEditField.Editable = 'off';
            app.TrialDataTrajectoryDateExecutedEditField = uieditfield(app.TrialDataPanel, 'text');
            app.TrialDataTrajectoryDateExecutedEditField.Editable = 'off';
            app.TrialDataChoosedWorkingPlaneEditField = uieditfield(app.TrialDataPanel, 'text');
            app.TrialDataChoosedWorkingPlaneEditField.Editable = 'off';
            app.TrialDataLabel = uilabel(app.TrialDataPanel);
            app.TrialDataLabel.Text = 'Datos Trayectoria Seleccionada';
            app.PlotsPanel = uipanel(app.UIFigure);
            app.PlotsPanel.Visible = 'off';
            app.CloseImage = uiimage(app.PlotsPanel);
            app.CloseImage.ImageClickedFcn = createCallbackFcn(app, @CloseImageClicked, true);
            app.SessionInfoPanel = uipanel(app.UIFigure);
            app.SessionInfoPanel.Visible = 'off';
            app.SessionInfoLabel = uilabel(app.SessionInfoPanel);
            app.SessionInfoLabel.Text = 'Introduzca los Datos de Inicio de la Sesión';
            app.ChooseArmLabel = uilabel(app.SessionInfoPanel);
            app.ChooseArmLabel.Text = '2.Indicar el Brazo Seleccionado';
            app.ChoosePlaneLabel = uilabel(app.SessionInfoPanel);
            app.ChoosePlaneLabel.Text = '3.Seleccionar la Posición del Paciente';
            app.ChooseMovementLabel = uilabel(app.SessionInfoPanel);
            app.ChooseMovementLabel.Text = '4.Seleccionar el Tipo de Movimiento a Realizar';
            app.ArmSwitch = uiswitch(app.SessionInfoPanel, 'slider');
            app.ArmSwitch.Items = {'Brazo Izquierdo', 'Brazo Derecho'};
            app.ArmSwitch.Value = 'Brazo Izquierdo';
            app.PlaneSwitch = uiswitch(app.SessionInfoPanel, 'slider');
            app.PlaneSwitch.Items = {'Plano YZ', 'Plano XZ'};
            app.PlaneSwitch.Value = 'Plano YZ';
            app.MovementDropDown = uidropdown(app.SessionInfoPanel);
            app.ConnectSensorsLabel = uilabel(app.SessionInfoPanel);
            app.ConnectSensorsLabel.Text = '1.Conectar el Robot y los Sensores';
            app.IIWACheckBox = uicheckbox(app.SessionInfoPanel);
            app.IIWACheckBox.ValueChangedFcn = createCallbackFcn(app, @IIWACheckBoxValueChanged, true);
            app.IIWACheckBox.Text = 'IIWA';
            app.FTSensorCheckBox = uicheckbox(app.SessionInfoPanel);
            app.FTSensorCheckBox.ValueChangedFcn = createCallbackFcn(app, @FTSensorCheckBoxValueChanged, true);
            app.FTSensorCheckBox.Text = 'Sensor Fuerza-Tensión';
            app.DelsysSensorsCheckBox = uicheckbox(app.SessionInfoPanel);
            app.DelsysSensorsCheckBox.ValueChangedFcn = createCallbackFcn(app, @DelsysSensorsCheckBoxValueChanged, true);
            app.DelsysSensorsCheckBox.Text = 'Sensores Delsys';
            app.GoToSensorsPanelButton = uibutton(app.SessionInfoPanel, 'push');
            app.GoToSensorsPanelButton.ButtonPushedFcn = createCallbackFcn(app, @GoToSensorsPanelButtonPushed, true);
            app.GoToSensorsPanelButton.Text = 'Colocar los Sensores';
            app.BackToMenuSessionInfoButton = uibutton(app.SessionInfoPanel, 'push');
            app.BackToMenuSessionInfoButton.ButtonPushedFcn = createCallbackFcn(app, @BackToMenuSessionInfoButtonPushed, true);
            app.BackToMenuSessionInfoButton.Text = 'Volver al Menú';
            app.GoToTrialMenuButton = uibutton(app.SessionInfoPanel, 'push');
            app.GoToTrialMenuButton.ButtonPushedFcn = createCallbackFcn(app, @GoToTrialMenuButtonPushed, true);
            app.GoToTrialMenuButton.Text = 'Continuar';
            app.SensorsPanel = uipanel(app.UIFigure);
            app.SensorsPanel.Visible = 'off';
            app.SensorsLabel = uilabel(app.SensorsPanel);
            app.SensorsLabel.Text = 'Indicar la Posición de los Sensores como [x,y,z] en mm';
            app.Sensor1Label = uilabel(app.SensorsPanel);
            app.Sensor1Label.Text = 'Sensor 1: Biceps Brachii';
            app.Sensor2Label = uilabel(app.SensorsPanel);
            app.Sensor2Label.Text = 'Sensor 2: Triceps Lateral Head';
            app.Sensor1Image = uiimage(app.SensorsPanel);
            app.Sensor2Image = uiimage(app.SensorsPanel);
            app.Sensor3Image = uiimage(app.SensorsPanel);
            app.Sensor4Image = uiimage(app.SensorsPanel);
            app.Sensor3Label = uilabel(app.SensorsPanel);
            app.Sensor3Label.Text = 'Sensor 3: Brachio Radialis';
            app.Sensor4Label = uilabel(app.SensorsPanel);
            app.Sensor4Label.Text = 'Sensor 4: Flexor Carpi Radialis';
            app.Sensor1PosEditField = uieditfield(app.SensorsPanel, 'text');
            app.Sensor1PosEditField.Tooltip = {'[x,y,z]'};
            app.Sensor1PosEditField.Value = '[0,0,0]';
            app.Sensor2PosEditField = uieditfield(app.SensorsPanel, 'text');
            app.Sensor2PosEditField.Tooltip = {'[x,y,z]'};
            app.Sensor2PosEditField.Value = '[0,0,0]';
            app.Sensor3PosEditField = uieditfield(app.SensorsPanel, 'text');
            app.Sensor3PosEditField.Tooltip = {'[x,y,z]'};
            app.Sensor3PosEditField.Value = '[0,0,0]';
            app.Sensor4PosEditField = uieditfield(app.SensorsPanel, 'text');
            app.Sensor4PosEditField.Tooltip = {'[x,y,z]'};
            app.Sensor4PosEditField.Value = '[0,0,0]';
            app.BackToSessionInfoSensorsButton = uibutton(app.SensorsPanel, 'push');
            app.BackToSessionInfoSensorsButton.ButtonPushedFcn = createCallbackFcn(app, @BackToSessionInfoSensorsButtonPushed, true);
            app.BackToSessionInfoSensorsButton.Text = 'Continuar';
            app.TrialMenuPanel = uipanel(app.UIFigure);
            app.TrialMenuPanel.Visible = 'off';
            app.SummaryPanel = uipanel(app.TrialMenuPanel);
            app.SummaryLabel = uilabel(app.SummaryPanel);
            app.SummaryLabel.Text = 'Resumen de los Datos Iniciales';
            app.IIWASummaryLabel = uilabel(app.SummaryPanel);
            app.IIWASummaryLabel.Text = 'IIWA: Conectado';
            app.FTSensorSummaryLabel = uilabel(app.SummaryPanel);
            app.FTSensorSummaryLabel.Text = 'Sensor Fuerza-Tensión: Conectado';
            app.DelsysSensorsSummaryLabel = uilabel(app.SummaryPanel);
            app.DelsysSensorsSummaryLabel.Text = 'Sensores Delsys: Conectado';
            app.ArmSummaryLabel = uilabel(app.SummaryPanel);
            app.ArmSummaryLabel.Text = 'Brazo Seleccionado: Derecho';
            app.PlaneSummaryLabel = uilabel(app.SummaryPanel);
            app.PlaneSummaryLabel.Text = 'Posición del Paciente: Plano XZ';
            app.Sensor1SummaryLabel = uilabel(app.SummaryPanel);
            app.Sensor1SummaryLabel.Text = 'Posición Sensor 1 (Biceps Brachii) : (0,0,0)';
            app.Sensor2SummaryLabel = uilabel(app.SummaryPanel);
            app.Sensor2SummaryLabel.Text = 'Posición Sensor 2 (Triceps Lateral Head): (0,0,0)';
            app.Sensor3SummaryLabel = uilabel(app.SummaryPanel);
            app.Sensor3SummaryLabel.Text = 'Posición Sensor 3 (Brachio Radialis): (0,0,0)';
            app.Sensor4SummaryLabel = uilabel(app.SummaryPanel);
            app.Sensor4SummaryLabel.Text = 'Posición Sensor 4 (Flexor Carpi Radialis): (0,0,0)';
            app.MovementSummaryLabel = uilabel(app.SummaryPanel);
            app.MovementSummaryLabel.Text = 'Tipo de Movimiento Seleccionado: Flexión-Extensión Codo';
            app.GoToCaptureButton = uibutton(app.TrialMenuPanel, 'push');
            app.GoToCaptureButton.ButtonPushedFcn = createCallbackFcn(app, @GoToCaptureButtonPushed, true);
            app.GoToCaptureButton.Text = 'Capturar Nueva Trayectoria';
            app.GoToRepeatButton = uibutton(app.TrialMenuPanel, 'push');
            app.GoToRepeatButton.ButtonPushedFcn = createCallbackFcn(app, @GoToRepeatButtonPushed, true);
            app.GoToRepeatButton.Text = 'Repetir Trayectoria Capturada';
            app.BackToSessionInfoTrialMenuButton = uibutton(app.TrialMenuPanel, 'push');
            app.BackToSessionInfoTrialMenuButton.ButtonPushedFcn = createCallbackFcn(app, @BackToSessionInfoTrialMenuButtonPushed, true);
            app.BackToSessionInfoTrialMenuButton.Text = 'Cambiar Datos Iniciales';
            app.BackToMenuTrialMenuButton = uibutton(app.TrialMenuPanel, 'push');
            app.BackToMenuTrialMenuButton.ButtonPushedFcn = createCallbackFcn(app, @BackToMenuTrialMenuButtonPushed, true);
            app.BackToMenuTrialMenuButton.Text = 'Volver al Menú';
            app.CaptureTrajectory1Panel = uipanel(app.UIFigure);
            app.CaptureTrajectory1Panel.Visible = 'off';
            app.Capture1Label = uilabel(app.CaptureTrajectory1Panel);
            app.Capture1Label.Text = 'Capturar Nueva Trayectoria';
            app.MoveReferencePosLabel = uilabel(app.CaptureTrajectory1Panel);
            app.MoveReferencePosLabel.Text = '1.Mover Robot a la Posición Inicial de Referencia';
            app.LeftReferencePosButton = uibutton(app.CaptureTrajectory1Panel, 'push');
            app.LeftReferencePosButton.ButtonPushedFcn = createCallbackFcn(app, @LeftReferencePosButtonPushed, true);
            app.LeftReferencePosButton.Text = 'Brazo Izquierdo';
            app.RightReferencePosButton = uibutton(app.CaptureTrajectory1Panel, 'push');
            app.RightReferencePosButton.ButtonPushedFcn = createCallbackFcn(app, @RightReferencePosButtonPushed, true);
            app.RightReferencePosButton.Text = 'Brazo Derecho';
            app.ConfigureTrajectorySettingsLabel = uilabel(app.CaptureTrajectory1Panel);
            app.ConfigureTrajectorySettingsLabel.Text = '2.Configurar Ajustes de Trayectoria';
            app.SmoothingLabel = uilabel(app.CaptureTrajectory1Panel);
            app.SmoothingLabel.Text = '2.1.Parámetro de Smoothing';
            app.ChoosePlotsLabel = uilabel(app.CaptureTrajectory1Panel);
            app.ChoosePlotsLabel.Text = '2.2.Seleccionar Gráficas a Mostrar Tras el Ajuste';
            app.PlotAdjustmentCheckBox = uicheckbox(app.CaptureTrajectory1Panel);
            app.PlotAdjustmentCheckBox.Text = 'Posición Cartesiana + Ajuste Circular';
            app.PlotCartesianPosCheckBox = uicheckbox(app.CaptureTrajectory1Panel);
            app.PlotCartesianPosCheckBox.Text = 'Posición Cartesiana';
            app.PlotCartesianVelCheckBox = uicheckbox(app.CaptureTrajectory1Panel);
            app.PlotCartesianVelCheckBox.Text = 'Velocidad Cartesiana';
            app.PlotErrorCartesianPosCheckBox = uicheckbox(app.CaptureTrajectory1Panel);
            app.PlotErrorCartesianPosCheckBox.Text = 'Error Posición Cartesiana';
            app.PlotErrorCartesianVelCheckBox = uicheckbox(app.CaptureTrajectory1Panel);
            app.PlotErrorCartesianVelCheckBox.Text = 'Error Velocidad Cartesiana';
            app.Plot3DCartesianPosCheckBox = uicheckbox(app.CaptureTrajectory1Panel);
            app.Plot3DCartesianPosCheckBox.Text = 'Posición Cartesiana 3D';
            app.GoToCapture2Button = uibutton(app.CaptureTrajectory1Panel, 'push');
            app.GoToCapture2Button.ButtonPushedFcn = createCallbackFcn(app, @GoToCapture2ButtonPushed, true);
            app.GoToCapture2Button.Text = 'Continuar';
            app.InputSmoothingEditFieldLabel = uilabel(app.CaptureTrajectory1Panel);
            app.InputSmoothingEditFieldLabel.Text = 'Trayectoria Grabada';
            app.InputSmoothingEditField = uieditfield(app.CaptureTrajectory1Panel, 'numeric');
            app.CommandedSmoothingEditFieldLabel = uilabel(app.CaptureTrajectory1Panel);
            app.CommandedSmoothingEditFieldLabel.Text = 'Trayectoria Ajustada';
            app.CommandedSmoothingEditField = uieditfield(app.CaptureTrajectory1Panel, 'numeric');
            app.OutputSmoothingEditFieldLabel = uilabel(app.CaptureTrajectory1Panel);
            app.OutputSmoothingEditFieldLabel.Text = 'Trayectoria Ejecutada';
            app.OutputSmoothingEditField = uieditfield(app.CaptureTrajectory1Panel, 'numeric');
            app.CaptureTrajectory2Panel = uipanel(app.UIFigure);
            app.Capture2Label = uilabel(app.CaptureTrajectory2Panel);
            app.Capture2Label.Text = 'Capturar Nueva Trayectoria';
            app.InitialPositionLabel = uilabel(app.CaptureTrajectory2Panel);
            app.InitialPositionLabel.Text = '3.Personalizar Posición Inicial';
            app.FreeBlockRobotButton = uibutton(app.CaptureTrajectory2Panel, 'push');
            app.FreeBlockRobotButton.ButtonPushedFcn = createCallbackFcn(app, @FreeBlockRobotButtonPushed, true);
            app.FreeBlockRobotButton.Text = 'Bloquear Robot';
            app.FreeXButton = uibutton(app.CaptureTrajectory2Panel, 'push');
            app.FreeXButton.ButtonPushedFcn = createCallbackFcn(app, @FreeXButtonPushed, true);
            app.FreeXButton.Text = 'Liberar Eje X';
            app.FreeYButton = uibutton(app.CaptureTrajectory2Panel, 'push');
            app.FreeYButton.ButtonPushedFcn = createCallbackFcn(app, @FreeYButtonPushed, true);
            app.FreeYButton.Text = 'Liberar Eje Y';
            app.FreeZButton = uibutton(app.CaptureTrajectory2Panel, 'push');
            app.FreeZButton.ButtonPushedFcn = createCallbackFcn(app, @FreeZButtonPushed, true);
            app.FreeZButton.Text = 'Liberar Eje Z';
            app.CaptureTrajectoryLabel = uilabel(app.CaptureTrajectory2Panel);
            app.CaptureTrajectoryLabel.Text = '4.Grabar la Trayectoria';
            app.StartCaptureButton = uibutton(app.CaptureTrajectory2Panel, 'push');
            app.StartCaptureButton.ButtonPushedFcn = createCallbackFcn(app, @StartCaptureButtonPushed, true);
            app.StartCaptureButton.Text = 'Iniciar Captura';
            app.EndCaptureButton = uibutton(app.CaptureTrajectory2Panel, 'push');
            app.EndCaptureButton.ButtonPushedFcn = createCallbackFcn(app, @EndCaptureButtonPushed, true);
            app.EndCaptureButton.Text = 'Finalizar Captura';
            app.GenerateSaveTrajectoryLabel = uilabel(app.CaptureTrajectory2Panel);
            app.GenerateSaveTrajectoryLabel.Text = '5.Generar y Guardar Trayectoria';
            app.SaveTrajectoryButton = uibutton(app.CaptureTrajectory2Panel, 'push');
            app.SaveTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTrajectoryButtonPushed, true);
            app.SaveTrajectoryButton.Text = 'Guardar Trayectoria';
            app.RepeatCaptureButton = uibutton(app.CaptureTrajectory2Panel, 'push');
            app.RepeatCaptureButton.ButtonPushedFcn = createCallbackFcn(app, @RepeatCaptureButtonPushed, true);
            app.RepeatCaptureButton.Text = 'Repetir Captura';
            app.InputDropDownLabel = uilabel(app.CaptureTrajectory2Panel);
            app.InputDropDownLabel.Text = 'Seleccionar Trayectoria';
            app.InputDropDown = uidropdown(app.CaptureTrajectory2Panel);
            app.InputDropDown.ValueChangedFcn = createCallbackFcn(app, @InputDropDownValueChanged, true);
            app.RepeatTrajectoryPanel = uipanel(app.UIFigure);
            app.RepeatTrajectoryPanel.Visible = 'off';
            app.RepeatTrajectoryLabel = uilabel(app.RepeatTrajectoryPanel);
            app.RepeatTrajectoryLabel.Text = 'Repetir Trayectoria';
            app.ChooseTrajectoryLabel = uilabel(app.RepeatTrajectoryPanel);
            app.ChooseTrajectoryLabel.Text = '1.Seleccionar trayectoria a repetir';
            app.ChangeVelocityLabel = uilabel(app.RepeatTrajectoryPanel);
            app.ChangeVelocityLabel.Text = '2.Modificar velocidad';
            app.VelocitySlider = uislider(app.RepeatTrajectoryPanel);
            app.VelocitySlider.ValueChangedFcn = createCallbackFcn(app, @VelocitySliderValueChanged, true);
            app.CalibrateFTSensorLabel = uilabel(app.RepeatTrajectoryPanel);
            app.CalibrateFTSensorLabel.Text = '3.Calibrar sensor Fuerza-Tensión para esta trayectoria';
            app.InitialPositionEmptyButton = uibutton(app.RepeatTrajectoryPanel, 'push');
            app.InitialPositionEmptyButton.ButtonPushedFcn = createCallbackFcn(app, @InitialPositionEmptyButtonPushed, true);
            app.InitialPositionEmptyButton.Text = 'Mandar a la Posición Inicial';
            app.RunTrajectoryEmptyButton = uibutton(app.RepeatTrajectoryPanel, 'push');
            app.RunTrajectoryEmptyButton.ButtonPushedFcn = createCallbackFcn(app, @RunTrajectoryEmptyButtonPushed, true);
            app.RunTrajectoryEmptyButton.Text = 'Ejecutar Trayectoria en Vacío';
            app.RunTrajectoryLabel = uilabel(app.RepeatTrajectoryPanel);
            app.RunTrajectoryLabel.Text = '4.Ejecutar Trayectoria';
            app.InitialPositionRunButton = uibutton(app.RepeatTrajectoryPanel, 'push');
            app.InitialPositionRunButton.ButtonPushedFcn = createCallbackFcn(app, @InitialPositionRunButtonPushed, true);
            app.InitialPositionRunButton.Text = 'Mandar a la Posición Inicial';
            app.RunTrajectoryButton = uibutton(app.RepeatTrajectoryPanel, 'push');
            app.RunTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @RunTrajectoryButtonPushed, true);
            app.RunTrajectoryButton.Text = 'Ejecutar Trayectoria';
            app.RepetitionsSpinner = uispinner(app.RepeatTrajectoryPanel);
            app.RepetitionsSpinner.Limits = [1 Inf];
            app.RepetitionsSpinner.Value = 1;
            app.GoToResultsButton = uibutton(app.RepeatTrajectoryPanel, 'push');
            app.GoToResultsButton.ButtonPushedFcn = createCallbackFcn(app, @GoToResultsButtonPushed, true);
            app.GoToResultsButton.Text = 'Ver Resultados';
            app.BackToTrialMenuRepeatButton = uibutton(app.RepeatTrajectoryPanel, 'push');
            app.BackToTrialMenuRepeatButton.ButtonPushedFcn = createCallbackFcn(app, @BackToTrialMenuRepeatButtonPushed, true);
            app.BackToTrialMenuRepeatButton.Text = 'Volver al Menú';
            app.AdjustedTrajectoryDropDownLabel = uilabel(app.RepeatTrajectoryPanel);
            app.AdjustedTrajectoryDropDownLabel.Text = 'Trayectoria';
            app.AdjustedTrajectoryDropDown = uidropdown(app.RepeatTrajectoryPanel);
            app.AdjustedTrajectoryDropDown.ValueChangedFcn = createCallbackFcn(app, @AdjustedTrajectoryDropDownValueChanged, true);
            app.VelocityLabel = uilabel(app.RepeatTrajectoryPanel);
            app.VelocityLabel.Text = {'º/s'; ''};
            app.RelaxMusclesEmptyButton = uibutton(app.RepeatTrajectoryPanel, 'state');
            app.RelaxMusclesEmptyButton.Text = 'Comprobar Relajación Muscular';
            app.RelaxMusclesRunButton = uibutton(app.RepeatTrajectoryPanel, 'state');
            app.RelaxMusclesRunButton.Text = 'Comprobar Relajación Muscular';
            app.ResultsPanel = uipanel(app.UIFigure);
            app.ResultsPanel.Visible = 'off';
            app.Trajectory2Label = uilabel(app.ResultsPanel);
            app.Trajectory2Label.Text = 'Trayectoria';
            app.Joint2Label = uilabel(app.ResultsPanel);
            app.Joint2Label.Text = 'Articular';
            app.q2Button = uibutton(app.ResultsPanel, 'push');
            app.q2Button.ButtonPushedFcn = createCallbackFcn(app, @q2ButtonPushed, true);
            app.q2Button.Text = 'q';
            app.dq2Button = uibutton(app.ResultsPanel, 'push');
            app.dq2Button.ButtonPushedFcn = createCallbackFcn(app, @dq2ButtonPushed, true);
            app.dq2Button.Text = 'dq';
            app.Eq2Button = uibutton(app.ResultsPanel, 'push');
            app.Eq2Button.ButtonPushedFcn = createCallbackFcn(app, @Eq2ButtonPushed, true);
            app.Eq2Button.Text = 'E(q)';
            app.Edq2Button = uibutton(app.ResultsPanel, 'push');
            app.Edq2Button.ButtonPushedFcn = createCallbackFcn(app, @Edq2ButtonPushed, true);
            app.Edq2Button.Text = 'E(dq)';
            app.Cartesian2Label = uilabel(app.ResultsPanel);
            app.Cartesian2Label.Text = 'Cartesiana';
            app.x2Button = uibutton(app.ResultsPanel, 'push');
            app.x2Button.ButtonPushedFcn = createCallbackFcn(app, @x2ButtonPushed, true);
            app.x2Button.Text = 'x';
            app.Ex2Button = uibutton(app.ResultsPanel, 'push');
            app.Ex2Button.ButtonPushedFcn = createCallbackFcn(app, @Ex2ButtonPushed, true);
            app.Ex2Button.Text = 'E(x)';
            app.Edx2Button = uibutton(app.ResultsPanel, 'push');
            app.Edx2Button.ButtonPushedFcn = createCallbackFcn(app, @Edx2ButtonPushed, true);
            app.Edx2Button.Text = 'E(dx)';
            app.ThreeD2Button = uibutton(app.ResultsPanel, 'push');
            app.ThreeD2Button.ButtonPushedFcn = createCallbackFcn(app, @ThreeD2ButtonPushed, true);
            app.ThreeD2Button.Text = '3D';
            app.dx2Button = uibutton(app.ResultsPanel, 'push');
            app.dx2Button.ButtonPushedFcn = createCallbackFcn(app, @dx2ButtonPushed, true);
            app.dx2Button.Text = 'dx';
            app.ForceSensor2Label = uilabel(app.ResultsPanel);
            app.ForceSensor2Label.Text = 'Sensor Fuerza Par';
            app.FT2Button = uibutton(app.ResultsPanel, 'push');
            app.FT2Button.ButtonPushedFcn = createCallbackFcn(app, @FT2ButtonPushed, true);
            app.FT2Button.Text = 'FT';
            app.DelsysSensors2Label = uilabel(app.ResultsPanel);
            app.DelsysSensors2Label.Text = 'Sensores Delsys';
            app.EMG2Button = uibutton(app.ResultsPanel, 'push');
            app.EMG2Button.ButtonPushedFcn = createCallbackFcn(app, @EMG2ButtonPushed, true);
            app.EMG2Button.Text = 'EMG';
            app.Accelerations2Button = uibutton(app.ResultsPanel, 'push');
            app.Accelerations2Button.ButtonPushedFcn = createCallbackFcn(app, @Accelerations2ButtonPushed, true);
            app.Accelerations2Button.Text = 'Aceleraciones';
            app.AngularVelocity2Button = uibutton(app.ResultsPanel, 'push');
            app.AngularVelocity2Button.ButtonPushedFcn = createCallbackFcn(app, @AngularVelocity2ButtonPushed, true);
            app.AngularVelocity2Button.Text = 'Velocidad Angular';
            app.ddq2Button = uibutton(app.ResultsPanel, 'push');
            app.ddq2Button.ButtonPushedFcn = createCallbackFcn(app, @ddq2ButtonPushed, true);
            app.ddq2Button.Text = 'ddq';
            app.Eddq2Button = uibutton(app.ResultsPanel, 'push');
            app.Eddq2Button.ButtonPushedFcn = createCallbackFcn(app, @Eddq2ButtonPushed, true);
            app.Eddq2Button.Text = 'E(ddq)';
            app.Epq2Button = uibutton(app.ResultsPanel, 'push');
            app.Epq2Button.ButtonPushedFcn = createCallbackFcn(app, @Epq2ButtonPushed, true);
            app.Epq2Button.Text = 'Ep(q)';
            app.Epdq2Button = uibutton(app.ResultsPanel, 'push');
            app.Epdq2Button.ButtonPushedFcn = createCallbackFcn(app, @Epdq2ButtonPushed, true);
            app.Epdq2Button.Text = 'Ep(dq)';
            app.Epddq2Button = uibutton(app.ResultsPanel, 'push');
            app.Epddq2Button.ButtonPushedFcn = createCallbackFcn(app, @Epddq2ButtonPushed, true);
            app.Epddq2Button.Text = 'Ep(ddq)';
            app.Epx2Button = uibutton(app.ResultsPanel, 'push');
            app.Epx2Button.ButtonPushedFcn = createCallbackFcn(app, @Epx2ButtonPushed, true);
            app.Epx2Button.Text = 'Ep(x)';
            app.Epdx2Button = uibutton(app.ResultsPanel, 'push');
            app.Epdx2Button.ButtonPushedFcn = createCallbackFcn(app, @Epdx2ButtonPushed, true);
            app.Epdx2Button.Text = 'Ep(dx)';
            app.JointPrecision2Label = uilabel(app.ResultsPanel);
            app.JointPrecision2Label.Text = 'Precisión:';
            app.JointAccuracy2Label = uilabel(app.ResultsPanel);
            app.JointAccuracy2Label.Text = 'Exactitud:';
            app.CartesianPrecision2Label = uilabel(app.ResultsPanel);
            app.CartesianPrecision2Label.Text = 'Precisión:';
            app.CartesianAccuracy2Label = uilabel(app.ResultsPanel);
            app.CartesianAccuracy2Label.Text = 'Exactitud:';
            app.ResultsLabel = uilabel(app.ResultsPanel);
            app.ResultsLabel.Text = 'Resultados';
            app.BackToTrialMenuResultsButton = uibutton(app.ResultsPanel, 'push');
            app.BackToTrialMenuResultsButton.ButtonPushedFcn = createCallbackFcn(app, @BackToTrialMenuResultsButtonPushed, true);
            app.BackToTrialMenuResultsButton.Text = 'Volver al Menú';
            app.RepetitionPlotSpinnerLabel = uilabel(app.ResultsPanel);
            app.RepetitionPlotSpinnerLabel.Text = 'Seleccione la repetición a mostrar';
            app.RepetitionPlotSpinner = uispinner(app.ResultsPanel);
            app.RepetitionPlotSpinner.Limits = [1 Inf];
            app.RepetitionPlotSpinner.ValueChangedFcn = createCallbackFcn(app, @RepetitionPlotSpinnerValueChanged, true);
            app.RepetitionPlotSpinner.Value = 1;
            app.RepetitionPlotLabel = uilabel(app.ResultsPanel);
            app.RepetitionPlotLabel.Text = 'Introduce 0 para comparar todas las repeticiones';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RoboespasDefinitivo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end